classdef ESN_CS_SS_Sorter_App_v2_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        grid_main                  matlab.ui.container.GridLayout
        grid_status                matlab.ui.container.GridLayout
        txtstatic_status_label     matlab.ui.control.Label
        txtstatic_status_message   matlab.ui.control.Label
        lamp_status                matlab.ui.control.Lamp
        grid_file_and_slot         matlab.ui.container.GridLayout
        pushbtn_load_file          matlab.ui.control.Button
        pushbtn_save_file          matlab.ui.control.Button
        txtstatic_filename         matlab.ui.control.Label
        txtstatic_filepath         matlab.ui.control.Label
        grid_slot_pushbtn          matlab.ui.container.GridLayout
        pushbtn_slot_back          matlab.ui.control.Button
        pushbtn_slot_next          matlab.ui.control.Button
        grid_slot_num              matlab.ui.container.GridLayout
        txtedit_slot_numCurrent    matlab.ui.control.NumericEditField
        txtstatic_slot_label1      matlab.ui.control.Label
        txtstatic_slot_label2      matlab.ui.control.Label
        txtstatic_slot_numTotal    matlab.ui.control.Label
        grid_signal                matlab.ui.container.GridLayout
        grid_raw_and_flip          matlab.ui.container.GridLayout
        grid_flip                  matlab.ui.container.GridLayout
        chkbox_neg_ss_hipass       matlab.ui.control.CheckBox
        chkbox_neg_cs_hipass       matlab.ui.control.CheckBox
        chkbox_pos_cs_lopass       matlab.ui.control.CheckBox
        grid_raw                   matlab.ui.container.GridLayout
        grid_threshold             matlab.ui.container.GridLayout
        txtstatic_ss_threshold     matlab.ui.control.Label
        txtedit_ss_threshold       matlab.ui.control.NumericEditField
        txtstatic_cs_threshold     matlab.ui.control.Label
        txtedit_cs_threshold       matlab.ui.control.NumericEditField
        pushbtn_rawSignal_refresh  matlab.ui.control.Button
        axes_rawSignal_cs_dist     matlab.ui.control.UIAxes
        axes_rawSignal_ss_dist     matlab.ui.control.UIAxes
        axes_rawSignal             matlab.ui.control.UIAxes
        grid_ss_cs_params          matlab.ui.container.GridLayout
        axes_ss_wave               matlab.ui.control.UIAxes
        axes_ss_isi                matlab.ui.control.UIAxes
        axes_ss_pca                matlab.ui.control.UIAxes
        axes_ss_corr               matlab.ui.control.UIAxes
        axes_cs_wave               matlab.ui.control.UIAxes
        axes_cs_isi                matlab.ui.control.UIAxes
        axes_cs_pca                matlab.ui.control.UIAxes
        axes_cs_corr               matlab.ui.control.UIAxes
        grid_ss_wave               matlab.ui.container.GridLayout
        pushbtn_ss_wave_select     matlab.ui.control.Button
        pushbtn_ss_wave_delete     matlab.ui.control.Button
        pushbtn_ss_wave_keep       matlab.ui.control.Button
        pushbtn_ss_wave_refresh    matlab.ui.control.Button
        grid_ss_pca                matlab.ui.container.GridLayout
        pushbtn_ss_pca_select      matlab.ui.control.Button
        pushbtn_ss_pca_delete      matlab.ui.control.Button
        pushbtn_ss_pca_keep        matlab.ui.control.Button
        pushbtn_ss_pca_refresh     matlab.ui.control.Button
        grid_cs_wave               matlab.ui.container.GridLayout
        pushbtn_cs_wave_select     matlab.ui.control.Button
        pushbtn_cs_wave_delete     matlab.ui.control.Button
        pushbtn_cs_wave_keep       matlab.ui.control.Button
        pushbtn_cs_wave_refresh    matlab.ui.control.Button
        grid_cs_pca                matlab.ui.container.GridLayout
        pushbtn_cs_pca_select      matlab.ui.control.Button
        pushbtn_cs_pca_delete      matlab.ui.control.Button
        pushbtn_cs_pca_keep        matlab.ui.control.Button
        pushbtn_cs_pca_refresh     matlab.ui.control.Button
    end

    
    properties (Access = private)
        app_databade % database for the user defined variables
    end
    
    methods (Access = private)
        
        function ESN_filter_EPHYS(app)
            sample_freq = 30e3; %Hz
            Wn_hipass = [300 3000]/(sample_freq/2);
            [b_hipass,a_hipass] = butter(4, Wn_hipass);
            [b_lopass,a_lopass] = butter(4, Wn_hipass(1), 'low');
            
            fieldnames_EPHYS = fieldnames(app.app_databade.EPHYS);
            for counter_fields = 1 : 1 : length(fieldnames_EPHYS)
                field_name = fieldnames_EPHYS{counter_fields};
                % if field name does not contain 'CH_' skip it
                if ~contains(field_name, 'CH_')
                    continue;
                end
                % if field name is 'CH_EVE' skip it
                if contains(field_name, 'CH_EVE')
                    continue;
                end
                % common average the ch_data, except the CH_CMN
                if contains(field_name, 'CH_CMN')
                    app.app_databade.EPHYS.CH_CMN.ch_data_cmn       = app.app_databade.EPHYS.CH_CMN.ch_data(:);
                else
                    app.app_databade.EPHYS.(field_name).ch_data_cmn = app.app_databade.EPHYS.(field_name).ch_data(:) - app.app_databade.EPHYS.CH_CMN.ch_data(:);
                    app.app_databade.EPHYS.(field_name) = rmfield(app.app_databade.EPHYS.(field_name),{'ch_data', 'ch_info', 'ch_time'});
                end
                % band pass filter the data
                app.app_databade.EPHYS.(field_name).ch_data_hipass = filtfilt( b_hipass, a_hipass, app.app_databade.EPHYS.(field_name).ch_data_cmn(:));
                % low pass filter and then detrend the data
                app.app_databade.EPHYS.(field_name).ch_data_lopass = filtfilt( b_lopass, a_lopass, app.app_databade.EPHYS.(field_name).ch_data_cmn(:));
                app.app_databade.EPHYS.(field_name).ch_data_lopass = detrend(app.app_databade.EPHYS.(field_name).ch_data_lopass);
            end
            
            app.app_databade.EPHYS.time = app.app_databade.EPHYS.CH_CMN.ch_time(:);
            app.app_databade.EPHYS.CH_CMN = rmfield(app.app_databade.EPHYS.CH_CMN,{'ch_data', 'ch_info', 'ch_time'});
        end
        
        function ESN_init_slot_data(app)
            app.chkbox_pos_cs_lopass.Value = true;
            app.chkbox_neg_cs_hipass.Value = true;
            app.chkbox_neg_ss_hipass.Value = true;
            
            app.app_databade.current_slot_data.time          = [];
            app.app_databade.current_slot_data.hipass        = [];
            app.app_databade.current_slot_data.lopass        = [];
            app.app_databade.current_slot_data.CS_peak_value = [];
            app.app_databade.current_slot_data.CS_time       = [];
            app.app_databade.current_slot_data.CS_width      = [];
            app.app_databade.current_slot_data.CS_prominence = [];
            app.app_databade.current_slot_data.CS_ind        = [];
            app.app_databade.current_slot_data.CS_inds       = [];
            app.app_databade.current_slot_data.CS_waveform   = [];
            app.app_databade.current_slot_data.CS_peak_lopass       = [];
            app.app_databade.current_slot_data.CS_prominence_lopass = [];
            app.app_databade.current_slot_data.CS_width_lopass      = [];
            app.app_databade.current_slot_data.CS_time_lopass       = [];
            app.app_databade.current_slot_data.CS_ind_lopass        = [];
            app.app_databade.current_slot_data.SS_peak_value = [];
            app.app_databade.current_slot_data.SS_time       = [];
            app.app_databade.current_slot_data.SS_width      = [];
            app.app_databade.current_slot_data.SS_prominence = [];
            app.app_databade.current_slot_data.SS_ind        = [];
            app.app_databade.current_slot_data.SS_inds       = [];
            app.app_databade.current_slot_data.SS_waveform   = [];
            
            app.app_databade.slot_data.is_analyzed   = false(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.CS_peak_value = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.CS_time       = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.CS_width      = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.CS_prominence = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.CS_ind        = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.CS_inds       = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.CS_waveform   = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.CS_peak_lopass       = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.CS_prominence_lopass = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.CS_width_lopass      = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.CS_time_lopass       = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.CS_ind_lopass        = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.SS_peak_value = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.SS_time       = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.SS_width      = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.SS_prominence = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.SS_ind        = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.SS_inds       = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.SS_waveform   = cell(app.app_databade.total_num_slots,1);
            
        end
        
        function ESN_evaluate_slot_data(app)
            app.txtstatic_status_message.Text = ['Evaluating ' 'slot data # ' num2str(app.app_databade.current_slot_num) ' ... '];
            app.lamp_status.Color = [1 0 0];
            pause(0.050);
            time_slot_str = app.app_databade.time_edges(app.app_databade.current_slot_num);
            time_slot_end = app.app_databade.time_edges(app.app_databade.current_slot_num+1);
            ind_str       = find(app.app_databade.EPHYS.time >= time_slot_str, 1, 'first');
            ind_end       = find(app.app_databade.EPHYS.time <= time_slot_end, 1, 'last');
            inds_slot     = ind_str : 1 : ind_end;
            
            threshold_CS      =  app.txtedit_cs_threshold.Value;
            threshold_SS      =  app.txtedit_ss_threshold.Value;
            threshold_CS_time = 0.005;
            threshold_SS_time = 0.001;
            if app.chkbox_pos_cs_lopass.Value
                lopass_slot =  app.app_databade.EPHYS.CH_1.ch_data_lopass(inds_slot);
            else
                lopass_slot = -app.app_databade.EPHYS.CH_1.ch_data_lopass(inds_slot);
            end
            if app.chkbox_neg_ss_hipass.Value
                hipass_slot = -app.app_databade.EPHYS.CH_1.ch_data_hipass(inds_slot);
            else
                hipass_slot =  app.app_databade.EPHYS.CH_1.ch_data_hipass(inds_slot);
            end
%             time_slot   = app.app_databade.EPHYS.time(inds_slot);
            % find peaks in the lopass_slot data as CS events
            [CS_peak_lopass, CS_ind_lopass, CS_width_lopass, CS_prominence_lopass] = findpeaks(lopass_slot , 'MinPeakHeight',threshold_CS,'MinPeakProminence',threshold_CS,'MinPeakDistance',threshold_CS_time);
            if ~app.chkbox_pos_cs_lopass.Value
                CS_peak_lopass =  -CS_peak_lopass;
            end
            % represent the CS_ind as absolute values instead of local
            % values of the current time slot
            CS_ind_lopass  = CS_ind_lopass + ind_str - 1;
            CS_time_lopass = app.app_databade.EPHYS.time(CS_ind_lopass);
            app.app_databade.current_slot_data.CS_peak_lopass       = CS_peak_lopass;
            app.app_databade.current_slot_data.CS_prominence_lopass = CS_prominence_lopass;
            app.app_databade.current_slot_data.CS_width_lopass      = CS_width_lopass;
            app.app_databade.current_slot_data.CS_time_lopass       = CS_time_lopass;
            app.app_databade.current_slot_data.CS_ind_lopass        = CS_ind_lopass;
            % align CS waveform to the zero-crossing point and save CS data
            ESN_align_CS(app);
            % find peaks in the hipass_slot data as SS events
            [SS_peak_value, SS_ind, SS_width, SS_prominence] = findpeaks(hipass_slot, 'MinPeakHeight',threshold_SS,'MinPeakProminence',threshold_SS,'MinPeakDistance',threshold_SS_time);
            if app.chkbox_neg_ss_hipass.Value
                SS_peak_value = -SS_peak_value;
            end
            % represent the SS_ind as absolute values instead of local
            % values of the current time slot
            SS_ind  = SS_ind + ind_str - 1;
            % find overlapping CS_ind in SS_ind and remove them from SS_ind
            CS_ind   = app.app_databade.current_slot_data.CS_ind;
            SS_num_total = length(SS_ind);
            SS_valid = true(SS_num_total, 1);
            half_window_length_   = 8; % full window length is 16 data points or ~0.5ms
            if (~isempty(CS_ind)) && (~isempty(SS_ind))
                CS_ind_first_element_ = min([SS_ind(1),   CS_ind(1)])   - (2*half_window_length_);
                CS_ind_last_element_  = max([SS_ind(end), CS_ind(end)]) + (2*half_window_length_);
                CS_ind    = [CS_ind_first_element_; CS_ind(:); CS_ind_last_element_];
                counter_CS = 1;
                for counter_SS = 1 : SS_num_total
                    SS_ind_current_  = SS_ind(counter_SS);
                    CS_ind_previous_ = CS_ind(counter_CS);
                    CS_ind_next_     = CS_ind(counter_CS+1);
                    if (abs(SS_ind_current_-CS_ind_previous_)<half_window_length_) || (abs(CS_ind_next_-SS_ind_current_)<half_window_length_)
                        SS_valid(counter_SS) = false;
                    end
                    if SS_ind_current_ >= CS_ind_next_
                        counter_CS = counter_CS + 1;
                    end
                end
                
            end
            
            % remove shared ind between SS_ind and CS_ind from SS_ind
            SS_ind        = SS_ind(       SS_valid, :);
            SS_peak_value = SS_peak_value(SS_valid, :);
            SS_width      = SS_width(     SS_valid, :);
            SS_prominence = SS_prominence(SS_valid, :);
            % extract SS_time
            SS_time = app.app_databade.EPHYS.time(SS_ind);
            % find 4ms around each CS event, % 2ms before and after the peak, 2ms with 30KHz will be 60 data points
            inds_span_SS = (-60+1) : 1 : (120);
            length_time_ = ind_str + length(hipass_slot) -1;
            SS_inds = repmat( SS_ind(:), 1, length(inds_span_SS)) + repmat(inds_span_SS, length(SS_ind), 1);
            SS_inds( SS_inds < 1 ) = 1;
            SS_inds( SS_inds > length_time_ ) = length_time_;
            SS_waveform = app.app_databade.EPHYS.CH_1.ch_data_hipass(SS_inds);
            
            app.app_databade.current_slot_data.time          = app.app_databade.EPHYS.time(inds_slot);
            app.app_databade.current_slot_data.hipass        = app.app_databade.EPHYS.CH_1.ch_data_hipass(inds_slot);
            app.app_databade.current_slot_data.lopass        = app.app_databade.EPHYS.CH_1.ch_data_lopass(inds_slot);
            app.app_databade.current_slot_data.SS_peak_value = SS_peak_value;
            app.app_databade.current_slot_data.SS_time       = SS_time;
            app.app_databade.current_slot_data.SS_width      = SS_width;
            app.app_databade.current_slot_data.SS_prominence = SS_prominence;
            app.app_databade.current_slot_data.SS_ind        = SS_ind;
            app.app_databade.current_slot_data.SS_inds       = SS_inds;
            app.app_databade.current_slot_data.SS_waveform   = SS_waveform;
            
            ESN_plot_slot_data(app);
            
            app.txtstatic_status_message.Text = ['Evaluating ' 'slot data # ' num2str(app.app_databade.current_slot_num) ' ... ' '-> Completed.'];
            app.lamp_status.Color = [0 1 0];
        end
        
        function ESN_align_CS(app)
            data.ch_data_time   = app.app_databade.EPHYS.time;
            data.ch_data_hipass = app.app_databade.EPHYS.CH_1.ch_data_hipass;
            data.CS_time        = app.app_databade.current_slot_data.CS_time_lopass;
            threshold_CS        = 0.8 * app.txtedit_cs_threshold.Value; % reduce the threshold by 80%, make it less conservative
            threshold_CS_time   = 0.005;
            data_length          = length(data.ch_data_time);
            CS_num_total         = length(data.CS_time);
            if CS_num_total > 1
                CS_valid             = false(CS_num_total, 1);
                CS_time_hipass       = nan(CS_num_total, 1);
                CS_ind               = nan(CS_num_total, 1);
                CS_inds              = nan(CS_num_total, 180);
                CS_waveform_hipass   = nan(CS_num_total, 180);
                CS_peak_value_hipass = nan(CS_num_total, 1);
                CS_prominence_hipass = nan(CS_num_total, 1);
                CS_width_hipass      = nan(CS_num_total, 1);
                
                for counter_CS = 1 : 1 : CS_num_total
                    if counter_CS == 1
                        % search the entire time_vector to find the
                        % beginning of the 1st CS
                        CS_interval_begin_ = find(data.ch_data_time >= data.CS_time(counter_CS, 1), 1, 'first');
                        % shift back the CS_interval_begin_ by 1s (30K)
                        CS_interval_begin_ = max([(CS_interval_begin_ - 30e3) 1]);
                    else
                        counter_CS_not_nan_ = find(~isnan(CS_ind), 1, 'last');
                        CS_interval_begin_ = CS_ind(counter_CS_not_nan_);
                    end
                    CS_interval_end_   = min([(CS_interval_begin_ + 30e4) data_length]);
                    CS_interval_inds   = CS_interval_begin_ : 1 : CS_interval_end_;
                    CS_interval_time   = data.ch_data_time(CS_interval_inds);
                    CS_interval_hipass = data.ch_data_hipass(CS_interval_inds);
                    
                    CS_ind_ = find(CS_interval_time >= data.CS_time(counter_CS, 1), 1, 'first');
                    
                    if isempty(CS_ind_)
                        % if there is no CS in the 10s window from the
                        % previous CS, then search the entire time_vector
                        CS_interval_begin_ = find(data.ch_data_time >= data.CS_time(counter_CS, 1), 1, 'first');
                        CS_interval_begin_ = max([(CS_interval_begin_ - 30e3) 1]); % shift back the CS_interval_begin_ by 1s (30K)
                        CS_interval_end_   = min([(CS_interval_begin_ + 30e4) data_length]);
                        CS_interval_inds   = CS_interval_begin_ : 1 : CS_interval_end_;
                        CS_interval_time   = data.ch_data_time(  CS_interval_inds);
                        CS_interval_hipass = data.ch_data_hipass(CS_interval_inds);
                        
                        CS_ind_ = find(CS_interval_time >= data.CS_time(counter_CS, 1), 1, 'first');
                    end
                    if(CS_ind_<150)
                        CS_ind_ = 150;
                    elseif(CS_ind_>(length(CS_interval_inds)-150))
                        CS_ind_ = length(CS_interval_inds)-150;
                    end
                    CS_inds_ = (CS_ind_ - 150+1) : 1 : (CS_ind_ + 150);
                    CS_waveform_hipass_ = CS_interval_hipass(CS_inds_, 1)';
                    CS_waveform_time_   = CS_interval_time(CS_inds_, 1)';
                    if app.chkbox_pos_cs_lopass.Value
                        [CS_peak_value_hipass_, CS_time_hipass_, CS_width_hipass_, CS_prominence_hipass_] = findpeaks( CS_waveform_hipass_ , CS_waveform_time_, 'SortStr', 'descend', 'NPeaks', 1);%, 'MinPeakHeight',threshold_CS,'MinPeakProminence',threshold_CS,'MinPeakDistance',threshold_CS_time);
                    else
                        [CS_peak_value_hipass_, CS_time_hipass_, CS_width_hipass_, CS_prominence_hipass_] = findpeaks(-CS_waveform_hipass_ , CS_waveform_time_, 'SortStr', 'descend', 'NPeaks', 1);%, 'MinPeakHeight',threshold_CS,'MinPeakProminence',threshold_CS,'MinPeakDistance',threshold_CS_time);
                    end
                    if ~app.chkbox_pos_cs_lopass.Value
                        CS_peak_value_hipass_ = -CS_peak_value_hipass_;
                    end
                    if isempty(CS_time_hipass_)
                        continue;
                    end
                    CS_ind_local_ = find(CS_waveform_time_ >= CS_time_hipass_, 1, 'first');
                    if isempty(CS_ind_local_)
                        continue;
                    end
                    if app.chkbox_pos_cs_lopass.Value
                        ind_upcross = find((CS_waveform_hipass_(1:CS_ind_local_-1) <= 0) & (CS_waveform_hipass_(2:CS_ind_local_) > 0), 1, 'last');
                    else
                        ind_upcross = find((CS_waveform_hipass_(1:CS_ind_local_-1) >= 0) & (CS_waveform_hipass_(2:CS_ind_local_) < 0), 1, 'last');
                    end
                    if isempty(ind_upcross)
                        continue;
                    end
                    if app.chkbox_neg_cs_hipass.Value
                        ind_localMin_b4_upcross = find((diff(CS_waveform_hipass_(1:ind_upcross-1)) <= 0) & (diff(CS_waveform_hipass_(2:ind_upcross)) > 0), 1, 'last');
                    else
                        ind_localMin_b4_upcross = find((diff(CS_waveform_hipass_(1:ind_upcross-1)) >= 0) & (diff(CS_waveform_hipass_(2:ind_upcross)) < 0), 1, 'last');
                    end
                    if isempty(ind_localMin_b4_upcross)
                        continue;
                    end
                    CS_time_hipass_ = CS_waveform_time_(ind_localMin_b4_upcross);
                    CS_ind_  = CS_inds_(ind_localMin_b4_upcross);
                    CS_inds_ = (CS_ind_ - 60+1) : 1 : (CS_ind_ + 120);
                    CS_inds_(CS_inds_<1) = 1;
                    CS_inds_(CS_inds_>length(CS_interval_inds)) = length(CS_interval_inds);
                    CS_waveform_hipass_ = CS_interval_hipass(CS_inds_, 1)';
                    CS_ind_  = CS_interval_inds(CS_ind_);
                    CS_inds_ = CS_interval_inds(CS_inds_);
                    
                    CS_valid(            counter_CS, :) = true;
                    CS_ind(              counter_CS, :) = CS_ind_;
                    CS_peak_value_hipass(counter_CS, :) = CS_peak_value_hipass_;
                    CS_time_hipass(      counter_CS, :) = CS_time_hipass_;
                    CS_width_hipass(     counter_CS, :) = CS_width_hipass_;
                    CS_prominence_hipass(counter_CS, :) = CS_prominence_hipass_;
                    CS_waveform_hipass(  counter_CS, :) = CS_waveform_hipass_(:)';
                    CS_inds(             counter_CS, :) = CS_inds_(:)';
                end
                
                CS_peak_value_hipass = CS_peak_value_hipass(CS_valid,:);
                CS_prominence_hipass = CS_prominence_hipass(CS_valid,:);
                CS_width_hipass      = CS_width_hipass(CS_valid,:);
                CS_time_hipass       = CS_time_hipass(CS_valid,:);
                CS_ind               = CS_ind(CS_valid,:);
                CS_inds              = CS_inds(CS_valid,:);
                CS_waveform_hipass   = CS_waveform_hipass(CS_valid,:);
                
                CS_peak_lopass       = app.app_databade.current_slot_data.CS_peak_lopass(CS_valid,:);
                CS_prominence_lopass = app.app_databade.current_slot_data.CS_prominence_lopass(CS_valid,:);
                CS_width_lopass      = app.app_databade.current_slot_data.CS_width_lopass(CS_valid,:);
                CS_time_lopass       = app.app_databade.current_slot_data.CS_time_lopass(CS_valid,:);
                CS_ind_lopass        = app.app_databade.current_slot_data.CS_ind_lopass(CS_valid,:);
            else
                CS_num_total         = 0;
                CS_time_hipass       = nan(CS_num_total, 1);
                CS_ind               = nan(CS_num_total, 1);
                CS_inds              = nan(CS_num_total, 180);
                CS_waveform_hipass   = nan(CS_num_total, 180);
                CS_peak_value_hipass = nan(CS_num_total, 1);
                CS_prominence_hipass = nan(CS_num_total, 1);
                CS_width_hipass      = nan(CS_num_total, 1);
                CS_peak_lopass       = nan(CS_num_total, 1);
                CS_prominence_lopass = nan(CS_num_total, 1);
                CS_width_lopass      = nan(CS_num_total, 1);
                CS_time_lopass       = nan(CS_num_total, 1);
                CS_ind_lopass        = nan(CS_num_total, 1);
            end
            
            app.app_databade.current_slot_data.CS_peak_value = CS_peak_value_hipass;
            app.app_databade.current_slot_data.CS_prominence = CS_prominence_hipass;
            app.app_databade.current_slot_data.CS_width      = CS_width_hipass;
            app.app_databade.current_slot_data.CS_time       = CS_time_hipass;
            app.app_databade.current_slot_data.CS_ind        = CS_ind;
            app.app_databade.current_slot_data.CS_inds       = CS_inds;
            app.app_databade.current_slot_data.CS_waveform   = CS_waveform_hipass;
            app.app_databade.current_slot_data.CS_peak_lopass       = CS_peak_lopass;
            app.app_databade.current_slot_data.CS_prominence_lopass = CS_prominence_lopass;
            app.app_databade.current_slot_data.CS_width_lopass      = CS_width_lopass;
            app.app_databade.current_slot_data.CS_time_lopass       = CS_time_lopass;
            app.app_databade.current_slot_data.CS_ind_lopass        = CS_ind_lopass;
        end
        
        function ESN_plot_slot_data(app)
            ESN_correlogram(app)
            
            ESN_plot_rawSignal(app);
            ESN_plot_ss_wave(app);
            ESN_plot_cs_wave(app);
            ESN_plot_ss_isi(app);
            ESN_plot_cs_isi(app);
            ESN_plot_ss_peak_dist(app);
            ESN_plot_cs_peak_dist(app);
            ESN_plot_ss_pca(app)
            ESN_plot_cs_pca(app)
            ESN_plot_ss_corr(app)
            ESN_plot_cs_corr(app)
        end
        
        function ESN_save_slot_data(app)
            app.app_databade.slot_data.is_analyzed(   app.app_databade.current_slot_num, 1) = true;
            app.app_databade.slot_data.CS_peak_value{ app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.CS_peak_value;
            app.app_databade.slot_data.CS_time{       app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.CS_time;
            app.app_databade.slot_data.CS_width{      app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.CS_width;
            app.app_databade.slot_data.CS_prominence{ app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.CS_prominence;
            app.app_databade.slot_data.CS_ind{        app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.CS_ind;
            app.app_databade.slot_data.CS_inds{       app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.CS_inds;
            app.app_databade.slot_data.CS_waveform{   app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.CS_waveform;
            app.app_databade.slot_data.CS_peak_lopass{       app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.CS_peak_lopass;
            app.app_databade.slot_data.CS_prominence_lopass{ app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.CS_prominence_lopass;
            app.app_databade.slot_data.CS_width_lopass{      app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.CS_width_lopass;
            app.app_databade.slot_data.CS_time_lopass{       app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.CS_time_lopass;
            app.app_databade.slot_data.CS_ind_lopass{        app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.CS_ind_lopass;
            app.app_databade.slot_data.SS_peak_value{ app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.SS_peak_value;
            app.app_databade.slot_data.SS_time{       app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.SS_time;
            app.app_databade.slot_data.SS_width{      app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.SS_width;
            app.app_databade.slot_data.SS_prominence{ app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.SS_prominence;
            app.app_databade.slot_data.SS_ind{        app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.SS_ind;
            app.app_databade.slot_data.SS_inds{       app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.SS_inds;
            app.app_databade.slot_data.SS_waveform{   app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.SS_waveform;
        end
        
        function ESN_extract_slot_data(app)
            app.txtstatic_status_message.Text = ['Extracting ' 'slot data # ' num2str(app.app_databade.current_slot_num) ' ... '];
            app.lamp_status.Color = [1 0 0];
            pause(0.050);
            time_slot_str = app.app_databade.time_edges(app.app_databade.current_slot_num);
            time_slot_end = app.app_databade.time_edges(app.app_databade.current_slot_num+1);
            ind_str = find(app.app_databade.EPHYS.time >= time_slot_str, 1, 'first');
            ind_end = find(app.app_databade.EPHYS.time <= time_slot_end, 1, 'last');
            inds_slot = ind_str : 1 : ind_end;
            time_slot = app.app_databade.EPHYS.time(inds_slot);
            lopass_slot = app.app_databade.EPHYS.CH_1.ch_data_lopass(inds_slot);
            hipass_slot = app.app_databade.EPHYS.CH_1.ch_data_hipass(inds_slot);
            
            app.app_databade.current_slot_data.time          = time_slot;
            app.app_databade.current_slot_data.hipass        = hipass_slot;
            app.app_databade.current_slot_data.lopass        = lopass_slot;
            app.app_databade.current_slot_data.CS_peak_value = app.app_databade.slot_data.CS_peak_value{ app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.CS_time       = app.app_databade.slot_data.CS_time{       app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.CS_width      = app.app_databade.slot_data.CS_width{      app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.CS_prominence = app.app_databade.slot_data.CS_prominence{ app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.CS_ind        = app.app_databade.slot_data.CS_ind{        app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.CS_inds       = app.app_databade.slot_data.CS_inds{       app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.CS_waveform   = app.app_databade.slot_data.CS_waveform{   app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.CS_peak_lopass       = app.app_databade.slot_data.CS_peak_lopass{       app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.CS_prominence_lopass = app.app_databade.slot_data.CS_prominence_lopass{ app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.CS_width_lopass      = app.app_databade.slot_data.CS_width_lopass{      app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.CS_time_lopass       = app.app_databade.slot_data.CS_time_lopass{       app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.CS_ind_lopass        = app.app_databade.slot_data.CS_ind_lopass{        app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.SS_peak_value = app.app_databade.slot_data.SS_peak_value{ app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.SS_time       = app.app_databade.slot_data.SS_time{       app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.SS_width      = app.app_databade.slot_data.SS_width{      app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.SS_prominence = app.app_databade.slot_data.SS_prominence{ app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.SS_ind        = app.app_databade.slot_data.SS_ind{        app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.SS_inds       = app.app_databade.slot_data.SS_inds{       app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.SS_waveform   = app.app_databade.slot_data.SS_waveform{   app.app_databade.current_slot_num, 1};
            
            ESN_plot_slot_data(app);
            
            app.txtstatic_status_message.Text = ['Extracting ' 'slot data # ' num2str(app.app_databade.current_slot_num) ' ... ' '-> Completed.'];
            app.lamp_status.Color = [0 1 0];
        end
        
        function ESN_plot_ss_wave(app)
            cla( app.axes_ss_wave)
            grid(app.axes_ss_wave, 'on')
            hold(app.axes_ss_wave, 'on')
            ss_wave_y_axis = app.app_databade.current_slot_data.SS_waveform';
            if (~isempty(ss_wave_y_axis))
                ss_wave_x_axis = (1 : 1 : size(ss_wave_y_axis, 1))';
            else
                ss_wave_x_axis = nan(size(ss_wave_y_axis));
            end
            app.app_databade.plot_handles.axes_ss_wave.h1 = plot(app.axes_ss_wave, ss_wave_x_axis, ss_wave_y_axis, '-b');
            app.axes_ss_wave.XLimMode = 'auto';
            app.axes_ss_wave.YLimMode = 'auto';
            app.axes_ss_wave.Interactions = [app.app_databade.plot_handles.panInterac app.app_databade.plot_handles.zoomInterac];
            if (~isempty(ss_wave_y_axis))
                vertices_wave= [...
                    (nanmean(ss_wave_x_axis)),                                 (nanmean(nanmean(ss_wave_y_axis))+nanmean(nanstd(ss_wave_y_axis)));...
                    (nanmean(ss_wave_x_axis)+nanmean(nanstd(ss_wave_x_axis))), (nanmean(nanmean(ss_wave_y_axis)));...
                    (nanmean(ss_wave_x_axis)+nanmean(nanstd(ss_wave_x_axis))), (nanmean(nanmean(ss_wave_y_axis))-nanmean(nanstd(ss_wave_y_axis)));...
                    (nanmean(ss_wave_x_axis)-nanmean(nanstd(ss_wave_x_axis))), (nanmean(nanmean(ss_wave_y_axis))-nanmean(nanstd(ss_wave_y_axis)));...
                    (nanmean(ss_wave_x_axis)-nanmean(nanstd(ss_wave_x_axis))), (nanmean(nanmean(ss_wave_y_axis)));...
                    ];
                
%                 app.app_databade.plot_handles.axes_ss_wave.h_polygon = drawpolygon(app.axes_ss_wave, 'Position', vertices_wave);
            end
        end
        
        function ESN_plot_cs_wave(app)
            cla( app.axes_cs_wave)
            grid(app.axes_cs_wave, 'on')
            hold(app.axes_cs_wave, 'on')
            cs_wave_y_axis = app.app_databade.current_slot_data.CS_waveform';
            if (~isempty(cs_wave_y_axis))
                cs_wave_x_axis = (1 : 1 : size(cs_wave_y_axis, 1))';
            else
                cs_wave_x_axis = nan(size(cs_wave_y_axis));
            end
            app.app_databade.plot_handles.axes_cs_wave.h1 = plot(app.axes_cs_wave, cs_wave_x_axis, cs_wave_y_axis, '-r');
            app.axes_cs_wave.XLimMode = 'auto';
            app.axes_cs_wave.YLimMode = 'auto';
            app.axes_cs_wave.Interactions = [app.app_databade.plot_handles.panInterac app.app_databade.plot_handles.zoomInterac];
            if (~isempty(cs_wave_y_axis))
                vertices_wave= [...
                    (nanmean(cs_wave_x_axis)),                                 (nanmean(nanmean(cs_wave_y_axis))+nanmean(nanstd(cs_wave_y_axis)));...
                    (nanmean(cs_wave_x_axis)+nanmean(nanstd(cs_wave_x_axis))), (nanmean(nanmean(cs_wave_y_axis)));...
                    (nanmean(cs_wave_x_axis)+nanmean(nanstd(cs_wave_x_axis))), (nanmean(nanmean(cs_wave_y_axis))-nanmean(nanstd(cs_wave_y_axis)));...
                    (nanmean(cs_wave_x_axis)-nanmean(nanstd(cs_wave_x_axis))), (nanmean(nanmean(cs_wave_y_axis))-nanmean(nanstd(cs_wave_y_axis)));...
                    (nanmean(cs_wave_x_axis)-nanmean(nanstd(cs_wave_x_axis))), (nanmean(nanmean(cs_wave_y_axis)));...
                    ];
                
%                 app.app_databade.plot_handles.axes_cs_wave.h_polygon = drawpolygon(app.axes_cs_wave, 'Position', vertices_wave);
            end
        end
        
        function ESN_plot_rawSignal(app)
            if app.chkbox_pos_cs_lopass.Value
                threshold_CS      =  app.txtedit_cs_threshold.Value;
            else
                threshold_CS      = -app.txtedit_cs_threshold.Value;
            end
            if app.chkbox_neg_ss_hipass.Value
                threshold_SS      = -app.txtedit_ss_threshold.Value;
            else
                threshold_SS      =  app.txtedit_ss_threshold.Value;
            end
            cla( app.axes_rawSignal)
            grid(app.axes_rawSignal, 'on')
            hold(app.axes_rawSignal, 'on')
            app.app_databade.plot_handles.axes_rawSignal.h1 = plot(app.axes_rawSignal , app.app_databade.current_slot_data.time,    app.app_databade.current_slot_data.hipass, '-b'); % SS
            app.app_databade.plot_handles.axes_rawSignal.h2 = plot(app.axes_rawSignal,  app.app_databade.current_slot_data.time,    app.app_databade.current_slot_data.lopass, '-r'); % CS
            app.app_databade.plot_handles.axes_rawSignal.h4 = plot(app.axes_rawSignal,  app.app_databade.current_slot_data.SS_time, app.app_databade.current_slot_data.SS_peak_value, '.', 'Color', [0.3, 0.3, 0.9]); % SS
            app.app_databade.plot_handles.axes_rawSignal.h3 = plot(app.axes_rawSignal,  app.app_databade.current_slot_data.CS_time, app.app_databade.current_slot_data.CS_peak_value, '.', 'Color', [0.9, 0.3, 0.3]); % CS
            app.app_databade.plot_handles.axes_rawSignal.h6 = plot(app.axes_rawSignal, [app.app_databade.current_slot_data.time(1); app.app_databade.current_slot_data.time(end)],    [1; 1]*threshold_SS, '-', 'Color', [0.3, 0.3, 0.9]); % SS
            app.app_databade.plot_handles.axes_rawSignal.h5 = plot(app.axes_rawSignal, [app.app_databade.current_slot_data.time(1); app.app_databade.current_slot_data.time(end)],    [1; 1]*threshold_CS, '-', 'Color', [0.9, 0.3, 0.3]); % CS
            
            app.axes_rawSignal.XLimMode = 'auto';
            app.axes_rawSignal.YLimMode = 'auto';
            app.axes_rawSignal.Interactions     = [app.app_databade.plot_handles.panInterac app.app_databade.plot_handles.zoomInterac];
            
        end
        
        function ESN_plot_ss_isi(app)
            cla( app.axes_ss_isi)
            grid(app.axes_ss_isi, 'on')
            hold(app.axes_ss_isi, 'on')
            SS_time = app.app_databade.current_slot_data.SS_time;
            edges_SS = (0 : 0.002 : 0.050) *1000;
            ISI_SS = diff(SS_time) * 1000;
            histogram(app.axes_ss_isi, ISI_SS, edges_SS, 'DisplayStyle', 'stairs', 'Normalization', 'count', 'LineWidth', 2, 'EdgeColor', [0.1 0.1 0.9]);
            set(app.axes_ss_isi, 'XTick', [0 0.025 0.050]*1000)
        end
        
        function ESN_plot_cs_isi(app)
            cla( app.axes_cs_isi)
            grid(app.axes_cs_isi, 'on')
            hold(app.axes_cs_isi, 'on')
            CS_time = app.app_databade.current_slot_data.CS_time;
            edges_CS = 0 : 0.200 : 5.000;
            ISI_CS = diff(CS_time);
            histogram(app.axes_cs_isi, ISI_CS, edges_CS, 'DisplayStyle', 'stairs', 'Normalization', 'count', 'LineWidth', 2, 'EdgeColor', [0.9 0.1 0.1]);
            set(app.axes_cs_isi, 'XTick', [0 2.5 5.0])
        end
        
        function ESN_plot_ss_peak_dist(app)
            cla( app.axes_rawSignal_ss_dist)
            grid(app.axes_rawSignal_ss_dist, 'on')
            hold(app.axes_rawSignal_ss_dist, 'on')
            SS_peak = app.app_databade.current_slot_data.SS_peak_value;
            histogram(app.axes_rawSignal_ss_dist, SS_peak, 'DisplayStyle', 'stairs', 'Normalization', 'count', 'LineWidth', 2, 'EdgeColor', [0.1 0.1 0.9], 'Orientation', 'horizontal');
        end
        
        function ESN_plot_cs_peak_dist(app)
            cla( app.axes_rawSignal_cs_dist)
            grid(app.axes_rawSignal_cs_dist, 'on')
            hold(app.axes_rawSignal_cs_dist, 'on')
            CS_peak = app.app_databade.current_slot_data.CS_peak_lopass;
            histogram(app.axes_rawSignal_cs_dist, CS_peak, 'DisplayStyle', 'stairs', 'Normalization', 'count', 'LineWidth', 2, 'EdgeColor', [0.9 0.1 0.1], 'Orientation', 'horizontal');
        end
        
        function ESN_plot_ss_pca(app)
            cla( app.axes_ss_pca)
            grid(app.axes_ss_pca, 'on')
            hold(app.axes_ss_pca, 'on')
            SS_waveform = app.app_databade.current_slot_data.SS_waveform;
            if (~isempty(SS_waveform))
                %             [SS_pca_coeff, SS_pca_score, SS_pca_latent] = pca(SS_waveform);
                [~, SS_pca_score, ~] = pca(SS_waveform);
                pca_1 = SS_pca_score(:,1);
                pca_2 = SS_pca_score(:,2);
                vertices_pca = [...
                    (nanmean(pca_1)),               (nanmean(pca_2)+nanstd(pca_2));...
                    (nanmean(pca_1)+nanstd(pca_1)), (nanmean(pca_2));...
                    (nanmean(pca_1)+nanstd(pca_1)), (nanmean(pca_2)-nanstd(pca_2));...
                    (nanmean(pca_1)-nanstd(pca_1)), (nanmean(pca_2)-nanstd(pca_2));...
                    (nanmean(pca_1)-nanstd(pca_1)), (nanmean(pca_2));...
                    ];
            else
                pca_1 = nan(size(SS_waveform));
                pca_2 = nan(size(SS_waveform));
            end
            plot(app.axes_ss_pca,  pca_1,     pca_2, '.b');
            if (~isempty(SS_waveform))
%                 app.app_databade.plot_handles.axes_ss_pca.h_polygon = drawpolygon(app.axes_ss_pca,  'Position', vertices_pca);
            end
        end
        
        function ESN_plot_cs_pca(app)
            cla( app.axes_cs_pca)
            grid(app.axes_cs_pca, 'on')
            hold(app.axes_cs_pca, 'on')
            CS_waveform = app.app_databade.current_slot_data.CS_waveform;
            if (~isempty(CS_waveform))
                %             [CS_pca_coeff, CS_pca_score, CS_pca_latent] = pca(CS_waveform);
                [~, CS_pca_score, ~] = pca(CS_waveform);
                pca_1 = CS_pca_score(:,1);
                pca_2 = CS_pca_score(:,2);
                vertices_pca = [...
                    (nanmean(pca_1)),               (nanmean(pca_2)+nanstd(pca_2));...
                    (nanmean(pca_1)+nanstd(pca_1)), (nanmean(pca_2));...
                    (nanmean(pca_1)+nanstd(pca_1)), (nanmean(pca_2)-nanstd(pca_2));...
                    (nanmean(pca_1)-nanstd(pca_1)), (nanmean(pca_2)-nanstd(pca_2));...
                    (nanmean(pca_1)-nanstd(pca_1)), (nanmean(pca_2));...
                    ];
            else
                pca_1 = nan(size(CS_waveform));
                pca_2 = nan(size(CS_waveform));
            end
            plot(app.axes_cs_pca,  pca_1,     pca_2, '.r');
            if (~isempty(CS_waveform))
%                 app.app_databade.plot_handles.axes_cs_pca.h_polygon = drawpolygon(app.axes_cs_pca,  'Position', vertices_pca);
            end
        end
        
        function ESN_plot_ss_corr(app)
            cla( app.axes_ss_corr)
            grid(app.axes_ss_corr, 'on')
            hold(app.axes_ss_corr, 'on')
            SSxSS_AUTO = app.app_databade.current_slot_data.SSxSS_AUTO;
            inds_span = app.app_databade.current_slot_data.inds_span;
            bin_size_time = app.app_databade.current_slot_data.bin_size_time;
            if (~isempty(SSxSS_AUTO))
                x_axis_ = linspace((-50+(bin_size_time*1000)), 50, length(inds_span))';
                
                prob_value_ = mean(SSxSS_AUTO);
                prob_value_(round(length(inds_span)/2)) = 0;
                y_axis_mean_ = prob_value_;
                y_axis_stdv_ = sqrt(size(SSxSS_AUTO, 1) .* ( prob_value_ .* (1 - prob_value_) ) ) ./ size(SSxSS_AUTO, 1);
            else
                x_axis_ = nan(size(SSxSS_AUTO));
                y_axis_mean_ = nan(size(SSxSS_AUTO));
                y_axis_stdv_ = nan(size(SSxSS_AUTO));
            end
            
            plot(app.axes_ss_corr, x_axis_, y_axis_mean_+y_axis_stdv_, 'LineWidth', 1, 'Color', [0.5 0.5 0.9])
            plot(app.axes_ss_corr, x_axis_, y_axis_mean_-y_axis_stdv_, 'LineWidth', 1, 'Color', [0.5 0.5 0.9])
            plot(app.axes_ss_corr, x_axis_, y_axis_mean_,              'LineWidth', 2, 'Color', [0.1 0.1 0.9])
            
            ylim(app.axes_ss_corr, [0 inf])
        end
        
        function ESN_plot_cs_corr(app)
            cla( app.axes_cs_corr)
            grid(app.axes_cs_corr, 'on')
            hold(app.axes_cs_corr, 'on')
            CSxSS_AUTO = app.app_databade.current_slot_data.CSxSS_AUTO;
            inds_span = app.app_databade.current_slot_data.inds_span;
            bin_size_time = app.app_databade.current_slot_data.bin_size_time;
            if (~isempty(CSxSS_AUTO))
                x_axis_ = linspace((-50+(bin_size_time*1000)), 50, length(inds_span))';
                
                prob_value_ = mean(CSxSS_AUTO);
                y_axis_mean_ = prob_value_;
                y_axis_stdv_ = sqrt(size(CSxSS_AUTO, 1) .* ( prob_value_ .* (1 - prob_value_) ) ) ./ size(CSxSS_AUTO, 1);
            else
                x_axis_ = nan(size(CSxSS_AUTO));
                y_axis_mean_ = nan(size(CSxSS_AUTO));
                y_axis_stdv_ = nan(size(CSxSS_AUTO));
            end
            
            
            plot(app.axes_cs_corr, x_axis_, y_axis_mean_+y_axis_stdv_, 'LineWidth', 1, 'Color', [0.9 0.5 0.5])
            plot(app.axes_cs_corr, x_axis_, y_axis_mean_-y_axis_stdv_, 'LineWidth', 1, 'Color', [0.9 0.5 0.5])
            plot(app.axes_cs_corr, x_axis_, y_axis_mean_,              'LineWidth', 2, 'Color', [0.9 0.1 0.1])
            
            ylim(app.axes_cs_corr, [0 inf])
        end
        
        function ESN_correlogram(app)
            SS_time = app.app_databade.current_slot_data.SS_time;
            CS_time = app.app_databade.current_slot_data.CS_time;
            
            bin_size_time = 1e-3; % seconds
            span_window_size = (1 / bin_size_time) * (100 / 1000);
            span_window_size_half = round(span_window_size / 2);
            inds_span = ((-span_window_size_half+1) : 1 : (span_window_size_half))';
            if (~isempty(CS_time)) && (~isempty(SS_time))
                ch_time_min = min([SS_time(1) CS_time(1)]);
                ch_time_min = max([(ch_time_min-2.0) 0]);
                ch_time_max = max([SS_time(end) CS_time(end)]) + 2.0;
                
                CH__.SS_data.SS_time =  SS_time - ch_time_min;
                CH__.CS_data.CS_time =  CS_time - ch_time_min;
                CH__.SS_data.SS_time = ESN_Round(CH__.SS_data.SS_time, bin_size_time);
                CH__.CS_data.CS_time = ESN_Round(CH__.CS_data.CS_time, bin_size_time);
                CH__.SS_data.SS_ind  = round(CH__.SS_data.SS_time .* (1/bin_size_time));
                CH__.SS_data.SS_ind( CH__.SS_data.SS_ind < 1 ) = 1;
                CH__.CS_data.CS_ind  = round(CH__.CS_data.CS_time .* (1/bin_size_time));
                CH__.CS_data.CS_ind( CH__.CS_data.CS_ind < 1 ) = 1;
                CH__.CH_data.ch_time_reconstruct = ch_time_min : bin_size_time : ch_time_max;
            elseif (~isempty(CS_time))
                ch_time_min = min([ CS_time(1)]);
                ch_time_min = max([(ch_time_min-2.0) 0]);
                ch_time_max = max([ CS_time(end)]) + 2.0;
                
                CH__.CS_data.CS_time =  CS_time - ch_time_min;
                CH__.CS_data.CS_time = ESN_Round(CH__.CS_data.CS_time, bin_size_time);
                CH__.CS_data.CS_ind  = round(CH__.CS_data.CS_time .* (1/bin_size_time));
                CH__.CS_data.CS_ind( CH__.CS_data.CS_ind < 1 ) = 1;
                CH__.CH_data.ch_time_reconstruct = ch_time_min : bin_size_time : ch_time_max;
            elseif (~isempty(SS_time))
                ch_time_min = min([SS_time(1) ]);
                ch_time_min = max([(ch_time_min-2.0) 0]);
                ch_time_max = max([SS_time(end) ]) + 2.0;
                
                CH__.SS_data.SS_time =  SS_time - ch_time_min;
                CH__.SS_data.SS_time = ESN_Round(CH__.SS_data.SS_time, bin_size_time);
                CH__.SS_data.SS_ind  = round(CH__.SS_data.SS_time .* (1/bin_size_time));
                CH__.SS_data.SS_ind( CH__.SS_data.SS_ind < 1 ) = 1;
                CH__.CH_data.ch_time_reconstruct = ch_time_min : bin_size_time : ch_time_max;
            end    
            
            % SSxSS_AUTO
            if (~isempty(SS_time))
                CH__.SS_data.SS_inds_reconstruct = repmat( CH__.SS_data.SS_ind(:), 1, length(inds_span)) + repmat(inds_span(:)', length(CH__.SS_data.SS_ind), 1);
                CH__.SS_data.SS_inds_reconstruct( CH__.SS_data.SS_inds_reconstruct < 1 ) = 1;
                CH__.SS_data.SS_inds_reconstruct( CH__.SS_data.SS_inds_reconstruct > length( CH__.CH_data.ch_time_reconstruct ) ) = length( CH__.CH_data.ch_time_reconstruct );
                
                CH__.SS_data.SS_event_trace = false( size(CH__.CH_data.ch_time_reconstruct) );
                CH__.SS_data.SS_event_trace( CH__.SS_data.SS_ind ) = true ;
                CH__.SS_data.SS_event_trace( 1   ) = false;
                CH__.SS_data.SS_event_trace( end ) = false;
                
                CH__.SS_data.SS_event_reconstruct = CH__.SS_data.SS_event_trace( CH__.SS_data.SS_inds_reconstruct );
                % SSxSS correlogram
                SSxSS_AUTO = CH__.SS_data.SS_event_reconstruct;
            else
                SSxSS_AUTO = nan(size(SS_time));
            end
            
            % CSxSS_WITHIN
            if (~isempty(CS_time)) && (~isempty(SS_time))
                CH__.CS_data.CS_inds_reconstruct = repmat( CH__.CS_data.CS_ind(:), 1, length(inds_span)) + repmat(inds_span(:)', length(CH__.CS_data.CS_ind), 1);
                CH__.CS_data.CS_inds_reconstruct( CH__.CS_data.CS_inds_reconstruct < 1 ) = 1;
                CH__.CS_data.CS_inds_reconstruct( CH__.CS_data.CS_inds_reconstruct > length( CH__.CH_data.ch_time_reconstruct ) ) = length( CH__.CH_data.ch_time_reconstruct );
                
                CH__.SS_data.SS_event_trace = false( size(CH__.CH_data.ch_time_reconstruct) );
                CH__.SS_data.SS_event_trace( CH__.SS_data.SS_ind ) = true ;
                CH__.SS_data.SS_event_trace( 1   ) = false;
                CH__.SS_data.SS_event_trace( end ) = false;
                
                CH__.SS_data.SS_event_reconstruct = CH__.SS_data.SS_event_trace( CH__.CS_data.CS_inds_reconstruct );
                % CSxSS correlogram
                CSxSS_AUTO = CH__.SS_data.SS_event_reconstruct;
            else
                CSxSS_AUTO = nan(size(CS_time));
            end
            
            app.app_databade.current_slot_data.inds_span     = inds_span;
            app.app_databade.current_slot_data.bin_size_time = bin_size_time;
            app.app_databade.current_slot_data.SSxSS_AUTO    = SSxSS_AUTO;
            app.app_databade.current_slot_data.CSxSS_AUTO    = CSxSS_AUTO;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning('off','signal:findpeaks:largeMinPeakHeight');
            app.app_databade.current_slot_num = 1;
            app.app_databade.total_num_slots  = 1;
            ESN_init_slot_data(app)
            app.app_databade.plot_handles.panInterac  = panInteraction('Dimensions','x');
            app.app_databade.plot_handles.zoomInterac = zoomInteraction('Dimensions','x');
            app.axes_rawSignal.Interactions = [app.app_databade.plot_handles.panInterac app.app_databade.plot_handles.zoomInterac];
            app.axes_ss_wave.Interactions   = [app.app_databade.plot_handles.panInterac app.app_databade.plot_handles.zoomInterac];
            app.axes_cs_wave.Interactions   = [app.app_databade.plot_handles.panInterac app.app_databade.plot_handles.zoomInterac];
            app.txtstatic_status_message.Text = 'Please load ''10#_CH#.mat'' file and then ''10#_CMN1.mat'' to begin spike sorting.';
        end

        % Button pushed function: pushbtn_load_file
        function pushbtn_load_fileButtonPushed(app, event)
            app.app_databade.EPHYS = struct;
            app.app_databade.EPHYS.CH_1.ch_data_cmn          = [];
            app.app_databade.EPHYS.CH_1.ch_data_lopass       = [];
            app.app_databade.EPHYS.CH_1.ch_data_hipass       = [];
            app.app_databade.EPHYS.CH_CMN.ch_data_cmn        = [];
            app.app_databade.EPHYS.CH_CMN.ch_data_lopass     = [];
            app.app_databade.EPHYS.CH_CMN.ch_data_hipass     = [];
            
            [file_name,file_path] = uigetfile([pwd filesep '*_CH*.mat'], 'Select _CH mat file');
            app.txtstatic_filename.Text = file_name;
            app.txtstatic_filepath.Text = file_path;
            
            app.txtstatic_status_message.Text = ['Loading ' file_name ' ... '];
            app.lamp_status.Color = [1 0 0];
            figure(app.UIFigure);
            pause(0.050);
            app.app_databade.EPHYS.CH_1   = load([file_path filesep file_name]);
            app.txtstatic_status_message.Text = ['Loading ' file_name ' ... ' '-> Completed.'];
            app.lamp_status.Color = [0 1 0];
            
            [file_name_cmn,file_path] = uigetfile([file_path filesep '*_CMN1.mat'], 'Select _CMN1 mat file');
            app.txtstatic_status_message.Text = ['Loading ' file_name_cmn ' ... '];
            app.lamp_status.Color = [1 0 0];
            figure(app.UIFigure);
            pause(0.050);
            app.app_databade.EPHYS.CH_CMN = load([file_path filesep file_name_cmn]);
            app.txtstatic_status_message.Text = ['Loading ' file_name_cmn ' ... ' '-> Completed.'];
            app.lamp_status.Color = [0 1 0];
            
            app.txtstatic_status_message.Text = ['Filtering ' 'EPHYS data' ' ... '];
            app.lamp_status.Color = [1 0 0];
            figure(app.UIFigure);
            pause(0.050);
            ESN_filter_EPHYS(app);
            app.txtstatic_status_message.Text = ['Filtering ' 'EPHYS data' ' ... ' '-> Completed.'];
            app.lamp_status.Color = [0 1 0];
            
            time_edge_interval = 60; %s
            app.app_databade.total_num_slots = ceil((app.app_databade.EPHYS.time(end)-app.app_databade.EPHYS.time(1)) / time_edge_interval);
            app.txtstatic_slot_numTotal.Text = num2str(app.app_databade.total_num_slots);
            app.app_databade.time_edges = [(app.app_databade.EPHYS.time(1) : time_edge_interval : app.app_databade.EPHYS.time(end))'; app.app_databade.EPHYS.time(end)];
            
            app.app_databade.current_slot_num = 1;
            app.txtedit_slot_numCurrent.Value = app.app_databade.current_slot_num;
            app.txtedit_slot_numCurrent.Limits = [1 app.app_databade.total_num_slots];
            
            ESN_init_slot_data(app);
            ESN_evaluate_slot_data(app);
            ESN_save_slot_data(app);
        end

        % Button pushed function: pushbtn_save_file
        function pushbtn_save_fileButtonPushed(app, event)
            CH_data.ch_data_cmn    = app.app_databade.EPHYS.CH_1.ch_data_cmn;
            CH_data.ch_data_hipass = app.app_databade.EPHYS.CH_1.ch_data_hipass;
            CH_data.ch_data_lopass = app.app_databade.EPHYS.CH_1.ch_data_lopass;
            CH_data.ch_data_time   = app.app_databade.EPHYS.time;
            CS_data.CS_time       = cell2mat(app.app_databade.slot_data.CS_time);
            CS_data.CS_peak_value = cell2mat(app.app_databade.slot_data.CS_peak_value);
            CS_data.CS_width      = cell2mat(app.app_databade.slot_data.CS_width);
            CS_data.CS_prominence = cell2mat(app.app_databade.slot_data.CS_prominence);
            CS_data.CS_ind        = cell2mat(app.app_databade.slot_data.CS_ind);
            CS_data.CS_inds       = cell2mat(app.app_databade.slot_data.CS_inds);
            CS_data.CS_waveform   = cell2mat(app.app_databade.slot_data.CS_waveform);
            CS_data.CS_peak_lopass       = cell2mat(app.app_databade.slot_data.CS_peak_lopass);
            CS_data.CS_prominence_lopass = cell2mat(app.app_databade.slot_data.CS_prominence_lopass);
            CS_data.CS_width_lopass      = cell2mat(app.app_databade.slot_data.CS_width_lopass);
            CS_data.CS_time_lopass       = cell2mat(app.app_databade.slot_data.CS_time_lopass);
            CS_data.CS_ind_lopass        = cell2mat(app.app_databade.slot_data.CS_ind_lopass);
            SS_data.SS_time       = cell2mat(app.app_databade.slot_data.SS_time);
            SS_data.SS_peak_value = cell2mat(app.app_databade.slot_data.SS_peak_value);
            SS_data.SS_width      = cell2mat(app.app_databade.slot_data.SS_width);
            SS_data.SS_prominence = cell2mat(app.app_databade.slot_data.SS_prominence);
            SS_data.SS_ind        = cell2mat(app.app_databade.slot_data.SS_ind);
            SS_data.SS_inds       = cell2mat(app.app_databade.slot_data.SS_inds);
            SS_data.SS_waveform   = cell2mat(app.app_databade.slot_data.SS_waveform);
            
            file_name = app.txtstatic_filename.Text;
            file_path = app.txtstatic_filepath.Text;
            [~,file_name,~] = fileparts(file_name);
            file_name = [file_name '_sorted' '.mat'];
            [file_name,file_path] = uiputfile([file_path filesep file_name], 'Select where to save the _sorted mat-file.');
            app.txtstatic_status_message.Text = ['Saving ' file_name ' ... '];
            app.lamp_status.Color = [1 0 0];
            figure(app.UIFigure);
            pause(0.050);
            save([file_path filesep file_name], 'CH_data','CS_data','SS_data', '-v7.3');
            app.txtstatic_status_message.Text = ['Saving ' file_name ' ... ' '-> Completed.'];
            app.lamp_status.Color = [0 1 0];
        end

        % Button pushed function: pushbtn_slot_next
        function pushbtn_slot_nextButtonPushed(app, event)
            ESN_save_slot_data(app);
            app.app_databade.current_slot_num = app.app_databade.current_slot_num + 1;
            if app.app_databade.current_slot_num >= app.app_databade.total_num_slots
                app.app_databade.current_slot_num = app.app_databade.total_num_slots;
            end
            app.txtedit_slot_numCurrent.Value = app.app_databade.current_slot_num;
            if app.app_databade.slot_data.is_analyzed(   app.app_databade.current_slot_num, 1)
                ESN_extract_slot_data(app);
            else
                ESN_evaluate_slot_data(app);
            end
            ESN_save_slot_data(app);
        end

        % Button pushed function: pushbtn_slot_back
        function pushbtn_slot_backButtonPushed(app, event)
            ESN_save_slot_data(app);
            app.app_databade.current_slot_num = app.app_databade.current_slot_num - 1;
            if app.app_databade.current_slot_num <= 0
                app.app_databade.current_slot_num = 1;
            end
            app.txtedit_slot_numCurrent.Value = app.app_databade.current_slot_num;
            if app.app_databade.slot_data.is_analyzed(   app.app_databade.current_slot_num, 1)
                ESN_extract_slot_data(app);
            else
                ESN_evaluate_slot_data(app);
            end
            ESN_save_slot_data(app);
        end

        % Button pushed function: pushbtn_rawSignal_refresh
        function pushbtn_rawSignal_refreshButtonPushed(app, event)
            ESN_evaluate_slot_data(app);
        end

        % Button pushed function: pushbtn_cs_pca_select
        function pushbtn_cs_pca_selectButtonPushed(app, event)
            pop_up_fig_handle = figure(1);
            set(pop_up_fig_handle, 'units','normalized','outerposition',[0 0 1 1]);
            clf(pop_up_fig_handle);
            pop_up_axes_handle = axes;
            hold(pop_up_axes_handle, 'on');
            grid(pop_up_axes_handle,'on');
            CS_waveform = app.app_databade.current_slot_data.CS_waveform;
            if (~isempty(CS_waveform))
                [~, CS_pca_score, ~] = pca(CS_waveform);
                pca_1 = CS_pca_score(:,1);
                pca_2 = CS_pca_score(:,2);
            else
                pca_1 = nan(0,1);
                pca_2 = nan(0,1);
            end
            plot(pop_up_axes_handle,  pca_1,     pca_2, '.r');
            
            hold(app.axes_cs_wave, 'on');
            if isfield(app.app_databade.plot_handles, 'axes_cs_wave')
            if isfield(app.app_databade.plot_handles.axes_cs_wave, 'polygon')
                delete(app.app_databade.plot_handles.axes_cs_wave.polygon);
            end
            if isfield(app.app_databade.plot_handles.axes_cs_wave, 'selected_waves')
                delete(app.app_databade.plot_handles.axes_cs_wave.selected_waves);
            end
            end
            hold(app.axes_cs_pca, 'on');
            if isfield(app.app_databade.plot_handles, 'axes_cs_pca')
            if isfield(app.app_databade.plot_handles.axes_cs_pca, 'polygon')
                delete(app.app_databade.plot_handles.axes_cs_pca.polygon);
            end
            if isfield(app.app_databade.plot_handles.axes_cs_pca, 'selected_points')
                delete(app.app_databade.plot_handles.axes_cs_pca.selected_points);
            end
            end
            
            num_points = 6;
            coordinates = nan(num_points+1, 2);
            pop_up_plot_handle = plot(pop_up_axes_handle , coordinates(:,1),    coordinates(:,2), 'o-', 'Color', 'g'); % CS
            for counter_points = 1 : 1 : num_points
                [x_, y_] = ginput(1);
                coordinates(counter_points, 1) = x_;
                coordinates(counter_points, 2) = y_;
                delete(pop_up_plot_handle);
                pop_up_plot_handle = plot(pop_up_axes_handle , coordinates(:,1),    coordinates(:,2), 'o-', 'Color', 'g'); % CS
            end
            coordinates(num_points+1, :) = coordinates(1, :);
            delete(pop_up_plot_handle);
            pop_up_plot_handle = plot(pop_up_axes_handle , coordinates(:,1),    coordinates(:,2), 'o-', 'Color', 'g'); % CS
            close(pop_up_fig_handle);
            app.app_databade.plot_handles.axes_cs_pca.polygon = plot(app.axes_cs_pca , coordinates(:,1),    coordinates(:,2), '.-', 'Color', 'g'); % CS
            
            is_inside_ = inpolygon(pca_1, pca_2, coordinates(:,1), coordinates(:,2));
            if sum(is_inside_)>0
                app.app_databade.plot_handles.axes_cs_pca.selected_points = plot(app.axes_cs_pca , pca_1(is_inside_),     pca_2(is_inside_), '.k'); % CS
                cs_wave_y_axis = app.app_databade.current_slot_data.CS_waveform';
                cs_wave_x_axis = (1 : 1 : size(cs_wave_y_axis, 1))';
                app.app_databade.plot_handles.axes_cs_wave.selected_waves = plot(app.axes_cs_wave , cs_wave_x_axis,     cs_wave_y_axis(:, is_inside_), '-k'); % CS
            end
            
        end

        % Button pushed function: pushbtn_cs_wave_select
        function pushbtn_cs_wave_selectButtonPushed(app, event)
            pop_up_fig_handle = figure(1);
            set(pop_up_fig_handle, 'units','normalized','outerposition',[0 0 1 1]);
            clf(pop_up_fig_handle);
            pop_up_axes_handle = axes;
            hold(pop_up_axes_handle, 'on');
            grid(pop_up_axes_handle,'on');
            cs_wave_y_axis = app.app_databade.current_slot_data.CS_waveform';
            if (~isempty(cs_wave_y_axis))
                cs_wave_x_axis = (1 : 1 : size(cs_wave_y_axis, 1))';
            else
                cs_wave_x_axis = nan(size(cs_wave_y_axis));
            end
            plot(pop_up_axes_handle, cs_wave_x_axis, cs_wave_y_axis, '-r');
            
            hold(app.axes_cs_wave, 'on');
            if isfield(app.app_databade.plot_handles, 'axes_cs_wave')
            if isfield(app.app_databade.plot_handles.axes_cs_wave, 'polygon')
                delete(app.app_databade.plot_handles.axes_cs_wave.polygon);
            end
            if isfield(app.app_databade.plot_handles.axes_cs_wave, 'selected_waves')
                delete(app.app_databade.plot_handles.axes_cs_wave.selected_waves);
            end
            end
            hold(app.axes_cs_pca, 'on');
            if isfield(app.app_databade.plot_handles, 'axes_cs_pca')
            if isfield(app.app_databade.plot_handles.axes_cs_pca, 'polygon')
                delete(app.app_databade.plot_handles.axes_cs_pca.polygon);
            end
            if isfield(app.app_databade.plot_handles.axes_cs_pca, 'selected_points')
                delete(app.app_databade.plot_handles.axes_cs_pca.selected_points);
            end
            end
            
            num_points = 6;
            coordinates = nan(num_points+1, 2);
            pop_up_plot_handle = plot(pop_up_axes_handle , coordinates(:,1),    coordinates(:,2), 'o-', 'Color', 'g'); % CS
            for counter_points = 1 : 1 : num_points
                [x_, y_] = ginput(1);
                coordinates(counter_points, 1) = x_;
                coordinates(counter_points, 2) = y_;
                delete(pop_up_plot_handle);
                pop_up_plot_handle = plot(pop_up_axes_handle , coordinates(:,1),    coordinates(:,2), 'o-', 'Color', 'g'); % CS
            end
            coordinates(num_points+1, :) = coordinates(1, :);
            delete(pop_up_plot_handle);
            pop_up_plot_handle = plot(pop_up_axes_handle , coordinates(:,1),    coordinates(:,2), 'o-', 'Color', 'g'); % CS
            close(pop_up_fig_handle);
            app.app_databade.plot_handles.axes_cs_wave.polygon = plot(app.axes_cs_wave , coordinates(:,1),    coordinates(:,2), '.-', 'Color', 'g'); % CS
            
            num_cs = size(cs_wave_y_axis, 2);
            is_inside_ = true(num_cs, 1);
            for counter_cs_wave = 1 : 1 : num_cs
                is_inside_wave_ = inpolygon(cs_wave_x_axis,cs_wave_y_axis(:,counter_cs_wave),...
                    coordinates(:,1), coordinates(:,2));
                is_inside_(counter_cs_wave) = sum(is_inside_wave_) > 0;
            end
            if sum(is_inside_)>0
                app.app_databade.plot_handles.axes_cs_wave.selected_waves = plot(app.axes_cs_wave , cs_wave_x_axis,     cs_wave_y_axis(:, is_inside_), '-k'); % CS
                
                [~, CS_pca_score, ~] = pca(app.app_databade.current_slot_data.CS_waveform);
                pca_1 = CS_pca_score(:,1);
                pca_2 = CS_pca_score(:,2);
                app.app_databade.plot_handles.axes_cs_pca.selected_points = plot(app.axes_cs_pca , pca_1(is_inside_),     pca_2(is_inside_), '.k'); % CS
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.grid_main = uigridlayout(app.UIFigure);
            app.grid_status = uigridlayout(app.grid_main);
            app.txtstatic_status_label = uilabel(app.grid_status);
            app.txtstatic_status_label.Text = 'Status:';
            app.txtstatic_status_message = uilabel(app.grid_status);
            app.txtstatic_status_message.Text = 'Message';
            app.lamp_status = uilamp(app.grid_status);
            app.grid_file_and_slot = uigridlayout(app.grid_main);
            app.pushbtn_load_file = uibutton(app.grid_file_and_slot, 'push');
            app.pushbtn_load_file.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_load_fileButtonPushed, true);
            app.pushbtn_load_file.Text = 'Load File';
            app.pushbtn_save_file = uibutton(app.grid_file_and_slot, 'push');
            app.pushbtn_save_file.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_save_fileButtonPushed, true);
            app.pushbtn_save_file.Text = 'Save File';
            app.txtstatic_filename = uilabel(app.grid_file_and_slot);
            app.txtstatic_filename.Text = 'File Name';
            app.txtstatic_filepath = uilabel(app.grid_file_and_slot);
            app.txtstatic_filepath.Text = 'File Path';
            app.grid_slot_pushbtn = uigridlayout(app.grid_file_and_slot);
            app.pushbtn_slot_back = uibutton(app.grid_slot_pushbtn, 'push');
            app.pushbtn_slot_back.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_slot_backButtonPushed, true);
            app.pushbtn_slot_back.Text = 'Slot Back';
            app.pushbtn_slot_next = uibutton(app.grid_slot_pushbtn, 'push');
            app.pushbtn_slot_next.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_slot_nextButtonPushed, true);
            app.pushbtn_slot_next.Text = 'Slot Next';
            app.grid_slot_num = uigridlayout(app.grid_file_and_slot);
            app.txtedit_slot_numCurrent = uieditfield(app.grid_slot_num, 'numeric');
            app.txtedit_slot_numCurrent.Limits = [1 500];
            app.txtedit_slot_numCurrent.ValueDisplayFormat = '%.0f';
            app.txtedit_slot_numCurrent.Value = 1;
            app.txtstatic_slot_label1 = uilabel(app.grid_slot_num);
            app.txtstatic_slot_label1.Text = 'Slot#';
            app.txtstatic_slot_label2 = uilabel(app.grid_slot_num);
            app.txtstatic_slot_label2.Text = '/';
            app.txtstatic_slot_numTotal = uilabel(app.grid_slot_num);
            app.txtstatic_slot_numTotal.Text = '200';
            app.grid_signal = uigridlayout(app.grid_main);
            app.grid_raw_and_flip = uigridlayout(app.grid_signal);
            app.grid_flip = uigridlayout(app.grid_raw_and_flip);
            app.chkbox_neg_ss_hipass = uicheckbox(app.grid_flip);
            app.chkbox_neg_ss_hipass.Text = 'Negative SS HiPass Peak';
            app.chkbox_neg_ss_hipass.Value = true;
            app.chkbox_neg_cs_hipass = uicheckbox(app.grid_flip);
            app.chkbox_neg_cs_hipass.Text = 'Negative CS HiPass Peak';
            app.chkbox_neg_cs_hipass.Value = true;
            app.chkbox_pos_cs_lopass = uicheckbox(app.grid_flip);
            app.chkbox_pos_cs_lopass.Text = 'Positive CS LoPass Peak';
            app.chkbox_pos_cs_lopass.Value = true;
            app.grid_raw = uigridlayout(app.grid_raw_and_flip);
            app.grid_threshold = uigridlayout(app.grid_raw);
            app.txtstatic_ss_threshold = uilabel(app.grid_threshold);
            app.txtstatic_ss_threshold.Text = 'SS HiPass Thresh';
            app.txtedit_ss_threshold = uieditfield(app.grid_threshold, 'numeric');
            app.txtedit_ss_threshold.Limits = [10 1000];
            app.txtedit_ss_threshold.ValueDisplayFormat = '%.0f';
            app.txtedit_ss_threshold.Value = 100;
            app.txtstatic_cs_threshold = uilabel(app.grid_threshold);
            app.txtstatic_cs_threshold.Text = 'CS LoPass Thresh';
            app.txtedit_cs_threshold = uieditfield(app.grid_threshold, 'numeric');
            app.txtedit_cs_threshold.Limits = [10 1000];
            app.txtedit_cs_threshold.ValueDisplayFormat = '%.0f';
            app.txtedit_cs_threshold.Value = 100;
            app.pushbtn_rawSignal_refresh = uibutton(app.grid_threshold, 'push');
            app.pushbtn_rawSignal_refresh.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_rawSignal_refreshButtonPushed, true);
            app.pushbtn_rawSignal_refresh.Text = 'Re-Evaluate';
            app.axes_rawSignal_cs_dist = uiaxes(app.grid_raw);
            title(app.axes_rawSignal_cs_dist, 'CS Peak Dist.')
            xlabel(app.axes_rawSignal_cs_dist, 'Count')
            ylabel(app.axes_rawSignal_cs_dist, 'Amp')
            app.axes_rawSignal_ss_dist = uiaxes(app.grid_raw);
            title(app.axes_rawSignal_ss_dist, 'SS Peak Dist.')
            xlabel(app.axes_rawSignal_ss_dist, 'Count')
            ylabel(app.axes_rawSignal_ss_dist, 'Amp')
            app.axes_rawSignal = uiaxes(app.grid_raw);
            title(app.axes_rawSignal, 'Raw Signal')
            xlabel(app.axes_rawSignal, 'Time')
            ylabel(app.axes_rawSignal, 'Amp')
            app.grid_ss_cs_params = uigridlayout(app.grid_signal);
            app.axes_ss_wave = uiaxes(app.grid_ss_cs_params);
            title(app.axes_ss_wave, 'SS Waveform')
            xlabel(app.axes_ss_wave, 'Ind')
            ylabel(app.axes_ss_wave, 'Amp')
            app.axes_ss_isi = uiaxes(app.grid_ss_cs_params);
            title(app.axes_ss_isi, 'SS ISI')
            xlabel(app.axes_ss_isi, 'Time')
            ylabel(app.axes_ss_isi, 'Count')
            app.axes_ss_pca = uiaxes(app.grid_ss_cs_params);
            title(app.axes_ss_pca, 'SS PCA')
            xlabel(app.axes_ss_pca, 'PCA1')
            ylabel(app.axes_ss_pca, 'PCA2')
            app.axes_ss_corr = uiaxes(app.grid_ss_cs_params);
            title(app.axes_ss_corr, 'SSxSS Corr')
            xlabel(app.axes_ss_corr, 'Ind')
            ylabel(app.axes_ss_corr, 'P')
            app.axes_cs_wave = uiaxes(app.grid_ss_cs_params);
            title(app.axes_cs_wave, 'CS Waveform')
            xlabel(app.axes_cs_wave, 'Ind')
            ylabel(app.axes_cs_wave, 'Amp')
            app.axes_cs_isi = uiaxes(app.grid_ss_cs_params);
            title(app.axes_cs_isi, 'CS ISI')
            xlabel(app.axes_cs_isi, 'Time')
            ylabel(app.axes_cs_isi, 'Count')
            app.axes_cs_pca = uiaxes(app.grid_ss_cs_params);
            title(app.axes_cs_pca, 'CS PCA')
            xlabel(app.axes_cs_pca, 'PCA1')
            ylabel(app.axes_cs_pca, 'PCA2')
            app.axes_cs_corr = uiaxes(app.grid_ss_cs_params);
            title(app.axes_cs_corr, 'CSxSS Corr')
            xlabel(app.axes_cs_corr, 'Ind')
            ylabel(app.axes_cs_corr, 'P')
            app.grid_ss_wave = uigridlayout(app.grid_ss_cs_params);
            app.pushbtn_ss_wave_select = uibutton(app.grid_ss_wave, 'push');
            app.pushbtn_ss_wave_select.Text = 'S';
            app.pushbtn_ss_wave_delete = uibutton(app.grid_ss_wave, 'push');
            app.pushbtn_ss_wave_delete.Text = 'D';
            app.pushbtn_ss_wave_keep = uibutton(app.grid_ss_wave, 'push');
            app.pushbtn_ss_wave_keep.Text = 'K';
            app.pushbtn_ss_wave_refresh = uibutton(app.grid_ss_wave, 'push');
            app.pushbtn_ss_wave_refresh.Text = 'R';
            app.grid_ss_pca = uigridlayout(app.grid_ss_cs_params);
            app.pushbtn_ss_pca_select = uibutton(app.grid_ss_pca, 'push');
            app.pushbtn_ss_pca_select.Text = 'S';
            app.pushbtn_ss_pca_delete = uibutton(app.grid_ss_pca, 'push');
            app.pushbtn_ss_pca_delete.Text = 'D';
            app.pushbtn_ss_pca_keep = uibutton(app.grid_ss_pca, 'push');
            app.pushbtn_ss_pca_keep.Text = 'K';
            app.pushbtn_ss_pca_refresh = uibutton(app.grid_ss_pca, 'push');
            app.pushbtn_ss_pca_refresh.Text = 'R';
            app.grid_cs_wave = uigridlayout(app.grid_ss_cs_params);
            app.pushbtn_cs_wave_select = uibutton(app.grid_cs_wave, 'push');
            app.pushbtn_cs_wave_select.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_cs_wave_selectButtonPushed, true);
            app.pushbtn_cs_wave_select.Text = 'S';
            app.pushbtn_cs_wave_delete = uibutton(app.grid_cs_wave, 'push');
            app.pushbtn_cs_wave_delete.Text = 'D';
            app.pushbtn_cs_wave_keep = uibutton(app.grid_cs_wave, 'push');
            app.pushbtn_cs_wave_keep.Text = 'K';
            app.pushbtn_cs_wave_refresh = uibutton(app.grid_cs_wave, 'push');
            app.pushbtn_cs_wave_refresh.Text = 'R';
            app.grid_cs_pca = uigridlayout(app.grid_ss_cs_params);
            app.pushbtn_cs_pca_select = uibutton(app.grid_cs_pca, 'push');
            app.pushbtn_cs_pca_select.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_cs_pca_selectButtonPushed, true);
            app.pushbtn_cs_pca_select.Text = 'S';
            app.pushbtn_cs_pca_delete = uibutton(app.grid_cs_pca, 'push');
            app.pushbtn_cs_pca_delete.Text = 'D';
            app.pushbtn_cs_pca_keep = uibutton(app.grid_cs_pca, 'push');
            app.pushbtn_cs_pca_keep.Text = 'K';
            app.pushbtn_cs_pca_refresh = uibutton(app.grid_cs_pca, 'push');
            app.pushbtn_cs_pca_refresh.Text = 'R';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ESN_CS_SS_Sorter_App_v2_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end