classdef ESN_CS_SS_Sorter_App_v1_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        txtstatic_status_title         matlab.ui.control.Label
        txtstatic_message              matlab.ui.control.Label
        GridLayout3                    matlab.ui.container.GridLayout
        GridLayout4                    matlab.ui.container.GridLayout
        pushbtn_load_file              matlab.ui.control.Button
        pushbtn_save_file              matlab.ui.control.Button
        GridLayout5                    matlab.ui.container.GridLayout
        txtstatic_filename             matlab.ui.control.Label
        txtstatic_filepath             matlab.ui.control.Label
        GridLayout6                    matlab.ui.container.GridLayout
        GridLayout7                    matlab.ui.container.GridLayout
        txtstatic_total_slots          matlab.ui.control.Label
        SlotLabel                      matlab.ui.control.Label
        spinner_current_slot           matlab.ui.control.Spinner
        Label                          matlab.ui.control.Label
        GridLayout9                    matlab.ui.container.GridLayout
        pushbtn_previous               matlab.ui.control.Button
        pushbtn_next                   matlab.ui.control.Button
        GridLayout8                    matlab.ui.container.GridLayout
        FlipSignalSwitchLabel          matlab.ui.control.Label
        switch_flip_signal             matlab.ui.control.Switch
        GridLayout10                   matlab.ui.container.GridLayout
        GridLayout11                   matlab.ui.container.GridLayout
        GridLayout12                   matlab.ui.container.GridLayout
        CSLabel                        matlab.ui.control.Label
        SSLabel                        matlab.ui.control.Label
        slider_CS_threshold            matlab.ui.control.Slider
        slider_SS_threshold            matlab.ui.control.Slider
        axes_signals                   matlab.ui.control.UIAxes
        GridLayout13                   matlab.ui.container.GridLayout
        GridLayout14                   matlab.ui.container.GridLayout
        pushbtn_refresh                matlab.ui.control.Button
        txtstatic_CS_description       matlab.ui.control.Label
        GridLayout15                   matlab.ui.container.GridLayout
        txtstatic_CS_peak_label        matlab.ui.control.Label
        txtstatic_CS_peak              matlab.ui.control.Label
        GridLayout15_3                 matlab.ui.container.GridLayout
        pushbtn_CS_previous            matlab.ui.control.Button
        pushbtn_CS_next                matlab.ui.control.Button
        GridLayout15_4                 matlab.ui.container.GridLayout
        txtstatic_CS_width_label       matlab.ui.control.Label
        txtstatic_CS_width             matlab.ui.control.Label
        GridLayout15_5                 matlab.ui.container.GridLayout
        txtstatic_CS_prominance_label  matlab.ui.control.Label
        txtstatic_CS_prominance        matlab.ui.control.Label
        pushbtn_CS_delete              matlab.ui.control.Button
        axes_SS_waveform               matlab.ui.control.UIAxes
        axes_CS_waveform               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        app_databade % database for the user defined variables
    end
    
    methods (Access = private)
        function ESN_filter_EPHYS(app)
            sample_freq = 30e3; %Hz
            Wn_hipass = [300 3000]/(sample_freq/2);
            [b_hipass,a_hipass] = butter(4, Wn_hipass);
            [b_lopass,a_lopass] = butter(4, Wn_hipass(1), 'low');
            
            fieldnames_EPHYS = fieldnames(app.app_databade.EPHYS);
            for counter_fields = 1 : 1 : length(fieldnames_EPHYS)
                field_name = fieldnames_EPHYS{counter_fields};
                % if field name does not contain 'CH_' skip it
                if ~contains(field_name, 'CH_')
                    continue;
                end
                % if field name is 'CH_EVE' skip it
                if contains(field_name, 'CH_EVE')
                    continue;
                end
                % common average the ch_data, except the CH_CMN
                if contains(field_name, 'CH_CMN')
                    app.app_databade.EPHYS.CH_CMN.ch_data_cmn       = app.app_databade.EPHYS.CH_CMN.ch_data(:);
                else
                    app.app_databade.EPHYS.(field_name).ch_data_cmn = app.app_databade.EPHYS.(field_name).ch_data(:) - app.app_databade.EPHYS.CH_CMN.ch_data(:);
                    app.app_databade.EPHYS.(field_name) = rmfield(app.app_databade.EPHYS.(field_name),{'ch_data', 'ch_info', 'ch_time'});
                end
                % band pass filter the data
                app.app_databade.EPHYS.(field_name).ch_data_hipass = filtfilt( b_hipass, a_hipass, app.app_databade.EPHYS.(field_name).ch_data_cmn(:));
                % low pass filter and then detrend the data
                app.app_databade.EPHYS.(field_name).ch_data_lopass = filtfilt( b_lopass, a_lopass, app.app_databade.EPHYS.(field_name).ch_data_cmn(:));
                app.app_databade.EPHYS.(field_name).ch_data_lopass = detrend(app.app_databade.EPHYS.(field_name).ch_data_lopass);
            end
            
            app.app_databade.EPHYS.time = app.app_databade.EPHYS.CH_CMN.ch_time(:);
            app.app_databade.EPHYS.CH_CMN = rmfield(app.app_databade.EPHYS.CH_CMN,{'ch_data', 'ch_info', 'ch_time'});
        end
        
        function ESN_plot_slot_data(app)
            cla(app.axes_signals)
            grid(app.axes_signals, 'on')
            hold(app.axes_signals,'on')
            app.app_databade.plot_handles.axes_signals.h1 = plot(app.axes_signals ,app.app_databade.current_slot_data.time,     app.app_databade.current_slot_data.hipass, '-b');
            app.app_databade.plot_handles.axes_signals.h2 = plot(app.axes_signals, app.app_databade.current_slot_data.time,     app.app_databade.current_slot_data.lopass, '-r');
            app.app_databade.plot_handles.axes_signals.h3 = plot(app.axes_signals, app.app_databade.current_slot_data.CS_time,  app.app_databade.current_slot_data.CS_peak_value, 'or');
            app.app_databade.plot_handles.axes_signals.h4 = plot(app.axes_signals, app.app_databade.current_slot_data.SS_time, -app.app_databade.current_slot_data.SS_peak_value, 'ob');
            app.app_databade.plot_handles.axes_signals.h5 = plot(app.axes_signals, [app.app_databade.current_slot_data.time(1); app.app_databade.current_slot_data.time(end)],    [1; 1]*app.slider_CS_threshold.Value, '-', 'Color', [0.9, 0.3, 0.3]);
            app.app_databade.plot_handles.axes_signals.h6 = plot(app.axes_signals, [app.app_databade.current_slot_data.time(1); app.app_databade.current_slot_data.time(end)],    [1; 1]*app.slider_SS_threshold.Value, '-', 'Color', [0.3, 0.3, 0.9]);
            app.axes_signals.XLimMode = 'auto';
            app.axes_signals.YLimMode = 'auto';
            app.axes_signals.Interactions     = [app.app_databade.plot_handles.panInterac app.app_databade.plot_handles.zoomInterac];
            
            cla(app.axes_SS_waveform)
            grid(app.axes_SS_waveform, 'on')
            app.app_databade.plot_handles.axes_SS_waveform.h1 = plot(app.axes_SS_waveform, app.app_databade.current_slot_data.SS_waveform', '-b');
            app.axes_SS_waveform.XLimMode = 'auto';
            app.axes_SS_waveform.YLimMode = 'auto';
            app.axes_SS_waveform.Interactions = [app.app_databade.plot_handles.panInterac app.app_databade.plot_handles.zoomInterac];
            
            cla(app.axes_CS_waveform)
            grid(app.axes_CS_waveform, 'on')
            app.app_databade.plot_handles.axes_CS_waveform.h1 = plot(app.axes_CS_waveform, app.app_databade.current_slot_data.CS_waveform', '-r');
            app.axes_CS_waveform.XLimMode = 'auto';
            app.axes_CS_waveform.YLimMode = 'auto';
            app.axes_CS_waveform.Interactions = [app.app_databade.plot_handles.panInterac app.app_databade.plot_handles.zoomInterac];
            
        end
        
        function ESN_evaluate_slot_data(app)
            app.txtstatic_message.Text = ['Evaluating ' 'slot data # ' num2str(app.app_databade.current_slot_num) ' ... '];
            pause(0.050);
            time_slot_str = app.app_databade.time_edges(app.app_databade.current_slot_num);
            time_slot_end = app.app_databade.time_edges(app.app_databade.current_slot_num+1);
            ind_str = find(app.app_databade.EPHYS.time >= time_slot_str, 1, 'first');
            ind_end = find(app.app_databade.EPHYS.time <= time_slot_end, 1, 'last');
            inds_slot = ind_str : 1 : ind_end;
            
            threshold_CS = app.slider_CS_threshold.Value;%str2double((app.txtedit_CS_threshold.Value));
            threshold_CS_time = 0.005;
            threshold_SS = -app.slider_SS_threshold.Value;%str2double((app.txtedit_SS_threshold.Value));
            threshold_SS_time = 0.001;
            time_slot = app.app_databade.EPHYS.time(inds_slot);
            lopass_slot = app.app_databade.EPHYS.CH_1.ch_data_lopass(inds_slot);
            hipass_slot = app.app_databade.EPHYS.CH_1.ch_data_hipass(inds_slot);
            % find peaks in the lopass_slot data as CS events
            [CS_peak_lopass, CS_ind_lopass, CS_width_lopass, CS_prominence_lopass] = findpeaks(lopass_slot , 'MinPeakHeight',threshold_CS,'MinPeakProminence',threshold_CS,'MinPeakDistance',threshold_CS_time);
            % represent the CS_ind as absolute values instead of local
            % values of the current time slot
            CS_ind_lopass  = CS_ind_lopass + ind_str - 1;
            CS_time_lopass = app.app_databade.EPHYS.time(CS_ind_lopass);
            app.app_databade.current_slot_data.CS_peak_lopass       = CS_peak_lopass;
            app.app_databade.current_slot_data.CS_prominence_lopass = CS_prominence_lopass;
            app.app_databade.current_slot_data.CS_width_lopass      = CS_width_lopass;
            app.app_databade.current_slot_data.CS_time_lopass       = CS_time_lopass;
            app.app_databade.current_slot_data.CS_ind_lopass        = CS_ind_lopass;
            % align CS waveform to the zero-crossing point and save CS data
            ESN_align_CS(app);
            % find peaks in the hipass_slot data as SS events
            [SS_peak_value, SS_ind, SS_width, SS_prominence] = findpeaks(-hipass_slot, 'MinPeakHeight',threshold_SS,'MinPeakProminence',threshold_SS,'MinPeakDistance',threshold_SS_time);
            % represent the SS_ind as absolute values instead of local
            % values of the current time slot
            SS_ind  = SS_ind + ind_str - 1;
            % find overlapping CS_ind in SS_ind and remove them from SS_ind
            CS_ind   = app.app_databade.current_slot_data.CS_ind;
            SS_num_total = length(SS_ind);
            SS_valid = true(SS_num_total, 1);
            half_window_length_   = 8; % full window length is 16 data points or ~0.5ms
            if (~isempty(CS_ind)) && (~isempty(SS_ind))
                CS_ind_first_element_ = min([SS_ind(1),   CS_ind(1)])   - (2*half_window_length_);
                CS_ind_last_element_  = max([SS_ind(end), CS_ind(end)]) + (2*half_window_length_);
                CS_ind    = [CS_ind_first_element_; CS_ind(:); CS_ind_last_element_];
                counter_CS = 1;
                for counter_SS = 1 : SS_num_total
                    SS_ind_current_  = SS_ind(counter_SS);
                    CS_ind_previous_ = CS_ind(counter_CS);
                    CS_ind_next_     = CS_ind(counter_CS+1);
                    if (abs(SS_ind_current_-CS_ind_previous_)<half_window_length_) || (abs(CS_ind_next_-SS_ind_current_)<half_window_length_)
                        SS_valid(counter_SS) = false;
                    end
                    if SS_ind_current_ >= CS_ind_next_
                        counter_CS = counter_CS + 1;
                    end
                end
                
            end
            
            % remove shared ind between SS_ind and CS_ind from SS_ind
            SS_ind        = SS_ind(       SS_valid, :);
            SS_peak_value = SS_peak_value(SS_valid, :);
            SS_width      = SS_width(     SS_valid, :);
            SS_prominence = SS_prominence(SS_valid, :);
            % extract SS_time
            SS_time = app.app_databade.EPHYS.time(SS_ind);
            % find 4ms around each CS event, % 2ms before and after the peak, 2ms with 30KHz will be 60 data points
            inds_span_SS = (-60+1) : 1 : (120);
            length_time_ = ind_str + length(hipass_slot) -1;
            SS_inds = repmat( SS_ind(:), 1, length(inds_span_SS)) + repmat(inds_span_SS, length(SS_ind), 1);
            SS_inds( SS_inds < 1 ) = 1;
            SS_inds( SS_inds > length_time_ ) = length_time_;
            SS_waveform = app.app_databade.EPHYS.CH_1.ch_data_hipass(SS_inds);
            
            % find SS_time that are in -0.5ms and +2ms of CS_time and delete them
            % TODO
            
            app.app_databade.current_slot_data.time          = time_slot;
            app.app_databade.current_slot_data.hipass        = hipass_slot;
            app.app_databade.current_slot_data.lopass        = lopass_slot;
            app.app_databade.current_slot_data.SS_peak_value = SS_peak_value;
            app.app_databade.current_slot_data.SS_time       = SS_time;
            app.app_databade.current_slot_data.SS_width      = SS_width;
            app.app_databade.current_slot_data.SS_prominence = SS_prominence;
            app.app_databade.current_slot_data.SS_ind        = SS_ind;
            app.app_databade.current_slot_data.SS_inds       = SS_inds;
            app.app_databade.current_slot_data.SS_waveform   = SS_waveform;
            
            if isempty(app.app_databade.current_slot_data.CS_time)
                app.app_databade.current_CS_data.current_num = 0;
                app.app_databade.current_CS_data.max_num = 0;
                app.txtstatic_CS_peak.Text = '0.0';
                app.txtstatic_CS_width.Text = '0.0';
                app.txtstatic_CS_prominance.Text = '0.0';
            else
                app.app_databade.current_CS_data.current_num = 1;
                app.app_databade.current_CS_data.max_num = length(app.app_databade.current_slot_data.CS_time);
            end
            
            ESN_plot_slot_data(app);
            
            if (app.app_databade.current_CS_data.current_num ~= 0)
                app.app_databade.plot_handles.axes_CS_waveform.h1(app.app_databade.current_CS_data.current_num).Color = [0 0 0];
                app.app_databade.plot_handles.axes_CS_waveform.h1(app.app_databade.current_CS_data.current_num).LineWidth = 2;
                app.app_databade.plot_handles.axes_signals.h7 = plot(app.axes_signals, app.app_databade.current_slot_data.CS_time(app.app_databade.current_CS_data.current_num),  app.app_databade.current_slot_data.CS_peak_value(app.app_databade.current_CS_data.current_num), '*r');
                app.txtstatic_CS_peak.Text = num2str(app.app_databade.current_slot_data.CS_peak_value(app.app_databade.current_CS_data.current_num, 1), '%4.1f');
                app.txtstatic_CS_width.Text = num2str(app.app_databade.current_slot_data.CS_width(app.app_databade.current_CS_data.current_num, 1)*1000, '%4.1f');
                app.txtstatic_CS_prominance.Text = num2str(app.app_databade.current_slot_data.CS_prominence(app.app_databade.current_CS_data.current_num, 1), '%4.1f');
            end
            app.txtstatic_message.Text = ['Evaluating ' 'slot data # ' num2str(app.app_databade.current_slot_num) ' ... ' '-> Completed.'];
        end
        
        function ESN_save_slot_data(app)
            app.app_databade.slot_data.is_analyzed(   app.app_databade.current_slot_num, 1) = true;
            app.app_databade.slot_data.CS_peak_value{ app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.CS_peak_value;
            app.app_databade.slot_data.CS_time{       app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.CS_time;
            app.app_databade.slot_data.CS_width{      app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.CS_width;
            app.app_databade.slot_data.CS_prominence{ app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.CS_prominence;
            app.app_databade.slot_data.CS_ind{        app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.CS_ind;
            app.app_databade.slot_data.CS_inds{       app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.CS_inds;
            app.app_databade.slot_data.CS_waveform{   app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.CS_waveform;
            app.app_databade.slot_data.SS_peak_value{ app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.SS_peak_value;
            app.app_databade.slot_data.SS_time{       app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.SS_time;
            app.app_databade.slot_data.SS_width{      app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.SS_width;
            app.app_databade.slot_data.SS_prominence{ app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.SS_prominence;
            app.app_databade.slot_data.SS_ind{        app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.SS_ind;
            app.app_databade.slot_data.SS_inds{       app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.SS_inds;
            app.app_databade.slot_data.SS_waveform{   app.app_databade.current_slot_num, 1} = app.app_databade.current_slot_data.SS_waveform;
        end
        
        function ESN_extract_slot_data(app)
            app.txtstatic_message.Text = ['Extracting ' 'slot data # ' num2str(app.app_databade.current_slot_num) ' ... '];
            pause(0.050);
            time_slot_str = app.app_databade.time_edges(app.app_databade.current_slot_num);
            time_slot_end = app.app_databade.time_edges(app.app_databade.current_slot_num+1);
            ind_str = find(app.app_databade.EPHYS.time >= time_slot_str, 1, 'first');
            ind_end = find(app.app_databade.EPHYS.time <= time_slot_end, 1, 'last');
            inds_slot = ind_str : 1 : ind_end;
            time_slot = app.app_databade.EPHYS.time(inds_slot);
            lopass_slot = app.app_databade.EPHYS.CH_1.ch_data_lopass(inds_slot);
            hipass_slot = app.app_databade.EPHYS.CH_1.ch_data_hipass(inds_slot);
            
            app.app_databade.current_slot_data.time          = time_slot;
            app.app_databade.current_slot_data.hipass        = hipass_slot;
            app.app_databade.current_slot_data.lopass        = lopass_slot;
            app.app_databade.current_slot_data.CS_peak_value = app.app_databade.slot_data.CS_peak_value{ app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.CS_time       = app.app_databade.slot_data.CS_time{       app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.CS_width      = app.app_databade.slot_data.CS_width{      app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.CS_prominence = app.app_databade.slot_data.CS_prominence{ app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.CS_ind        = app.app_databade.slot_data.CS_ind{        app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.CS_inds       = app.app_databade.slot_data.CS_inds{       app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.CS_waveform   = app.app_databade.slot_data.CS_waveform{   app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.SS_peak_value = app.app_databade.slot_data.SS_peak_value{ app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.SS_time       = app.app_databade.slot_data.SS_time{       app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.SS_width      = app.app_databade.slot_data.SS_width{      app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.SS_prominence = app.app_databade.slot_data.SS_prominence{ app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.SS_ind        = app.app_databade.slot_data.SS_ind{        app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.SS_inds       = app.app_databade.slot_data.SS_inds{       app.app_databade.current_slot_num, 1};
            app.app_databade.current_slot_data.SS_waveform   = app.app_databade.slot_data.SS_waveform{   app.app_databade.current_slot_num, 1};
            
            if isempty(app.app_databade.current_slot_data.CS_time)
                app.app_databade.current_CS_data.current_num = 0;
                app.app_databade.current_CS_data.max_num = 0;
                app.txtstatic_CS_peak.Text = '0.0';
                app.txtstatic_CS_width.Text = '0.0';
                app.txtstatic_CS_prominance.Text = '0.0';
            else
                app.app_databade.current_CS_data.current_num = 1;
                app.app_databade.current_CS_data.max_num = length(app.app_databade.current_slot_data.CS_time);
            end
            
            ESN_plot_slot_data(app);
            
            if (app.app_databade.current_CS_data.current_num ~= 0)
                app.app_databade.plot_handles.axes_CS_waveform.h1(app.app_databade.current_CS_data.current_num).Color = [0 0 0];
                app.app_databade.plot_handles.axes_CS_waveform.h1(app.app_databade.current_CS_data.current_num).LineWidth = 2;
                app.app_databade.plot_handles.axes_signals.h7 = plot(app.axes_signals, app.app_databade.current_slot_data.CS_time(app.app_databade.current_CS_data.current_num),  app.app_databade.current_slot_data.CS_peak_value(app.app_databade.current_CS_data.current_num), '*r');
                app.txtstatic_CS_peak.Text = num2str(app.app_databade.current_slot_data.CS_peak_value(app.app_databade.current_CS_data.current_num, 1), '%4.1f');
                app.txtstatic_CS_width.Text = num2str(app.app_databade.current_slot_data.CS_width(app.app_databade.current_CS_data.current_num, 1)*1000, '%4.1f');
                app.txtstatic_CS_prominance.Text = num2str(app.app_databade.current_slot_data.CS_prominence(app.app_databade.current_CS_data.current_num, 1), '%4.1f');
            end
            app.txtstatic_message.Text = ['Extracting ' 'slot data # ' num2str(app.app_databade.current_slot_num) ' ... ' '-> Completed.'];
        end
        
        function ESN_align_CS(app)
            data.ch_data_time   = app.app_databade.EPHYS.time;
            data.ch_data_hipass = app.app_databade.EPHYS.CH_1.ch_data_hipass;
            data.CS_time        = app.app_databade.current_slot_data.CS_time_lopass;
            threshold_CS        = 0.8 * app.slider_CS_threshold.Value; % reduce the threshold by 80%, make it less conservative
            threshold_CS_time   = 0.005;
            data_length          = length(data.ch_data_time);
            CS_num_total         = length(data.CS_time);
            if CS_num_total > 1
                CS_valid             = false(CS_num_total, 1);
                CS_time_hipass       = nan(CS_num_total, 1);
                CS_ind               = nan(CS_num_total, 1);
                CS_inds              = nan(CS_num_total, 180);
                CS_waveform_hipass   = nan(CS_num_total, 180);
                CS_peak_value_hipass = nan(CS_num_total, 1);
                CS_prominence_hipass = nan(CS_num_total, 1);
                CS_width_hipass      = nan(CS_num_total, 1);
                
                for counter_CS = 1 : 1 : CS_num_total
                    if counter_CS == 1
                        % search the entire time_vector to find the
                        % beginning of the 1st CS
                        CS_interval_begin_ = find(data.ch_data_time >= data.CS_time(counter_CS, 1), 1, 'first');
                        % shift back the CS_interval_begin_ by 1s (30K)
                        CS_interval_begin_ = max([(CS_interval_begin_ - 30e3) 1]);
                    else
                        counter_CS_not_nan_ = find(~isnan(CS_ind), 1, 'last');
                        CS_interval_begin_ = CS_ind(counter_CS_not_nan_);
                    end
                    CS_interval_end_   = min([(CS_interval_begin_ + 30e4) data_length]);
                    CS_interval_inds   = CS_interval_begin_ : 1 : CS_interval_end_;
                    CS_interval_time   = data.ch_data_time(CS_interval_inds);
                    CS_interval_hipass = data.ch_data_hipass(CS_interval_inds);
                    
                    CS_ind_ = find(CS_interval_time >= data.CS_time(counter_CS, 1), 1, 'first');
                    
                    if isempty(CS_ind_)
                        % if there is no CS in the 10s window from the
                        % previous CS, then search the entire time_vector
                        CS_interval_begin_ = find(data.ch_data_time >= data.CS_time(counter_CS, 1), 1, 'first');
                        CS_interval_begin_ = max([(CS_interval_begin_ - 30e3) 1]); % shift back the CS_interval_begin_ by 1s (30K)
                        CS_interval_end_   = min([(CS_interval_begin_ + 30e4) data_length]);
                        CS_interval_inds   = CS_interval_begin_ : 1 : CS_interval_end_;
                        CS_interval_time   = data.ch_data_time(  CS_interval_inds);
                        CS_interval_hipass = data.ch_data_hipass(CS_interval_inds);
                        
                        CS_ind_ = find(CS_interval_time >= data.CS_time(counter_CS, 1), 1, 'first');
                    end
                    if(CS_ind_<150)
                        CS_ind_ = 150;
                    elseif(CS_ind_>(length(CS_interval_inds)-150))
                        CS_ind_ = length(CS_interval_inds)-150;
                    end
                    CS_inds_ = (CS_ind_ - 150+1) : 1 : (CS_ind_ + 150);
                    CS_waveform_hipass_ = CS_interval_hipass(CS_inds_, 1)';
                    CS_waveform_time_   = CS_interval_time(CS_inds_, 1)';
                    [CS_peak_value_hipass_, CS_time_hipass_, CS_width_hipass_, CS_prominence_hipass_] = findpeaks(CS_waveform_hipass_ , CS_waveform_time_, 'SortStr', 'descend', 'NPeaks', 1);%, 'MinPeakHeight',threshold_CS,'MinPeakProminence',threshold_CS,'MinPeakDistance',threshold_CS_time);
                    if isempty(CS_time_hipass_)
                        continue;
                    end
                    CS_ind_local_ = find(CS_waveform_time_ >= CS_time_hipass_, 1, 'first');
                    if isempty(CS_ind_local_)
                        continue;
                    end
                    ind_upcross = find((CS_waveform_hipass_(1:CS_ind_local_-1) <= 0) & (CS_waveform_hipass_(2:CS_ind_local_) > 0), 1, 'last');
                    if isempty(ind_upcross)
                        continue;
                    end
                    ind_localMin_b4_upcross = find((diff(CS_waveform_hipass_(1:ind_upcross-1)) <= 0) & (diff(CS_waveform_hipass_(2:ind_upcross)) > 0), 1, 'last');
                    if isempty(ind_localMin_b4_upcross)
                        continue;
                    end
                    CS_time_hipass_ = CS_waveform_time_(ind_localMin_b4_upcross);
                    CS_ind_  = CS_inds_(ind_localMin_b4_upcross);
                    CS_inds_ = (CS_ind_ - 60+1) : 1 : (CS_ind_ + 120);
                    CS_inds_(CS_inds_<1) = 1;
                    CS_inds_(CS_inds_>length(CS_interval_inds)) = length(CS_interval_inds);
                    CS_waveform_hipass_ = CS_interval_hipass(CS_inds_, 1)';
                    CS_ind_  = CS_interval_inds(CS_ind_);
                    CS_inds_ = CS_interval_inds(CS_inds_);
                    
                    CS_valid(            counter_CS, :) = true;
                    CS_ind(              counter_CS, :) = CS_ind_;
                    CS_peak_value_hipass(counter_CS, :) = CS_peak_value_hipass_;
                    CS_time_hipass(      counter_CS, :) = CS_time_hipass_;
                    CS_width_hipass(     counter_CS, :) = CS_width_hipass_;
                    CS_prominence_hipass(counter_CS, :) = CS_prominence_hipass_;
                    CS_waveform_hipass(  counter_CS, :) = CS_waveform_hipass_(:)';
                    CS_inds(             counter_CS, :) = CS_inds_(:)';
                end
                
                app.app_databade.current_slot_data.CS_peak_value = CS_peak_value_hipass(CS_valid,:);
                app.app_databade.current_slot_data.CS_prominence = CS_prominence_hipass(CS_valid,:);
                app.app_databade.current_slot_data.CS_width      = CS_width_hipass(CS_valid,:);
                app.app_databade.current_slot_data.CS_time       = CS_time_hipass(CS_valid,:);
                app.app_databade.current_slot_data.CS_ind        = CS_ind(CS_valid,:);
                app.app_databade.current_slot_data.CS_inds       = CS_inds(CS_valid,:);
                app.app_databade.current_slot_data.CS_waveform   = CS_waveform_hipass(CS_valid,:);
                
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ESN_CS_SS_Sorter_OpeningFcn(app)
            warning('off','signal:findpeaks:largeMinPeakHeight');
            app.app_databade.SS_threshold = 300;
            app.app_databade.CS_threshold = 200;
            app.app_databade.current_slot_num = 1;
            app.app_databade.total_num_slots = 180;
            app.app_databade.EPHYS = struct;
            app.app_databade.EPHYS.CH_1.ch_data_cmn          = [];
            app.app_databade.EPHYS.CH_1.ch_data_lopass       = [];
            app.app_databade.EPHYS.CH_1.ch_data_hipass       = [];
            app.app_databade.current_slot_data.time          = [];
            app.app_databade.current_slot_data.hipass        = [];
            app.app_databade.current_slot_data.lopass        = [];
            app.app_databade.current_slot_data.CS_peak_value = [];
            app.app_databade.current_slot_data.CS_time       = [];
            app.app_databade.current_slot_data.CS_width      = [];
            app.app_databade.current_slot_data.CS_prominence = [];
            app.app_databade.current_slot_data.CS_ind        = [];
            app.app_databade.current_slot_data.CS_inds       = [];
            app.app_databade.current_slot_data.CS_waveform   = [];
            app.app_databade.current_slot_data.SS_peak_value = [];
            app.app_databade.current_slot_data.SS_time       = [];
            app.app_databade.current_slot_data.SS_width      = [];
            app.app_databade.current_slot_data.SS_prominence = [];
            app.app_databade.current_slot_data.SS_ind        = [];
            app.app_databade.current_slot_data.SS_inds       = [];
            app.app_databade.current_slot_data.SS_waveform   = [];
            app.app_databade.slot_data.is_analyzed   = false(1,1);
            app.app_databade.slot_data.CS_peak_value = cell(1,1);
            app.app_databade.slot_data.CS_time       = cell(1,1);
            app.app_databade.slot_data.CS_width      = cell(1,1);
            app.app_databade.slot_data.CS_prominence = cell(1,1);
            app.app_databade.slot_data.CS_ind        = cell(1,1);
            app.app_databade.slot_data.CS_inds       = cell(1,1);
            app.app_databade.slot_data.CS_waveform   = cell(1,1);
            app.app_databade.slot_data.SS_peak_value = cell(1,1);
            app.app_databade.slot_data.SS_time       = cell(1,1);
            app.app_databade.slot_data.SS_width      = cell(1,1);
            app.app_databade.slot_data.SS_prominence = cell(1,1);
            app.app_databade.slot_data.SS_ind        = cell(1,1);
            app.app_databade.slot_data.SS_inds       = cell(1,1);
            app.app_databade.slot_data.SS_waveform   = cell(1,1);
            app.app_databade.plot_handles.panInterac = panInteraction('Dimensions','x');
            app.app_databade.plot_handles.zoomInterac = zoomInteraction('Dimensions','x');
            app.axes_signals.Interactions     = [app.app_databade.plot_handles.panInterac app.app_databade.plot_handles.zoomInterac];
            app.axes_SS_waveform.Interactions = [app.app_databade.plot_handles.panInterac app.app_databade.plot_handles.zoomInterac];
            app.axes_CS_waveform.Interactions = [app.app_databade.plot_handles.panInterac app.app_databade.plot_handles.zoomInterac];
            app.txtstatic_message.Text = 'Please load ''10#_CH#.mat'' file and then ''10#_CMN1.mat'' to begin spike sorting.';
        end

        % Button pushed function: pushbtn_load_file
        function pushbtn_load_file_Callback(app, event)
            [file_name,file_path] = uigetfile([pwd filesep '*_CH*.mat'], 'Select _CH mat file');
            app.txtstatic_filename.Text = file_name;
            app.txtstatic_filepath.Text = file_path;
            
            app.txtstatic_message.Text = ['Loading ' file_name ' ... '];
            figure(app.figure1);
            pause(0.050);
            app.app_databade.EPHYS.CH_1   = load([file_path filesep file_name]);
            app.txtstatic_message.Text = ['Loading ' file_name ' ... ' '-> Completed.'];
            
            [file_name_cmn,file_path] = uigetfile([file_path filesep '*_CMN1.mat'], 'Select _CMN1 mat file');
            app.txtstatic_message.Text = ['Loading ' file_name_cmn ' ... '];
            figure(app.figure1);
            pause(0.050);
            app.app_databade.EPHYS.CH_CMN = load([file_path filesep file_name_cmn]);
            app.txtstatic_message.Text = ['Loading ' file_name_cmn ' ... ' '-> Completed.'];
            
            app.txtstatic_message.Text = ['Filtering ' 'EPHYS data' ' ... '];
            figure(app.figure1);
            pause(0.050);
            ESN_filter_EPHYS(app);
            app.txtstatic_message.Text = ['Filtering ' 'EPHYS data' ' ... ' '-> Completed.'];
            
            app.app_databade.total_num_slots = ceil((app.app_databade.EPHYS.time(end)-app.app_databade.EPHYS.time(1)) / 10);
            app.txtstatic_total_slots.Text = num2str(app.app_databade.total_num_slots);
            app.app_databade.time_edges = [(app.app_databade.EPHYS.time(1) : 10 : app.app_databade.EPHYS.time(end))'; app.app_databade.EPHYS.time(end)];
            
            app.app_databade.current_slot_num = 1;
            app.spinner_current_slot.Value = app.app_databade.current_slot_num;
            app.spinner_current_slot.Limits = [1 app.app_databade.total_num_slots];
            
            app.app_databade.slot_data.is_analyzed   = false(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.CS_peak_value = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.CS_time       = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.CS_width      = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.CS_prominence = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.CS_ind        = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.CS_inds       = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.CS_waveform   = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.SS_peak_value = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.SS_time       = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.SS_width      = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.SS_prominence = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.SS_ind        = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.SS_inds       = cell(app.app_databade.total_num_slots,1);
            app.app_databade.slot_data.SS_waveform   = cell(app.app_databade.total_num_slots,1);
            
            app.switch_flip_signal.Value = app.switch_flip_signal.Items{1};
            
            ESN_evaluate_slot_data(app);
            ESN_save_slot_data(app);
        end

        % Button pushed function: pushbtn_next
        function pushbtn_next_Callback(app, event)
            ESN_save_slot_data(app);
            app.app_databade.current_slot_num = app.app_databade.current_slot_num + 1;
            if app.app_databade.current_slot_num >= app.app_databade.total_num_slots
                app.app_databade.current_slot_num = app.app_databade.total_num_slots;
            end
            app.spinner_current_slot.Value = app.app_databade.current_slot_num;
            if app.app_databade.slot_data.is_analyzed(   app.app_databade.current_slot_num, 1)
                ESN_extract_slot_data(app);
            else
                ESN_evaluate_slot_data(app);
            end
            ESN_save_slot_data(app);
        end

        % Button pushed function: pushbtn_previous
        function pushbtn_previous_Callback(app, event)
            ESN_save_slot_data(app);
            app.app_databade.current_slot_num = app.app_databade.current_slot_num - 1;
            if app.app_databade.current_slot_num <= 0
                app.app_databade.current_slot_num = 1;
            end
            app.spinner_current_slot.Value = app.app_databade.current_slot_num;
            if app.app_databade.slot_data.is_analyzed(   app.app_databade.current_slot_num, 1)
                ESN_extract_slot_data(app);
            else
                ESN_evaluate_slot_data(app);
            end
            ESN_save_slot_data(app);
        end

        % Button pushed function: pushbtn_refresh
        function pushbtn_refresh_Callback(app, event)
            ESN_evaluate_slot_data(app);
        end

        % Value changing function: slider_CS_threshold
        function slider_CS_thresholdValueChanging(app, event)
            changingValue = event.Value;
            delete(app.app_databade.plot_handles.axes_signals.h5);
            app.app_databade.plot_handles.axes_signals.h5 = plot(app.axes_signals, [app.app_databade.current_slot_data.time(1); app.app_databade.current_slot_data.time(end)],    [1; 1]*changingValue, '-', 'Color', [0.9, 0.3, 0.3]);
        end

        % Value changing function: slider_SS_threshold
        function slider_SS_thresholdValueChanging(app, event)
            changingValue = event.Value;
            delete(app.app_databade.plot_handles.axes_signals.h6);
            app.app_databade.plot_handles.axes_signals.h6 = plot(app.axes_signals, [app.app_databade.current_slot_data.time(1); app.app_databade.current_slot_data.time(end)],    [1; 1]*changingValue, '-', 'Color', [0.3, 0.3, 0.9]);
        end

        % Value changed function: spinner_current_slot
        function spinner_current_slotValueChanged(app, event)
            ESN_save_slot_data(app);
            app.app_databade.current_slot_num = app.spinner_current_slot.Value;
            if app.app_databade.slot_data.is_analyzed(   app.app_databade.current_slot_num, 1)
                ESN_extract_slot_data(app);
            else
                ESN_evaluate_slot_data(app);
            end
            ESN_save_slot_data(app);
        end

        % Button pushed function: pushbtn_CS_next
        function pushbtn_CS_nextButtonPushed(app, event)
            if (app.app_databade.current_CS_data.current_num ~= 0)
                app.app_databade.plot_handles.axes_CS_waveform.h1(app.app_databade.current_CS_data.current_num).Color = [1 0 0];
                app.app_databade.plot_handles.axes_CS_waveform.h1(app.app_databade.current_CS_data.current_num).LineWidth = 0.5;
                app.app_databade.current_CS_data.current_num = app.app_databade.current_CS_data.current_num + 1;
                if (app.app_databade.current_CS_data.current_num > app.app_databade.current_CS_data.max_num)
                    app.app_databade.current_CS_data.current_num = app.app_databade.current_CS_data.max_num;
                end
                app.app_databade.plot_handles.axes_CS_waveform.h1(app.app_databade.current_CS_data.current_num).Color = [0 0 0];
                app.app_databade.plot_handles.axes_CS_waveform.h1(app.app_databade.current_CS_data.current_num).LineWidth = 2;
                delete(app.app_databade.plot_handles.axes_signals.h7)
                app.app_databade.plot_handles.axes_signals.h7 = plot(app.axes_signals, app.app_databade.current_slot_data.CS_time(app.app_databade.current_CS_data.current_num),  app.app_databade.current_slot_data.CS_peak_value(app.app_databade.current_CS_data.current_num), '*r');
                app.txtstatic_CS_peak.Text = num2str(app.app_databade.current_slot_data.CS_peak_value(app.app_databade.current_CS_data.current_num, 1), '%4.1f');
                app.txtstatic_CS_width.Text = num2str(app.app_databade.current_slot_data.CS_width(app.app_databade.current_CS_data.current_num, 1)*1000, '%4.1f');
                app.txtstatic_CS_prominance.Text = num2str(app.app_databade.current_slot_data.CS_prominence(app.app_databade.current_CS_data.current_num, 1), '%4.1f');
            end
        end

        % Button pushed function: pushbtn_CS_previous
        function pushbtn_CS_previousButtonPushed(app, event)
            if (app.app_databade.current_CS_data.current_num ~= 0)
                app.app_databade.plot_handles.axes_CS_waveform.h1(app.app_databade.current_CS_data.current_num).Color = [1 0 0];
                app.app_databade.plot_handles.axes_CS_waveform.h1(app.app_databade.current_CS_data.current_num).LineWidth = 0.5;
                app.app_databade.current_CS_data.current_num = app.app_databade.current_CS_data.current_num - 1;
                if (app.app_databade.current_CS_data.current_num < 1)
                    app.app_databade.current_CS_data.current_num = 1;
                end
                app.app_databade.plot_handles.axes_CS_waveform.h1(app.app_databade.current_CS_data.current_num).Color = [0 0 0];
                app.app_databade.plot_handles.axes_CS_waveform.h1(app.app_databade.current_CS_data.current_num).LineWidth = 2;
                delete(app.app_databade.plot_handles.axes_signals.h7)
                app.app_databade.plot_handles.axes_signals.h7 = plot(app.axes_signals, app.app_databade.current_slot_data.CS_time(app.app_databade.current_CS_data.current_num),  app.app_databade.current_slot_data.CS_peak_value(app.app_databade.current_CS_data.current_num), '*r');
                app.txtstatic_CS_peak.Text = num2str(app.app_databade.current_slot_data.CS_peak_value(app.app_databade.current_CS_data.current_num, 1), '%4.1f');
                app.txtstatic_CS_width.Text = num2str(app.app_databade.current_slot_data.CS_width(app.app_databade.current_CS_data.current_num, 1)*1000, '%4.1f');
                app.txtstatic_CS_prominance.Text = num2str(app.app_databade.current_slot_data.CS_prominence(app.app_databade.current_CS_data.current_num, 1), '%4.1f');
            end
        end

        % Button pushed function: pushbtn_CS_delete
        function pushbtn_CS_deleteButtonPushed(app, event)
            if (app.app_databade.current_CS_data.current_num ~= 0)
                app.app_databade.current_slot_data.CS_peak_value(app.app_databade.current_CS_data.current_num, :) = [];
                app.app_databade.current_slot_data.CS_time(      app.app_databade.current_CS_data.current_num, :) = [];
                app.app_databade.current_slot_data.CS_width(     app.app_databade.current_CS_data.current_num, :) = [];
                app.app_databade.current_slot_data.CS_prominence(app.app_databade.current_CS_data.current_num, :) = [];
                app.app_databade.current_slot_data.CS_ind(       app.app_databade.current_CS_data.current_num, :) = [];
                app.app_databade.current_slot_data.CS_inds(      app.app_databade.current_CS_data.current_num, :) = [];
                app.app_databade.current_slot_data.CS_waveform(  app.app_databade.current_CS_data.current_num, :) = [];
                app.app_databade.current_CS_data.max_num =       app.app_databade.current_CS_data.max_num - 1;
                if(app.app_databade.current_CS_data.max_num == 0)
                    app.app_databade.current_CS_data.current_num = 0;
                    delete(app.app_databade.plot_handles.axes_signals.h7);
                    app.txtstatic_CS_peak.Text = '0';
                    app.txtstatic_CS_width.Text = '0';
                    app.txtstatic_CS_prominance.Text = '0';
                end
                if(app.app_databade.current_CS_data.current_num > app.app_databade.current_CS_data.max_num)
                    app.app_databade.current_CS_data.current_num = app.app_databade.current_CS_data.max_num;
                end
                delete(app.app_databade.plot_handles.axes_signals.h3)
                app.app_databade.plot_handles.axes_signals.h3 = plot(app.axes_signals, app.app_databade.current_slot_data.CS_time,  app.app_databade.current_slot_data.CS_peak_value, 'or');
                delete(app.app_databade.plot_handles.axes_CS_waveform.h1);
                app.app_databade.plot_handles.axes_CS_waveform.h1 = plot(app.axes_CS_waveform, app.app_databade.current_slot_data.CS_waveform', '-r');
                if (app.app_databade.current_CS_data.current_num ~= 0)
                    app.app_databade.plot_handles.axes_CS_waveform.h1(app.app_databade.current_CS_data.current_num).Color = [0 0 0];
                    app.app_databade.plot_handles.axes_CS_waveform.h1(app.app_databade.current_CS_data.current_num).LineWidth = 2;
                    delete(app.app_databade.plot_handles.axes_signals.h7)
                    app.app_databade.plot_handles.axes_signals.h7 = plot(app.axes_signals, app.app_databade.current_slot_data.CS_time(app.app_databade.current_CS_data.current_num),  app.app_databade.current_slot_data.CS_peak_value(app.app_databade.current_CS_data.current_num), '*r');
                    app.txtstatic_CS_peak.Text = num2str(app.app_databade.current_slot_data.CS_peak_value(app.app_databade.current_CS_data.current_num, 1), '%4.1f');
                    app.txtstatic_CS_width.Text = num2str(app.app_databade.current_slot_data.CS_width(app.app_databade.current_CS_data.current_num, 1)*1000, '%4.1f');
                    app.txtstatic_CS_prominance.Text = num2str(app.app_databade.current_slot_data.CS_prominence(app.app_databade.current_CS_data.current_num, 1), '%4.1f');
                end
            end
        end

        % Button pushed function: pushbtn_save_file
        function pushbtn_save_fileButtonPushed(app, event)
            CH_data.ch_data_cmn    = app.app_databade.EPHYS.CH_1.ch_data_cmn;
            CH_data.ch_data_hipass = app.app_databade.EPHYS.CH_1.ch_data_hipass;
            CH_data.ch_data_lopass = app.app_databade.EPHYS.CH_1.ch_data_lopass;
            CH_data.ch_data_time   = app.app_databade.EPHYS.time;
            CS_data.CS_time       = cell2mat(app.app_databade.slot_data.CS_time);
            CS_data.CS_peak_value = cell2mat(app.app_databade.slot_data.CS_peak_value);
            CS_data.CS_width      = cell2mat(app.app_databade.slot_data.CS_width);
            CS_data.CS_prominence = cell2mat(app.app_databade.slot_data.CS_prominence);
            CS_data.CS_ind        = cell2mat(app.app_databade.slot_data.CS_ind);
            CS_data.CS_inds       = cell2mat(app.app_databade.slot_data.CS_inds);
            CS_data.CS_waveform   = cell2mat(app.app_databade.slot_data.CS_waveform);
            SS_data.SS_time       = cell2mat(app.app_databade.slot_data.SS_time);
            SS_data.SS_peak_value = cell2mat(app.app_databade.slot_data.SS_peak_value);
            SS_data.SS_width      = cell2mat(app.app_databade.slot_data.SS_width);
            SS_data.SS_prominence = cell2mat(app.app_databade.slot_data.SS_prominence);
            SS_data.SS_ind        = cell2mat(app.app_databade.slot_data.SS_ind);
            SS_data.SS_inds       = cell2mat(app.app_databade.slot_data.SS_inds);
            SS_data.SS_waveform   = cell2mat(app.app_databade.slot_data.SS_waveform);
            
            file_name = app.txtstatic_filename.Text;
            file_path = app.txtstatic_filepath.Text;
            [~,file_name,~] = fileparts(file_name);
            file_name = [file_name '_sorted' '.mat'];
            [file_name,file_path] = uiputfile([file_path filesep file_name], 'Select where to save the _sorted mat-file.');
            app.txtstatic_message.Text = ['Saving ' file_name ' ... '];
            figure(app.figure1);
            pause(0.050);
            save([file_path filesep file_name], 'CH_data','CS_data','SS_data', '-v7.3');
            app.txtstatic_message.Text = ['Saving ' file_name ' ... ' '-> Completed.'];
        end

        % Value changed function: switch_flip_signal
        function switch_flip_signalValueChanged(app, event)
            switch_value = app.switch_flip_signal.Value;
            switch switch_value
                case app.switch_flip_signal.Items{1}
                    % Off
                case app.switch_flip_signal.Items{2}
                    % On
                    app.app_databade.EPHYS.CH_1.ch_data_cmn    = -app.app_databade.EPHYS.CH_1.ch_data_cmn;
                    app.app_databade.EPHYS.CH_1.ch_data_lopass = -app.app_databade.EPHYS.CH_1.ch_data_lopass;
                    app.app_databade.EPHYS.CH_1.ch_data_hipass = -app.app_databade.EPHYS.CH_1.ch_data_hipass;
            end
            ESN_evaluate_slot_data(app);
        end

        % Key press function: figure1
        function figure1KeyPress(app, event)
            key = event.Key;
            switch key
                case 'period'
                    pushbtn_CS_nextButtonPushed(app, event);
                case 'comma'
                    pushbtn_CS_previousButtonPushed(app, event)
                case 'd'
                    pushbtn_CS_deleteButtonPushed(app, event)
                case 'n'
                    pushbtn_next_Callback(app, event);
                case 'b'
                    pushbtn_previous_Callback(app, event);
                case 'r'
                    pushbtn_refresh_Callback(app, event);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'ESN_CS_SS_Sorter';
            app.figure1.KeyPressFcn = createCallbackFcn(app, @figure1KeyPress, true);
            app.GridLayout = uigridlayout(app.figure1);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.txtstatic_status_title = uilabel(app.GridLayout2);
            app.txtstatic_status_title.Text = 'Status: ';
            app.txtstatic_message = uilabel(app.GridLayout2);
            app.txtstatic_message.Text = 'Message';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.pushbtn_load_file = uibutton(app.GridLayout4, 'push');
            app.pushbtn_load_file.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_load_file_Callback, true);
            app.pushbtn_load_file.Text = 'Load File';
            app.pushbtn_save_file = uibutton(app.GridLayout4, 'push');
            app.pushbtn_save_file.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_save_fileButtonPushed, true);
            app.pushbtn_save_file.Text = 'Save File';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.txtstatic_filename = uilabel(app.GridLayout5);
            app.txtstatic_filename.Text = 'File Name';
            app.txtstatic_filepath = uilabel(app.GridLayout5);
            app.txtstatic_filepath.Text = 'File Path';
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.txtstatic_total_slots = uilabel(app.GridLayout7);
            app.txtstatic_total_slots.Text = '180';
            app.SlotLabel = uilabel(app.GridLayout7);
            app.SlotLabel.Text = 'Slot #';
            app.spinner_current_slot = uispinner(app.GridLayout7);
            app.spinner_current_slot.Limits = [1 180];
            app.spinner_current_slot.ValueDisplayFormat = '%.0f';
            app.spinner_current_slot.ValueChangedFcn = createCallbackFcn(app, @spinner_current_slotValueChanged, true);
            app.spinner_current_slot.Value = 1;
            app.Label = uilabel(app.GridLayout7);
            app.Label.Text = '/';
            app.GridLayout9 = uigridlayout(app.GridLayout6);
            app.pushbtn_previous = uibutton(app.GridLayout9, 'push');
            app.pushbtn_previous.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_previous_Callback, true);
            app.pushbtn_previous.Text = 'Previous';
            app.pushbtn_next = uibutton(app.GridLayout9, 'push');
            app.pushbtn_next.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_next_Callback, true);
            app.pushbtn_next.Text = 'Next';
            app.GridLayout8 = uigridlayout(app.GridLayout3);
            app.FlipSignalSwitchLabel = uilabel(app.GridLayout8);
            app.FlipSignalSwitchLabel.Text = 'Flip Signal';
            app.switch_flip_signal = uiswitch(app.GridLayout8, 'slider');
            app.switch_flip_signal.ValueChangedFcn = createCallbackFcn(app, @switch_flip_signalValueChanged, true);
            app.GridLayout10 = uigridlayout(app.GridLayout);
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.GridLayout12 = uigridlayout(app.GridLayout11);
            app.CSLabel = uilabel(app.GridLayout12);
            app.CSLabel.Text = 'CS';
            app.SSLabel = uilabel(app.GridLayout12);
            app.SSLabel.Text = 'SS';
            app.slider_CS_threshold = uislider(app.GridLayout12);
            app.slider_CS_threshold.Limits = [0 500];
            app.slider_CS_threshold.MajorTicks = [0 50 100 150 200 250 300 350 400 450 500];
            app.slider_CS_threshold.ValueChangingFcn = createCallbackFcn(app, @slider_CS_thresholdValueChanging, true);
            app.slider_CS_threshold.Value = 100;
            app.slider_SS_threshold = uislider(app.GridLayout12);
            app.slider_SS_threshold.Limits = [-500 0];
            app.slider_SS_threshold.MajorTicks = [-500 -450 -400 -350 -300 -250 -200 -150 -100 -50 0];
            app.slider_SS_threshold.ValueChangingFcn = createCallbackFcn(app, @slider_SS_thresholdValueChanging, true);
            app.slider_SS_threshold.Value = -150;
            app.axes_signals = uiaxes(app.GridLayout11);
            title(app.axes_signals, '')
            xlabel(app.axes_signals, 'Time')
            ylabel(app.axes_signals, 'Voltage')
            app.GridLayout13 = uigridlayout(app.GridLayout10);
            app.GridLayout14 = uigridlayout(app.GridLayout13);
            app.pushbtn_refresh = uibutton(app.GridLayout14, 'push');
            app.pushbtn_refresh.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_refresh_Callback, true);
            app.pushbtn_refresh.Text = 'Reevaluate';
            app.txtstatic_CS_description = uilabel(app.GridLayout14);
            app.txtstatic_CS_description.Text = 'Reveiw CS';
            app.GridLayout15 = uigridlayout(app.GridLayout14);
            app.txtstatic_CS_peak_label = uilabel(app.GridLayout15);
            app.txtstatic_CS_peak_label.Text = 'Peak:';
            app.txtstatic_CS_peak = uilabel(app.GridLayout15);
            app.txtstatic_CS_peak.Text = '0.0';
            app.GridLayout15_3 = uigridlayout(app.GridLayout14);
            app.pushbtn_CS_previous = uibutton(app.GridLayout15_3, 'push');
            app.pushbtn_CS_previous.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_CS_previousButtonPushed, true);
            app.pushbtn_CS_previous.Text = '<<';
            app.pushbtn_CS_next = uibutton(app.GridLayout15_3, 'push');
            app.pushbtn_CS_next.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_CS_nextButtonPushed, true);
            app.pushbtn_CS_next.Text = '>>';
            app.GridLayout15_4 = uigridlayout(app.GridLayout14);
            app.txtstatic_CS_width_label = uilabel(app.GridLayout15_4);
            app.txtstatic_CS_width_label.Text = 'Width:';
            app.txtstatic_CS_width = uilabel(app.GridLayout15_4);
            app.txtstatic_CS_width.Text = '0.0';
            app.GridLayout15_5 = uigridlayout(app.GridLayout14);
            app.txtstatic_CS_prominance_label = uilabel(app.GridLayout15_5);
            app.txtstatic_CS_prominance_label.Text = 'Prom:';
            app.txtstatic_CS_prominance = uilabel(app.GridLayout15_5);
            app.txtstatic_CS_prominance.Text = '0.0';
            app.pushbtn_CS_delete = uibutton(app.GridLayout14, 'push');
            app.pushbtn_CS_delete.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_CS_deleteButtonPushed, true);
            app.pushbtn_CS_delete.Text = 'Delete';
            app.axes_SS_waveform = uiaxes(app.GridLayout13);
            title(app.axes_SS_waveform, 'SS Waveform')
            xlabel(app.axes_SS_waveform, 'Index')
            ylabel(app.axes_SS_waveform, 'Voltage')
            app.axes_CS_waveform = uiaxes(app.GridLayout13);
            title(app.axes_CS_waveform, 'CS Waveform')
            xlabel(app.axes_CS_waveform, 'Index')
            ylabel(app.axes_CS_waveform, 'Voltage')
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ESN_CS_SS_Sorter_App_v1_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @ESN_CS_SS_Sorter_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end