classdef ESN_CS_SS_Sorter_App_v2_11 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PurkinjeSortUIFigure          matlab.ui.Figure
        grid_main                     matlab.ui.container.GridLayout
        panel_status                  matlab.ui.container.Panel
        grid_status                   matlab.ui.container.GridLayout
        txtstatic_status_label        matlab.ui.control.Label
        lamp_status                   matlab.ui.control.Lamp
        txtstatic_status_message      matlab.ui.control.Label
        txtstatic_status_ssFiring     matlab.ui.control.Label
        txtstatic_status_csFiring     matlab.ui.control.Label
        grid_file_slot                matlab.ui.container.GridLayout
        panel_load_save               matlab.ui.container.Panel
        grid_load_save                matlab.ui.container.GridLayout
        pushbtn_load_file             matlab.ui.control.Button
        pushbtn_save_file             matlab.ui.control.Button
        panel_file_name_path          matlab.ui.container.Panel
        grid_file_name_path           matlab.ui.container.GridLayout
        txtstatic_filepath            matlab.ui.control.Label
        txtstatic_filename            matlab.ui.control.Label
        panel_slot                    matlab.ui.container.Panel
        grid_slot                     matlab.ui.container.GridLayout
        grid_slot_back_next           matlab.ui.container.GridLayout
        pushbtn_slot_back             matlab.ui.control.Button
        pushbtn_slot_next             matlab.ui.control.Button
        grid_slot_num2                matlab.ui.container.GridLayout
        txtstatic_slot_label1         matlab.ui.control.Label
        txtedit_slot_numCurrent       matlab.ui.control.NumericEditField
        txtstatic_slot_numTotal       matlab.ui.control.Label
        txtstatic_slot_label2         matlab.ui.control.Label
        grid_signal                   matlab.ui.container.GridLayout
        panel_flip                    matlab.ui.container.Panel
        grid_flip                     matlab.ui.container.GridLayout
        chkbox_neg_ss_hipass          matlab.ui.control.CheckBox
        chkbox_neg_cs_hipass          matlab.ui.control.CheckBox
        chkbox_pos_cs_lopass          matlab.ui.control.CheckBox
        txtstatic_lopass_cutoff       matlab.ui.control.Label
        txtedit_lopass_cutoff_max     matlab.ui.control.NumericEditField
        txtstatic_hipass_cutoff       matlab.ui.control.Label
        txtedit_hipass_cutoff_min     matlab.ui.control.NumericEditField
        txtstatic_lopass_cutoff_dash  matlab.ui.control.Label
        txtstatic_hipass_cutoff_dash  matlab.ui.control.Label
        txtedit_lopass_cutoff_min     matlab.ui.control.NumericEditField
        txtedit_hipass_cutoff_max     matlab.ui.control.NumericEditField
        grid_raw_thresh               matlab.ui.container.GridLayout
        panel_raw                     matlab.ui.container.Panel
        grid_raw                      matlab.ui.container.GridLayout
        axes_rawSignal                matlab.ui.control.UIAxes
        axes_rawSignal_ss_dist        matlab.ui.control.UIAxes
        axes_rawSignal_cs_dist        matlab.ui.control.UIAxes
        panel_thresh                  matlab.ui.container.Panel
        grid_thresh                   matlab.ui.container.GridLayout
        pushbtn_rawSignal_refresh     matlab.ui.control.Button
        txtstatic_cs_threshold        matlab.ui.control.Label
        txtedit_cs_threshold          matlab.ui.control.NumericEditField
        txtstatic_ss_threshold        matlab.ui.control.Label
        txtedit_ss_threshold          matlab.ui.control.NumericEditField
        grid_ss_cs                    matlab.ui.container.GridLayout
        panel_ss                      matlab.ui.container.Panel
        grid_ss                       matlab.ui.container.GridLayout
        grid_ss_axes                  matlab.ui.container.GridLayout
        grid_ss_wave                  matlab.ui.container.GridLayout
        axes_ss_wave                  matlab.ui.control.UIAxes
        pushbtn_ss_wave_select        matlab.ui.control.Button
        grid_ss_pca                   matlab.ui.container.GridLayout
        axes_ss_pca                   matlab.ui.control.UIAxes
        grid_ss_pca_pushbtn           matlab.ui.container.GridLayout
        pushbtn_ss_pca_select         matlab.ui.control.Button
        pushbtn_ss_pca_window         matlab.ui.control.Button
        axes_ss_corr                  matlab.ui.control.UIAxes
        axes_ss_isi                   matlab.ui.control.UIAxes
        grid_ss_pushbtn               matlab.ui.container.GridLayout
        pushbtn_ss_move               matlab.ui.control.Button
        pushbtn_ss_keep               matlab.ui.control.Button
        pushbtn_ss_delete             matlab.ui.control.Button
        panel_cs                      matlab.ui.container.Panel
        grid_cs                       matlab.ui.container.GridLayout
        grid_cs_pushbtn               matlab.ui.container.GridLayout
        pushbtn_cs_move               matlab.ui.control.Button
        pushbtn_cs_keep               matlab.ui.control.Button
        pushbtn_cs_delete             matlab.ui.control.Button
        grid_cs_axes                  matlab.ui.container.GridLayout
        grid_cs_wave                  matlab.ui.container.GridLayout
        axes_cs_wave                  matlab.ui.control.UIAxes
        pushbtn_cs_wave_select        matlab.ui.control.Button
        grid_cs_pca                   matlab.ui.container.GridLayout
        axes_cs_pca                   matlab.ui.control.UIAxes
        grid_cs_pca_pushbtn           matlab.ui.container.GridLayout
        pushbtn_cs_pca_select         matlab.ui.control.Button
        pushbtn_cs_pca_window         matlab.ui.control.Button
        axes_cs_corr                  matlab.ui.control.UIAxes
        axes_cs_isi                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        app_databade % database for the user defined variables
    end
    
    methods (Access = private)
        
        function ESN_init_slot_data(app, total_num_slots)
            app.chkbox_pos_cs_lopass.Value = true;
            app.chkbox_neg_cs_hipass.Value = true;
            app.chkbox_neg_ss_hipass.Value = true;
            app.app_databade.list_variable_names_CH = { ...
                'inds','CH_time','CH_data_cmn','CH_hipass','CH_lopass', ...
                };
            app.app_databade.list_variable_names_CS = { ...
                'CS_time','CS_ind','CS_inds','CS_waveform','CS_peak','CS_prominence','CS_width', ...
                'CS_pca_1','CS_pca_2','CS_ind_selected', ...
                'CS_time_lopass','CS_ind_lopass','CS_peak_lopass','CS_prominence_lopass','CS_width_lopass', ...
                };
            app.app_databade.list_variable_names_SS = { ...
                'SS_time','SS_ind','SS_inds','SS_waveform','SS_peak','SS_prominence','SS_width', ...
                'SS_pca_1','SS_pca_2','SS_ind_selected', ...
                };
            app.app_databade.list_variable_names_Corr = { ...
                'Corr_CS_inds_span','Corr_CS_bin_size_time','Corr_CS_CSxSS_AUTO', ...
                'Corr_SS_inds_span','Corr_SS_bin_size_time','Corr_SS_SSxSS_AUTO', ...
                };
            app.app_databade.list_variable_names_All = [ ...
                app.app_databade.list_variable_names_CH, ...
                app.app_databade.list_variable_names_CS, ...
                app.app_databade.list_variable_names_SS, ...
                app.app_databade.list_variable_names_Corr, ...
                ];
            ESN_clear_current_slot_data(app);
            app.app_databade.slot_data = struct;
            app.app_databade.slot_data.is_analyzed   = false(total_num_slots,1);
            for counter_variable = 1 : 1 : length(app.app_databade.list_variable_names_All)
                variable_name_ = app.app_databade.list_variable_names_All{counter_variable};
                eval(['app.app_databade.slot_data.' variable_name_ ' = ' 'cell(total_num_slots,1)' ';']);
            end
            if(total_num_slots == 0)
                app.app_databade.EPHYS = struct;
                app.app_databade.EPHYS.time                  = [];
                app.app_databade.EPHYS.CH_1.ch_data          = [];
                app.app_databade.EPHYS.CH_CMN.ch_data        = [];
            end
        end
        
        function ESN_clear_current_slot_data(app)
            app.app_databade.current_slot_data = struct;
            app.app_databade.current_slot_data.is_analyzed   = false;
            app.app_databade.current_slot_data.convert_ind_local2global = [];
            for counter_variable = 1 : 1 : length(app.app_databade.list_variable_names_All)
                variable_name_ = app.app_databade.list_variable_names_All{counter_variable};
                eval(['app.app_databade.current_slot_data.' variable_name_ ' = ' '[]' ';']);
            end
        end
        
        function ESN_save_slot_data(app)
            app.app_databade.slot_data.is_analyzed(   app.app_databade.current_slot_num, 1) = app.app_databade.current_slot_data.is_analyzed;
            for counter_variable = 1 : 1 : length(app.app_databade.list_variable_names_All)
                variable_name_ = app.app_databade.list_variable_names_All{counter_variable};
                eval(['app.app_databade.slot_data.' variable_name_ '{app.app_databade.current_slot_num, 1}' ...
                    ' = ' ...
                    'app.app_databade.current_slot_data.' variable_name_ ';']);
            end
            text_ = [num2str(app.app_databade.total_num_slots) ' (' num2str(sum(app.app_databade.slot_data.is_analyzed)) ')'];
            app.txtstatic_slot_numTotal.Text = text_;
        end
        
        function ESN_extract_slot_data(app)
            app.txtstatic_status_message.Text = ['Extracting ' 'slot data # ' num2str(app.app_databade.current_slot_num) ' ... '];
            app.lamp_status.Color = [1 0 0];
            pause(0.050);
            ESN_clear_current_slot_data(app);
            app.app_databade.current_slot_data.is_analyzed = app.app_databade.slot_data.is_analyzed(app.app_databade.current_slot_num, 1);
            for counter_variable = 1 : 1 : length(app.app_databade.list_variable_names_All)
                variable_name_ = app.app_databade.list_variable_names_All{counter_variable};
                eval(['app.app_databade.current_slot_data.' variable_name_ ...
                    ' = ' ...
                    'app.app_databade.slot_data.' variable_name_ '{app.app_databade.current_slot_num, 1}' ';']);
            end
            app.txtstatic_status_message.Text = ['Extracting ' 'slot data # ' num2str(app.app_databade.current_slot_num) ' ... ' '-> Completed.'];
            app.lamp_status.Color = [0 1 0];
            pause(0.050);
            ESN_plot_slot_data(app);
        end
        
        function [CH_data, CS_data, SS_data, Corr_data] = ESN_save_file_data(app)
            CH_data.is_slot_analyzed = app.app_databade.slot_data.is_analyzed;
            for counter_variable = 1 : 1 : length(app.app_databade.list_variable_names_CH)
                variable_name_ = app.app_databade.list_variable_names_CH{counter_variable};
                eval(['CH_data.' variable_name_ ' = ' ...
                    'cell2mat(app.app_databade.slot_data.' variable_name_ '(app.app_databade.slot_data.is_analyzed)' ')' ';']);
            end
            for counter_variable = 1 : 1 : length(app.app_databade.list_variable_names_CS)
                variable_name_ = app.app_databade.list_variable_names_CS{counter_variable};
                eval(['CS_data.' variable_name_ ' = ' ...
                    'cell2mat(app.app_databade.slot_data.' variable_name_ '(app.app_databade.slot_data.is_analyzed)' ')' ';']);
            end
            for counter_variable = 1 : 1 : length(app.app_databade.list_variable_names_SS)
                variable_name_ = app.app_databade.list_variable_names_SS{counter_variable};
                eval(['SS_data.' variable_name_ ' = ' ...
                    'cell2mat(app.app_databade.slot_data.' variable_name_ '(app.app_databade.slot_data.is_analyzed)' ')' ';']);
            end
            for counter_variable = 1 : 1 : length(app.app_databade.list_variable_names_Corr)
                variable_name_ = app.app_databade.list_variable_names_Corr{counter_variable};
                eval(['Corr_data.' variable_name_ ' = ' ...
                    'cell2mat(app.app_databade.slot_data.' variable_name_ '(app.app_databade.slot_data.is_analyzed)' ')' ';']);
            end
            CH_data.inds = [];
            CS_data.CS_ind_selected = [];
            SS_data.SS_ind_selected = [];
        end
        
        function ESN_keep_cs(app, CS_ind_keep)
            for counter_variable = 1 : 1 : length(app.app_databade.list_variable_names_CS)
                variable_name_ = app.app_databade.list_variable_names_CS{counter_variable};
                eval(['app.app_databade.current_slot_data.' variable_name_ ...
                    ' = ' ...
                    'app.app_databade.current_slot_data.' variable_name_ '(CS_ind_keep, :)' ';']);
            end
            app.app_databade.current_slot_data.CS_ind_selected = false(size(app.app_databade.current_slot_data.CS_time));
        end

        function ESN_keep_ss(app, SS_ind_keep)
            for counter_variable = 1 : 1 : length(app.app_databade.list_variable_names_SS)
                variable_name_ = app.app_databade.list_variable_names_SS{counter_variable};
                eval(['app.app_databade.current_slot_data.' variable_name_ ...
                    ' = ' ...
                    'app.app_databade.current_slot_data.' variable_name_ '(SS_ind_keep, :)' ';']);
            end
            app.app_databade.current_slot_data.SS_ind_selected = false(size(app.app_databade.current_slot_data.SS_time));
        end
        
        function ESN_move_from_SS_to_CS(app, SS_ind_move)
            for counter_variable = 1 : 1 : length(app.app_databade.list_variable_names_CS)
                variable_name_CS_ = app.app_databade.list_variable_names_CS{counter_variable};
                variable_name_SS_ = variable_name_CS_;
                variable_name_SS_(1) = 'S';
                if contains(variable_name_CS_, 'lopass')
                    variable_name_SS_ = variable_name_SS_(1:end-7);
                end
                if ( contains(variable_name_CS_, 'time') || contains(variable_name_CS_, 'ind') || contains(variable_name_CS_, 'inds') || contains(variable_name_CS_, 'waveform') ) ...
                        && (~contains(variable_name_CS_, 'lopass'))
                    eval(['app.app_databade.current_slot_data.' variable_name_CS_ ...
                        ' = ' ...
                        '[' ...
                        'app.app_databade.current_slot_data.' variable_name_CS_ ';' ...
                        'app.app_databade.current_slot_data.' variable_name_SS_ '(SS_ind_move,:)' ...
                        ']' ...
                        ';']);
                else
                    eval(['app.app_databade.current_slot_data.' variable_name_CS_ ...
                        ' = ' ...
                        '[' ...
                        'app.app_databade.current_slot_data.' variable_name_CS_ ';' ...
                        'nan( size( app.app_databade.current_slot_data.' variable_name_SS_ '(SS_ind_move,:)' ') )' ...
                        ']' ...
                        ';']);
                end
            end
            ESN_sort_cs(app);
            ESN_keep_ss(app, ~SS_ind_move);
            app.app_databade.current_slot_data.CS_ind_selected = false(size(app.app_databade.current_slot_data.CS_time));
            app.app_databade.current_slot_data.SS_ind_selected = false(size(app.app_databade.current_slot_data.SS_time));
        end
        
        function ESN_move_from_CS_to_SS(app, CS_ind_move)
            for counter_variable = 1 : 1 : length(app.app_databade.list_variable_names_SS)
                variable_name_SS_ = app.app_databade.list_variable_names_SS{counter_variable};
                variable_name_CS_ = variable_name_SS_;
                variable_name_CS_(1) = 'C';
                if ( contains(variable_name_SS_, 'time') || contains(variable_name_SS_, 'ind') || contains(variable_name_SS_, 'inds') || contains(variable_name_SS_, 'waveform') )
                    eval(['app.app_databade.current_slot_data.' variable_name_SS_ ...
                        ' = ' ...
                        '[' ...
                        'app.app_databade.current_slot_data.' variable_name_SS_ ';' ...
                        'app.app_databade.current_slot_data.' variable_name_CS_ '(CS_ind_move,:)' ...
                        ']' ...
                        ';']);
                else
                    eval(['app.app_databade.current_slot_data.' variable_name_SS_ ...
                        ' = ' ...
                        '[' ...
                        'app.app_databade.current_slot_data.' variable_name_SS_ ';' ...
                        'nan( size( app.app_databade.current_slot_data.' variable_name_CS_ '(CS_ind_move,:)' ') )' ...
                        ']' ...
                        ';']);
                end
            end
            ESN_sort_ss(app);
            ESN_keep_cs(app, ~CS_ind_move);
            app.app_databade.current_slot_data.CS_ind_selected = false(size(app.app_databade.current_slot_data.CS_time));
            app.app_databade.current_slot_data.SS_ind_selected = false(size(app.app_databade.current_slot_data.SS_time));
        end
        
        function ESN_sort_cs(app)
            num_ind_per_ms = app.app_databade.sample_freq / 1000;
            CS_ind   = app.app_databade.current_slot_data.CS_ind;
            CS_width = app.app_databade.current_slot_data.CS_width;
            [CS_ind_sorted, ind_sort] = sort(CS_ind, 1, 'ascend');
            CS_width = CS_width(ind_sort);
            inds_too_close = diff(CS_ind_sorted(:)) < (num_ind_per_ms*10); % CS times which are less than 5ms apart
            inds_too_close_1st = [inds_too_close; false];
            inds_too_close_2nd = [false; inds_too_close];
            if sum(inds_too_close)>0
                inds_invalid = (inds_too_close_1st & isnan(CS_width)) | (inds_too_close_2nd & isnan(CS_width));
                inds_consecutive = [inds_invalid; false] & [false; inds_invalid];
                inds_invalid(inds_consecutive(2:end)) = false;
                ind_sort(inds_invalid) = [];
            end
            for counter_variable = 1 : 1 : length(app.app_databade.list_variable_names_CS)
                variable_name_ = app.app_databade.list_variable_names_CS{counter_variable};
                eval(['app.app_databade.current_slot_data.' variable_name_ ...
                    ' = ' ...
                    'app.app_databade.current_slot_data.' variable_name_ '(ind_sort, :)' ';']);
            end
        end
        
        function ESN_sort_ss(app)
            num_ind_per_ms = app.app_databade.sample_freq / 1000;
            SS_ind   = app.app_databade.current_slot_data.SS_ind;
            SS_width = app.app_databade.current_slot_data.SS_width;
            [SS_ind_sorted, ind_sort] = sort(SS_ind, 1, 'ascend');
            SS_width = SS_width(ind_sort);
            inds_too_close = diff(SS_ind_sorted) < (num_ind_per_ms/2); % SS times which are less than 0.5ms apart
            inds_too_close_1st = [inds_too_close; false];
            inds_too_close_2nd = [false; inds_too_close];
            if sum(inds_too_close)>0
                inds_invalid = (inds_too_close_1st & isnan(SS_width)) | (inds_too_close_2nd & isnan(SS_width));
                inds_consecutive = [inds_invalid; false] & [false; inds_invalid];
                inds_invalid(inds_consecutive(2:end)) = false;
                ind_sort(inds_invalid) = [];
            end
            for counter_variable = 1 : 1 : length(app.app_databade.list_variable_names_SS)
                variable_name_ = app.app_databade.list_variable_names_SS{counter_variable};
                eval(['app.app_databade.current_slot_data.' variable_name_ ...
                    ' = ' ...
                    'app.app_databade.current_slot_data.' variable_name_ '(ind_sort, :)' ';']);
            end
        end
        
        function ESN_filter_EPHYS(app)
            time_slot_str = app.app_databade.time_edges(app.app_databade.current_slot_num);
            time_slot_end = app.app_databade.time_edges(app.app_databade.current_slot_num+1);
            ind_str       = find(app.app_databade.EPHYS.time >  time_slot_str, 1, 'first');
            ind_end       = find(app.app_databade.EPHYS.time <= time_slot_end, 1, 'last');
            inds_slot     = (ind_str : 1 : ind_end)';
            app.app_databade.current_slot_data.inds   = inds_slot(:);
            app.app_databade.current_slot_data.convert_ind_local2global = ind_str - 1;
            
            Wn_hipass_min = app.txtedit_hipass_cutoff_min.Value;
            Wn_hipass_max = app.txtedit_hipass_cutoff_max.Value;
            Wn_lopass_min = app.txtedit_lopass_cutoff_min.Value;
            Wn_lopass_max = app.txtedit_lopass_cutoff_max.Value;
            use_default_flag = false;
            use_default_flag = use_default_flag || (Wn_hipass_min >= Wn_hipass_max);
            use_default_flag = use_default_flag || (Wn_lopass_min >= Wn_lopass_max);
            if use_default_flag
                Wn_hipass = [300 3000]/(app.app_databade.sample_freq/2);
                Wn_lopass = [30  300 ]/(app.app_databade.sample_freq/2);
            else
                Wn_hipass = [Wn_hipass_min Wn_hipass_max]/(app.app_databade.sample_freq/2);
                Wn_lopass = [Wn_lopass_min Wn_lopass_max]/(app.app_databade.sample_freq/2);
            end
            [b_hipass_max,a_hipass_max] = butter(4, Wn_hipass(2), 'low');
            [b_hipass_min,a_hipass_min] = butter(4, Wn_hipass(1), 'high');
            [b_lopass_max,a_lopass_max] = butter(4, Wn_lopass(2), 'low');
            [b_lopass_min,a_lopass_min] = butter(4, Wn_lopass(1), 'high');
            
            slot_data_time = app.app_databade.EPHYS.time(inds_slot);
            slot_data_cmn  = app.app_databade.EPHYS.CH_CMN.ch_data(inds_slot);
            slot_data_ch1  = app.app_databade.EPHYS.CH_1.ch_data(inds_slot);
            % common average the ch_data
            slot_data_ch1_cmn = slot_data_ch1(:) - slot_data_cmn(:);
            % remove point frequencies with a notch filter
            slot_data_ch1_cmn_notch = slot_data_ch1_cmn;
            notch_freq = [60, 100, 1000, 2000, 3000]; % Hz
            for counter_freq = 1 : length(notch_freq)
                fo_notch_ = notch_freq(counter_freq); %Hz
                wo_notch_ = fo_notch_/(app.app_databade.sample_freq/2);  
                bw_notch_ = wo_notch_/35;
                [b_notch_,a_notch_] = iirnotch(wo_notch_,bw_notch_);
                slot_data_ch1_cmn_notch = filtfilt( b_notch_, a_notch_, slot_data_ch1_cmn_notch);
            end
            % hi band pass filter the data
            slot_data_hipass = filtfilt( b_hipass_max, a_hipass_max, slot_data_ch1_cmn_notch);
            slot_data_hipass = filtfilt( b_hipass_min, a_hipass_min, slot_data_hipass);
            % lo band pass filter the data
            slot_data_lopass = filtfilt( b_lopass_max, a_lopass_max, slot_data_ch1_cmn_notch);
            slot_data_lopass = filtfilt( b_lopass_min, a_lopass_min, slot_data_lopass);
            
            app.app_databade.current_slot_data.CH_time     = slot_data_time(:);
            app.app_databade.current_slot_data.CH_data_cmn = slot_data_ch1_cmn(:);
            app.app_databade.current_slot_data.CH_hipass   = slot_data_hipass(:);
            app.app_databade.current_slot_data.CH_lopass   = slot_data_lopass(:);
        end
        
        function ESN_evaluate_slot_data(app)
            app.txtstatic_status_message.Text = ['Evaluating ' 'slot data # ' num2str(app.app_databade.current_slot_num) ' ... '];
            app.lamp_status.Color = [1 0 0];
            pause(0.050);
            ESN_clear_current_slot_data(app);
            ESN_filter_EPHYS(app);
            
            threshold_CS      =  app.txtedit_cs_threshold.Value;
            threshold_SS      =  app.txtedit_ss_threshold.Value;
            threshold_CS_time = 0.005;
            threshold_SS_time = 0.001;
            if app.chkbox_pos_cs_lopass.Value
                lopass_slot =  app.app_databade.current_slot_data.CH_lopass;
            else
                lopass_slot = -app.app_databade.current_slot_data.CH_lopass;
            end
            if app.chkbox_neg_ss_hipass.Value
                hipass_slot = -app.app_databade.current_slot_data.CH_hipass;
            else
                hipass_slot =  app.app_databade.current_slot_data.CH_hipass;
            end
            % find peaks in the lopass_slot data as CS events
            [CS_peak_lopass, CS_ind_lopass_local, CS_width_lopass, CS_prominence_lopass] = findpeaks(lopass_slot , 'MinPeakHeight',threshold_CS,'MinPeakProminence',threshold_CS,'MinPeakDistance',threshold_CS_time);
            if ~app.chkbox_pos_cs_lopass.Value
                CS_peak_lopass =  -CS_peak_lopass;
            end
            % represent the CS_ind as absolute values instead of local
            % values of the current time slot
            CS_ind_lopass  = CS_ind_lopass_local + app.app_databade.current_slot_data.convert_ind_local2global;
            CS_time_lopass = app.app_databade.current_slot_data.CH_time(CS_ind_lopass_local);
            app.app_databade.current_slot_data.CS_peak_lopass       = CS_peak_lopass;
            app.app_databade.current_slot_data.CS_prominence_lopass = CS_prominence_lopass;
            app.app_databade.current_slot_data.CS_width_lopass      = CS_width_lopass;
            app.app_databade.current_slot_data.CS_time_lopass       = CS_time_lopass;
            app.app_databade.current_slot_data.CS_ind_lopass        = CS_ind_lopass;
            app.app_databade.current_slot_data.CS_ind_lopass_local  = CS_ind_lopass_local;
            % align CS waveform to the zero-crossing point and save CS data
            ESN_align_CS(app);
            % find peaks in the hipass_slot data as SS events
            [SS_peak, SS_ind_local, SS_width, SS_prominence] = findpeaks(hipass_slot, 'MinPeakHeight',threshold_SS,'MinPeakProminence',threshold_SS,'MinPeakDistance',threshold_SS_time);
            if app.chkbox_neg_ss_hipass.Value
                SS_peak = -SS_peak;
            end
            % represent the SS_ind as absolute values instead of local
            % values of the current time slot
            SS_ind  = SS_ind_local + app.app_databade.current_slot_data.convert_ind_local2global;
            % find overlapping CS_ind in SS_ind and remove them from SS_ind
            CS_ind   = app.app_databade.current_slot_data.CS_ind;
            SS_num_total = length(SS_ind);
            SS_valid = true(SS_num_total, 1);
            half_window_length_   = 8; % full window length is 16 data points or ~0.5ms
            if (~isempty(CS_ind)) && (~isempty(SS_ind))
                CS_ind_first_element_ = min([SS_ind(1),   CS_ind(1)])   - (2*half_window_length_);
                CS_ind_last_element_  = max([SS_ind(end), CS_ind(end)]) + (2*half_window_length_);
                CS_ind    = [CS_ind_first_element_; CS_ind(:); CS_ind_last_element_];
                counter_CS = 1;
                for counter_SS = 1 : SS_num_total
                    SS_ind_current_  = SS_ind(counter_SS);
                    CS_ind_previous_ = CS_ind(counter_CS);
                    CS_ind_next_     = CS_ind(counter_CS+1);
                    if (abs(SS_ind_current_-CS_ind_previous_)<half_window_length_) || (abs(CS_ind_next_-SS_ind_current_)<half_window_length_)
                        SS_valid(counter_SS) = false;
                    end
                    if SS_ind_current_ >= CS_ind_next_
                        counter_CS = counter_CS + 1;
                    end
                end
            end
            % remove shared ind between SS_ind and CS_ind from SS_ind
            SS_ind        = SS_ind(       SS_valid, :);
            SS_ind_local  = SS_ind_local( SS_valid, :);
            SS_peak       = SS_peak(      SS_valid, :);
            SS_width      = SS_width(     SS_valid, :);
            SS_prominence = SS_prominence(SS_valid, :);
            % extract SS_time
            SS_time = app.app_databade.current_slot_data.CH_time(SS_ind_local);
            % find 4ms around each CS event, % 2ms before and after the peak, 2ms with 30KHz will be 60 data points
            num_ind_per_ms = app.app_databade.sample_freq / 1000;
            inds_span_SS = (-(num_ind_per_ms*2)+1) : 1 : (num_ind_per_ms*4);
            length_time_ = length(hipass_slot) + app.app_databade.current_slot_data.convert_ind_local2global;
            SS_inds = repmat( SS_ind(:), 1, length(inds_span_SS)) + repmat(inds_span_SS(:)', length(SS_ind), 1);
            SS_inds( SS_inds < 1 ) = 1;
            SS_inds( SS_inds > length_time_ ) = length_time_;
            SS_inds_local = SS_inds - app.app_databade.current_slot_data.convert_ind_local2global;
            SS_inds_local( SS_inds_local < 1 ) = 1;
            SS_inds_local( SS_inds_local > length(hipass_slot) ) = length(hipass_slot);
            SS_waveform = app.app_databade.current_slot_data.CH_hipass(SS_inds_local);
            % handling and error where the waveform become a column vector
            % instead of row vector when the number of waveforms is 1
            if (length(SS_ind)==1) && (size(SS_waveform,2)==1)
                SS_waveform = SS_waveform(:)';
            end
            app.app_databade.current_slot_data.SS_peak       = SS_peak;
            app.app_databade.current_slot_data.SS_time       = SS_time;
            app.app_databade.current_slot_data.SS_width      = SS_width;
            app.app_databade.current_slot_data.SS_prominence = SS_prominence;
            app.app_databade.current_slot_data.SS_ind        = SS_ind;
            app.app_databade.current_slot_data.SS_ind_local  = SS_ind_local;
            app.app_databade.current_slot_data.SS_inds       = SS_inds;
            app.app_databade.current_slot_data.SS_waveform   = SS_waveform;
            
            app.app_databade.current_slot_data.CS_ind_selected = false(size(app.app_databade.current_slot_data.CS_time));
            app.app_databade.current_slot_data.SS_ind_selected = false(size(app.app_databade.current_slot_data.SS_time));
            
            ESN_evaluate_corr_and_pca(app);
            ESN_sort_ss(app);
            ESN_sort_cs(app);
            app.app_databade.current_slot_data.is_analyzed = true;
            
            app.txtstatic_status_message.Text = ['Evaluating ' 'slot data # ' num2str(app.app_databade.current_slot_num) ' ... ' '-> Completed.'];
            app.lamp_status.Color = [0 1 0];
            pause(0.050);
            ESN_plot_slot_data(app);
        end
        
        function ESN_align_CS(app)
            data.ch_data_time   = app.app_databade.current_slot_data.CH_time;
            data.ch_data_hipass = app.app_databade.current_slot_data.CH_hipass;
            data.CS_time        = app.app_databade.current_slot_data.CS_time_lopass;
            data_length          = length(data.ch_data_time);
            CS_num_total         = length(data.CS_time);
            if CS_num_total > 1
                num_ind_per_ms = app.app_databade.sample_freq / 1000;
                CS_valid             = false(CS_num_total, 1);
                CS_time_hipass       = nan(CS_num_total, 1);
                CS_ind_local         = nan(CS_num_total, 1);
                CS_inds_local        = nan(CS_num_total, (num_ind_per_ms*6));
                CS_waveform_hipass   = nan(CS_num_total, (num_ind_per_ms*6));
                CS_peak_hipass       = nan(CS_num_total, 1);
                CS_prominence_hipass = nan(CS_num_total, 1);
                CS_width_hipass      = nan(CS_num_total, 1);
                
                for counter_CS = 1 : 1 : CS_num_total
                    if counter_CS == 1
                        % search the entire time_vector to find the
                        % beginning of the 1st CS
                        CS_interval_begin_ = find(data.ch_data_time >= data.CS_time(counter_CS, 1), 1, 'first');
                        % shift back the CS_interval_begin_ by 1s (30K)
                        CS_interval_begin_ = max([(CS_interval_begin_ - app.app_databade.sample_freq) 1]);
                    else
                        counter_CS_not_nan_ = find(~isnan(CS_ind_local), 1, 'last');
                        CS_interval_begin_ = CS_ind_local(counter_CS_not_nan_);
                    end
                    CS_interval_end_   = min([(CS_interval_begin_ + (app.app_databade.sample_freq*10)) data_length]);
                    CS_interval_inds   = CS_interval_begin_ : 1 : CS_interval_end_;
                    CS_interval_time   = data.ch_data_time(CS_interval_inds);
                    CS_interval_hipass = data.ch_data_hipass(CS_interval_inds);
                    
                    CS_ind_ = find(CS_interval_time >= data.CS_time(counter_CS, 1), 1, 'first');
                    
                    if isempty(CS_ind_)
                        % if there is no CS in the 10s window from the
                        % previous CS, then search the entire time_vector
                        CS_interval_begin_ = find(data.ch_data_time >= data.CS_time(counter_CS, 1), 1, 'first');
                        CS_interval_begin_ = max([(CS_interval_begin_ - app.app_databade.sample_freq) 1]); % shift back the CS_interval_begin_ by 1s (30K)
                        CS_interval_end_   = min([(CS_interval_begin_ + (app.app_databade.sample_freq*10)) data_length]);
                        CS_interval_inds   = CS_interval_begin_ : 1 : CS_interval_end_;
                        CS_interval_time   = data.ch_data_time(  CS_interval_inds);
                        CS_interval_hipass = data.ch_data_hipass(CS_interval_inds);
                        
                        CS_ind_ = find(CS_interval_time >= data.CS_time(counter_CS, 1), 1, 'first');
                    end
                    num_ind_per_ms = app.app_databade.sample_freq / 1000;
                    ind_to_look_back_ = (num_ind_per_ms*5);
                    ind_to_look_front_ = 5;
                    if(CS_ind_<ind_to_look_back_)
                        CS_ind_ = ind_to_look_back_;
                    elseif(CS_ind_>(length(CS_interval_inds)-ind_to_look_front_))
                        CS_ind_ = length(CS_interval_inds)-ind_to_look_front_;
                    end
                    CS_inds_ = (CS_ind_ - ind_to_look_back_+1) : 1 : (CS_ind_ + ind_to_look_front_);
                    CS_waveform_hipass_ = CS_interval_hipass(CS_inds_, 1)';
                    CS_waveform_time_   = CS_interval_time(CS_inds_, 1)';
                    if app.chkbox_pos_cs_lopass.Value
                        %threshold_CS_time   = 0.001;
                        threshold_CS = abs(mean(CS_waveform_hipass_(CS_waveform_hipass_>0))) + 1.0*std(CS_waveform_hipass_(CS_waveform_hipass_>0));
                        [CS_peak_hipass_, CS_time_hipass_, CS_width_hipass_, CS_prominence_hipass_] = findpeaks( CS_waveform_hipass_ , CS_waveform_time_, 'MinPeakHeight',threshold_CS,'MinPeakProminence',threshold_CS);%,'MinPeakDistance',threshold_CS_time);
                    else
                        %threshold_CS_time   = 0.001;
                        threshold_CS = abs(mean(CS_waveform_hipass_(CS_waveform_hipass_<0))) + 1.0*std(CS_waveform_hipass_(CS_waveform_hipass_<0));
                        [CS_peak_hipass_, CS_time_hipass_, CS_width_hipass_, CS_prominence_hipass_] = findpeaks(-CS_waveform_hipass_ , CS_waveform_time_, 'MinPeakHeight',threshold_CS,'MinPeakProminence',threshold_CS);%,'MinPeakDistance',threshold_CS_time);
                    end
                    if ~app.chkbox_pos_cs_lopass.Value
                        CS_peak_hipass_ = -CS_peak_hipass_;
                    end
                    if isempty(CS_time_hipass_)
                        continue;
                    end
                    CS_time_hipass_ = CS_time_hipass_(end);
                    CS_ind_local_ = find(CS_waveform_time_ >= CS_time_hipass_, 1, 'first');
                    if isempty(CS_ind_local_)
                        continue;
                    end
                    if app.chkbox_pos_cs_lopass.Value
                        ind_upcross = find((CS_waveform_hipass_(1:CS_ind_local_-1) <= 0) & (CS_waveform_hipass_(2:CS_ind_local_) > 0), 1, 'last');
                    else
                        ind_upcross = find((CS_waveform_hipass_(1:CS_ind_local_-1) >= 0) & (CS_waveform_hipass_(2:CS_ind_local_) < 0), 1, 'last');
                    end
                    if isempty(ind_upcross)
                        continue;
                    end
                    if app.chkbox_neg_cs_hipass.Value
                        ind_localMin_b4_upcross = find((diff(CS_waveform_hipass_(1:ind_upcross-1)) <= 0) & (diff(CS_waveform_hipass_(2:ind_upcross)) > 0), 1, 'last');
                    else
                        ind_localMin_b4_upcross = find((diff(CS_waveform_hipass_(1:ind_upcross-1)) >= 0) & (diff(CS_waveform_hipass_(2:ind_upcross)) < 0), 1, 'last');
                    end
                    if isempty(ind_localMin_b4_upcross)
                        continue;
                    end
                    num_ind_per_ms = app.app_databade.sample_freq / 1000;
                    CS_time_hipass_ = CS_waveform_time_(ind_localMin_b4_upcross);
                    CS_ind_  = CS_inds_(ind_localMin_b4_upcross);
                    CS_inds_ = (CS_ind_ - (num_ind_per_ms*2)+1) : 1 : (CS_ind_ + (num_ind_per_ms*4));
                    CS_inds_(CS_inds_<1) = 1;
                    CS_inds_(CS_inds_>length(CS_interval_inds)) = length(CS_interval_inds);
                    CS_waveform_hipass_ = CS_interval_hipass(CS_inds_, 1)';
                    CS_ind_  = CS_interval_inds(CS_ind_);
                    CS_inds_ = CS_interval_inds(CS_inds_);
                    
                    CS_valid(            counter_CS, :) = true;
                    CS_ind_local(        counter_CS, :) = CS_ind_;
                    CS_peak_hipass(      counter_CS, :) = CS_peak_hipass_(end);
                    CS_time_hipass(      counter_CS, :) = CS_time_hipass_;
                    CS_width_hipass(     counter_CS, :) = CS_width_hipass_(end);
                    CS_prominence_hipass(counter_CS, :) = CS_prominence_hipass_(end);
                    CS_waveform_hipass(  counter_CS, :) = CS_waveform_hipass_(:)';
                    CS_inds_local(       counter_CS, :) = CS_inds_(:)';
                end
                
                CS_peak_hipass       = CS_peak_hipass(      CS_valid,:);
                CS_prominence_hipass = CS_prominence_hipass(CS_valid,:);
                CS_width_hipass      = CS_width_hipass(     CS_valid,:);
                CS_time_hipass       = CS_time_hipass(      CS_valid,:);
                CS_ind_local         = CS_ind_local(        CS_valid,:);
                CS_inds_local        = CS_inds_local(       CS_valid,:);
                CS_waveform_hipass   = CS_waveform_hipass(  CS_valid,:);
                
                CS_peak_lopass       = app.app_databade.current_slot_data.CS_peak_lopass(      CS_valid,:);
                CS_prominence_lopass = app.app_databade.current_slot_data.CS_prominence_lopass(CS_valid,:);
                CS_width_lopass      = app.app_databade.current_slot_data.CS_width_lopass(     CS_valid,:);
                CS_time_lopass       = app.app_databade.current_slot_data.CS_time_lopass(      CS_valid,:);
                CS_ind_lopass        = app.app_databade.current_slot_data.CS_ind_lopass(       CS_valid,:);
                CS_ind_lopass_local  = app.app_databade.current_slot_data.CS_ind_lopass_local( CS_valid,:);
            else
                num_ind_per_ms = app.app_databade.sample_freq / 1000;
                CS_num_total         = 0;
                CS_time_hipass       = nan(CS_num_total, 1);
                CS_ind_local         = nan(CS_num_total, 1);
                CS_inds_local        = nan(CS_num_total, (num_ind_per_ms*6));
                CS_waveform_hipass   = nan(CS_num_total, (num_ind_per_ms*6));
                CS_peak_hipass       = nan(CS_num_total, 1);
                CS_prominence_hipass = nan(CS_num_total, 1);
                CS_width_hipass      = nan(CS_num_total, 1);
                CS_peak_lopass       = nan(CS_num_total, 1);
                CS_prominence_lopass = nan(CS_num_total, 1);
                CS_width_lopass      = nan(CS_num_total, 1);
                CS_time_lopass       = nan(CS_num_total, 1);
                CS_ind_lopass        = nan(CS_num_total, 1);
                CS_ind_lopass_local  = nan(CS_num_total, 1);
            end
            % handling and error where the waveform become a column vector
            % instead of row vector when the number of waveforms is 1
            if (length(CS_ind_local)==1) && (size(CS_waveform_hipass,2)==1)
                CS_waveform_hipass = CS_waveform_hipass(:)';
            end
            app.app_databade.current_slot_data.CS_peak       = CS_peak_hipass;
            app.app_databade.current_slot_data.CS_prominence = CS_prominence_hipass;
            app.app_databade.current_slot_data.CS_width      = CS_width_hipass;
            app.app_databade.current_slot_data.CS_time       = CS_time_hipass;
            app.app_databade.current_slot_data.CS_ind_local  = CS_ind_local;
            app.app_databade.current_slot_data.CS_ind        = CS_ind_local  + app.app_databade.current_slot_data.convert_ind_local2global;
            app.app_databade.current_slot_data.CS_inds       = CS_inds_local + app.app_databade.current_slot_data.convert_ind_local2global;
            app.app_databade.current_slot_data.CS_waveform   = CS_waveform_hipass;
            app.app_databade.current_slot_data.CS_peak_lopass       = CS_peak_lopass;
            app.app_databade.current_slot_data.CS_prominence_lopass = CS_prominence_lopass;
            app.app_databade.current_slot_data.CS_width_lopass      = CS_width_lopass;
            app.app_databade.current_slot_data.CS_time_lopass       = CS_time_lopass;
            app.app_databade.current_slot_data.CS_ind_lopass        = CS_ind_lopass;
            app.app_databade.current_slot_data.CS_ind_lopass_local  = CS_ind_lopass_local;
        end
        
        function Corr_data = ESN_correlogram(app, SS_time, CS_time)
            bin_size_time = 1e-3; % seconds
            span_window_size = (1 / bin_size_time) * (100 / 1000);
            span_window_size_half = round(span_window_size / 2);
            inds_span = ((-span_window_size_half+1) : 1 : (span_window_size_half))';
            if (~isempty(CS_time)) && (~isempty(SS_time))
                ch_time_min = min([SS_time(1) CS_time(1)]);
                ch_time_min = max([(ch_time_min-2.0) 0]);
                ch_time_max = max([SS_time(end) CS_time(end)]) + 2.0;
                
                CH__.SS_data.SS_time =  SS_time - ch_time_min;
                CH__.CS_data.CS_time =  CS_time - ch_time_min;
                CH__.SS_data.SS_time = ESN_round(app, CH__.SS_data.SS_time, bin_size_time);
                CH__.CS_data.CS_time = ESN_round(app, CH__.CS_data.CS_time, bin_size_time);
                CH__.SS_data.SS_ind  = round(CH__.SS_data.SS_time .* (1/bin_size_time));
                CH__.SS_data.SS_ind( CH__.SS_data.SS_ind < 1 ) = 1;
                CH__.CS_data.CS_ind  = round(CH__.CS_data.CS_time .* (1/bin_size_time));
                CH__.CS_data.CS_ind( CH__.CS_data.CS_ind < 1 ) = 1;
                CH__.CH_data.ch_time_reconstruct = ch_time_min : bin_size_time : ch_time_max;
            elseif (~isempty(CS_time))
                ch_time_min = min(  CS_time(1) );
                ch_time_min = max([(ch_time_min-2.0) 0]);
                ch_time_max = max(  CS_time(end) ) + 2.0;
                
                CH__.CS_data.CS_time =  CS_time - ch_time_min;
                CH__.CS_data.CS_time = ESN_round(app, CH__.CS_data.CS_time, bin_size_time);
                CH__.CS_data.CS_ind  = round(CH__.CS_data.CS_time .* (1/bin_size_time));
                CH__.CS_data.CS_ind( CH__.CS_data.CS_ind < 1 ) = 1;
                CH__.CH_data.ch_time_reconstruct = ch_time_min : bin_size_time : ch_time_max;
            elseif (~isempty(SS_time))
                ch_time_min = min( SS_time(1)  );
                ch_time_min = max([(ch_time_min-2.0) 0]);
                ch_time_max = max( SS_time(end)  ) + 2.0;
                
                CH__.SS_data.SS_time =  SS_time - ch_time_min;
                CH__.SS_data.SS_time = ESN_round(app, CH__.SS_data.SS_time, bin_size_time);
                CH__.SS_data.SS_ind  = round(CH__.SS_data.SS_time .* (1/bin_size_time));
                CH__.SS_data.SS_ind( CH__.SS_data.SS_ind < 1 ) = 1;
                CH__.CH_data.ch_time_reconstruct = ch_time_min : bin_size_time : ch_time_max;
            end    
            
            % SSxSS_AUTO
            if (~isempty(SS_time))
                CH__.SS_data.SS_inds_reconstruct = repmat( CH__.SS_data.SS_ind(:), 1, length(inds_span)) + repmat(inds_span(:)', length(CH__.SS_data.SS_ind), 1);
                CH__.SS_data.SS_inds_reconstruct( CH__.SS_data.SS_inds_reconstruct < 1 ) = 1;
                CH__.SS_data.SS_inds_reconstruct( CH__.SS_data.SS_inds_reconstruct > length( CH__.CH_data.ch_time_reconstruct ) ) = length( CH__.CH_data.ch_time_reconstruct );
                
                CH__.SS_data.SS_event_trace = false( size(CH__.CH_data.ch_time_reconstruct) );
                CH__.SS_data.SS_event_trace( CH__.SS_data.SS_ind ) = true ;
                CH__.SS_data.SS_event_trace( 1   ) = false;
                CH__.SS_data.SS_event_trace( end ) = false;
                
                CH__.SS_data.SS_event_reconstruct = CH__.SS_data.SS_event_trace( CH__.SS_data.SS_inds_reconstruct );
                % SSxSS correlogram
                SSxSS_AUTO       = CH__.SS_data.SS_event_reconstruct;
                ss_inds_span     = repmat(inds_span(:)',     size(SS_time(:),1), 1);
                ss_bin_size_time = repmat(bin_size_time(:)', size(SS_time(:),1), 1);
            else
                SSxSS_AUTO       = false(0, length(inds_span(:)'));
                ss_inds_span     = nan(0, length(inds_span(:)'));
                ss_bin_size_time = nan(0, 1);
            end
            
            % CSxSS_WITHIN
            if (~isempty(CS_time)) && (~isempty(SS_time))
                CH__.CS_data.CS_inds_reconstruct = repmat( CH__.CS_data.CS_ind(:), 1, length(inds_span)) + repmat(inds_span(:)', length(CH__.CS_data.CS_ind), 1);
                CH__.CS_data.CS_inds_reconstruct( CH__.CS_data.CS_inds_reconstruct < 1 ) = 1;
                CH__.CS_data.CS_inds_reconstruct( CH__.CS_data.CS_inds_reconstruct > length( CH__.CH_data.ch_time_reconstruct ) ) = length( CH__.CH_data.ch_time_reconstruct );
                
                CH__.SS_data.SS_event_trace = false( size(CH__.CH_data.ch_time_reconstruct) );
                CH__.SS_data.SS_event_trace( CH__.SS_data.SS_ind ) = true ;
                CH__.SS_data.SS_event_trace( 1   ) = false;
                CH__.SS_data.SS_event_trace( end ) = false;
                
                CH__.SS_data.SS_event_reconstruct = CH__.SS_data.SS_event_trace( CH__.CS_data.CS_inds_reconstruct );
                % CSxSS correlogram
                CSxSS_AUTO       = CH__.SS_data.SS_event_reconstruct;
                cs_inds_span     = repmat(inds_span(:)',     size(CS_time(:),1), 1);
                cs_bin_size_time = repmat(bin_size_time(:)', size(CS_time(:),1), 1);
            else
                CSxSS_AUTO       = false(0, length(inds_span(:)'));
                cs_inds_span     = nan(0, length(inds_span(:)'));
                cs_bin_size_time = nan(0, 1);
            end
            
            Corr_data = struct;
            Corr_data.CS_inds_span     = cs_inds_span;
            Corr_data.CS_bin_size_time = cs_bin_size_time;
            Corr_data.SS_inds_span     = ss_inds_span;
            Corr_data.SS_bin_size_time = ss_bin_size_time;
            Corr_data.SS_SSxSS_AUTO    = SSxSS_AUTO;
            Corr_data.CS_CSxSS_AUTO    = CSxSS_AUTO;
        end
        
        function [pca_1, pca_2] = ESN_evaluate_pca(~, signal_waveforms)
            if (size(signal_waveforms, 1) > 3)
                [~, pca_score, ~] = pca(signal_waveforms);
                pca_1 = pca_score(:,1);
                pca_2 = pca_score(:,2);
            else
                pca_1 = nan(size(signal_waveforms, 1), 1);
                pca_2 = nan(size(signal_waveforms, 1), 1);
            end
        end
        
        function ESN_evaluate_corr_and_pca(app)
            [cs_pca_1, cs_pca_2] = ESN_evaluate_pca(app, app.app_databade.current_slot_data.CS_waveform( :, app.app_databade.pca_window_cs_ind_min:app.app_databade.pca_window_cs_ind_max) );
            [ss_pca_1, ss_pca_2] = ESN_evaluate_pca(app, app.app_databade.current_slot_data.SS_waveform( :, app.app_databade.pca_window_ss_ind_min:app.app_databade.pca_window_ss_ind_max) );
            app.app_databade.current_slot_data.CS_pca_1 = cs_pca_1;
            app.app_databade.current_slot_data.CS_pca_2 = cs_pca_2;
            app.app_databade.current_slot_data.SS_pca_1 = ss_pca_1;
            app.app_databade.current_slot_data.SS_pca_2 = ss_pca_2;
            
            Corr_data_ = ESN_correlogram(app, app.app_databade.current_slot_data.SS_time, app.app_databade.current_slot_data.CS_time);
            app.app_databade.current_slot_data.Corr_CS_inds_span     = Corr_data_.CS_inds_span;
            app.app_databade.current_slot_data.Corr_CS_bin_size_time = Corr_data_.CS_bin_size_time;
            app.app_databade.current_slot_data.Corr_SS_inds_span     = Corr_data_.SS_inds_span;
            app.app_databade.current_slot_data.Corr_SS_bin_size_time = Corr_data_.SS_bin_size_time;
            app.app_databade.current_slot_data.Corr_SS_SSxSS_AUTO    = Corr_data_.SS_SSxSS_AUTO;
            app.app_databade.current_slot_data.Corr_CS_CSxSS_AUTO    = Corr_data_.CS_CSxSS_AUTO;
        end
        
        function ESN_plot_slot_data(app)
            app.txtstatic_status_message.Text = ['Plotting ' 'slot data # ' num2str(app.app_databade.current_slot_num) ' ... '];
            app.lamp_status.Color = [1 0 0];
            pause(0.050);
            ESN_plot_rawSignal(app);
            ESN_plot_ss_wave(app);
            ESN_plot_cs_wave(app);
            ESN_plot_ss_isi(app);
            ESN_plot_cs_isi(app);
            ESN_plot_ss_peak_dist(app);
            ESN_plot_cs_peak_dist(app);
            ESN_plot_ss_pca(app);
            ESN_plot_cs_pca(app);
            ESN_plot_ss_corr(app);
            ESN_plot_cs_corr(app);
            slot_time_duration = app.app_databade.current_slot_data.CH_time(end) - app.app_databade.current_slot_data.CH_time(1);
            SS_Firing_Rate = length(app.app_databade.current_slot_data.SS_time) ./ slot_time_duration;
            CS_Firing_Rate = length(app.app_databade.current_slot_data.CS_time) ./ slot_time_duration;
            app.txtstatic_status_ssFiring.Text = ['SS Firing: ' num2str(SS_Firing_Rate,'%4.1f') ' Hz' '.'];
            app.txtstatic_status_csFiring.Text = ['CS Firing: ' num2str(CS_Firing_Rate,'%4.2f') ' Hz' '.'];
            app.txtstatic_status_message.Text = ['Plotting ' 'slot data # ' num2str(app.app_databade.current_slot_num) ' ... ' '-> Completed.'];
            app.lamp_status.Color = [0 1 0];
            pause(0.050);
        end
        
        function ESN_plot_rawSignal(app)
            if app.chkbox_pos_cs_lopass.Value
                threshold_CS      =  app.txtedit_cs_threshold.Value;
            else
                threshold_CS      = -app.txtedit_cs_threshold.Value;
            end
            if app.chkbox_neg_ss_hipass.Value
                threshold_SS      = -app.txtedit_ss_threshold.Value;
            else
                threshold_SS      =  app.txtedit_ss_threshold.Value;
            end
            cla( app.axes_rawSignal)
            grid(app.axes_rawSignal, 'on')
            hold(app.axes_rawSignal, 'on')
            app.app_databade.plot_handles.axes_rawSignal.h1 = plot(app.axes_rawSignal , app.app_databade.current_slot_data.CH_time, app.app_databade.current_slot_data.CH_hipass, '-b', 'LineWidth', 0.5); % SS
            app.app_databade.plot_handles.axes_rawSignal.h2 = plot(app.axes_rawSignal,  app.app_databade.current_slot_data.CH_time, app.app_databade.current_slot_data.CH_lopass, '-r', 'LineWidth', 1.0); % CS
            app.app_databade.plot_handles.axes_rawSignal.h4 = plot(app.axes_rawSignal,  app.app_databade.current_slot_data.SS_time, app.app_databade.current_slot_data.SS_peak, '+', 'Color', [0.3, 0.3, 0.9], 'LineWidth', 1.0); % SS
            app.app_databade.plot_handles.axes_rawSignal.h3 = plot(app.axes_rawSignal,  app.app_databade.current_slot_data.CS_time, app.app_databade.current_slot_data.CS_peak, '*', 'Color', [0.9, 0.3, 0.3], 'LineWidth', 1.5); % CS
            app.app_databade.plot_handles.axes_rawSignal.h6 = plot(app.axes_rawSignal, [app.app_databade.current_slot_data.CH_time(1); app.app_databade.current_slot_data.CH_time(end)],    [1; 1]*threshold_SS, '-', 'Color', [0.3, 0.3, 0.9]); % SS
            app.app_databade.plot_handles.axes_rawSignal.h5 = plot(app.axes_rawSignal, [app.app_databade.current_slot_data.CH_time(1); app.app_databade.current_slot_data.CH_time(end)],    [1; 1]*threshold_CS, '-', 'Color', [0.9, 0.3, 0.3]); % CS
            
            SS_ind_selected = app.app_databade.current_slot_data.SS_ind_selected;
            app.app_databade.plot_handles.axes_rawSignal.h8 = plot(app.axes_rawSignal,  app.app_databade.current_slot_data.SS_time(SS_ind_selected,:), app.app_databade.current_slot_data.SS_peak(SS_ind_selected,:), 'o', 'Color', [0.0, 0.0, 0.2], 'LineWidth', 1.5); % SS
            
            CS_ind_selected = app.app_databade.current_slot_data.CS_ind_selected;
            app.app_databade.plot_handles.axes_rawSignal.h7 = plot(app.axes_rawSignal,  app.app_databade.current_slot_data.CS_time(CS_ind_selected,:), app.app_databade.current_slot_data.CS_peak(CS_ind_selected,:), 'o', 'Color', [0.2, 0.0, 0.0], 'LineWidth', 1.5); % CS
            
            app.axes_rawSignal.XLimMode = 'auto';
            app.axes_rawSignal.YLimMode = 'auto';
            app.axes_rawSignal.XLim     = [app.app_databade.current_slot_data.CH_time(1) app.app_databade.current_slot_data.CH_time(end)];
            app.axes_rawSignal.Interactions     = [app.app_databade.plot_handles.panInterac_X app.app_databade.plot_handles.zoomInterac_X];
            set(app.app_databade.plot_handles.axes_rawSignal.h1, 'HitTest', 'off');
            set(app.app_databade.plot_handles.axes_rawSignal.h2, 'HitTest', 'off');
            set(app.app_databade.plot_handles.axes_rawSignal.h3, 'HitTest', 'off');
            set(app.app_databade.plot_handles.axes_rawSignal.h4, 'HitTest', 'off');
            set(app.app_databade.plot_handles.axes_rawSignal.h5, 'HitTest', 'off');
            set(app.app_databade.plot_handles.axes_rawSignal.h6, 'HitTest', 'off');
            set(app.app_databade.plot_handles.axes_rawSignal.h7, 'HitTest', 'off');
            set(app.app_databade.plot_handles.axes_rawSignal.h8, 'HitTest', 'off');
        end
        
        function ESN_plot_ss_peak_dist(app)
            cla( app.axes_rawSignal_ss_dist)
            grid(app.axes_rawSignal_ss_dist, 'on')
            hold(app.axes_rawSignal_ss_dist, 'on')
            SS_peak = app.app_databade.current_slot_data.SS_peak;
            histogram(app.axes_rawSignal_ss_dist, SS_peak, 'DisplayStyle', 'stairs', 'Normalization', 'count', 'LineWidth', 2, 'EdgeColor', [0.1 0.1 0.9], 'Orientation', 'horizontal');
            
            app.axes_rawSignal_ss_dist.XLimMode = 'auto';
            app.axes_rawSignal_ss_dist.YLimMode = 'auto';
            app.axes_rawSignal_ss_dist.Interactions     = [app.app_databade.plot_handles.panInterac_Y app.app_databade.plot_handles.zoomInterac_Y];
        end
        
        function ESN_plot_cs_peak_dist(app)
            cla( app.axes_rawSignal_cs_dist)
            grid(app.axes_rawSignal_cs_dist, 'on')
            hold(app.axes_rawSignal_cs_dist, 'on')
            CS_peak = app.app_databade.current_slot_data.CS_peak_lopass;
            histogram(app.axes_rawSignal_cs_dist, CS_peak, 'DisplayStyle', 'stairs', 'Normalization', 'count', 'LineWidth', 2, 'EdgeColor', [0.9 0.1 0.1], 'Orientation', 'horizontal');
            
            app.axes_rawSignal_cs_dist.XLimMode = 'auto';
            app.axes_rawSignal_cs_dist.YLimMode = 'auto';
            app.axes_rawSignal_cs_dist.Interactions     = [app.app_databade.plot_handles.panInterac_Y app.app_databade.plot_handles.zoomInterac_Y];
        end
        
        function ESN_plot_ss_wave(app)
            cla( app.axes_ss_wave)
            grid(app.axes_ss_wave, 'on')
            hold(app.axes_ss_wave, 'on')
            ss_wave_y_axis = app.app_databade.current_slot_data.SS_waveform';
            if (~isempty(ss_wave_y_axis))
                ss_wave_x_axis = repmat((1:1:size(ss_wave_y_axis, 1))', 1, size(ss_wave_y_axis, 2)) ./ (app.app_databade.sample_freq/1000);
                ss_wave_y_axis(end+1, :) = nan(1, size(ss_wave_y_axis, 2));
                ss_wave_x_axis(end+1, :) = nan(1, size(ss_wave_x_axis, 2));
                app.axes_ss_wave.XLim   = [nanmin(ss_wave_x_axis(:)) nanmax(ss_wave_x_axis(:))];
                pca_window_x_min = ss_wave_x_axis(app.app_databade.pca_window_ss_ind_min, 1);
                pca_window_x_max = ss_wave_x_axis(app.app_databade.pca_window_ss_ind_max, 1);
            else
                ss_wave_x_axis = nan(size(ss_wave_y_axis));
                pca_window_x_min = nan;
                pca_window_x_max = nan;
            end
            app.app_databade.plot_handles.axes_ss_wave.h1 = plot(app.axes_ss_wave, ss_wave_x_axis(:), ss_wave_y_axis(:), '-b');
            
            SS_ind_selected = app.app_databade.current_slot_data.SS_ind_selected;
            if (sum(SS_ind_selected)>0) && (~isempty(ss_wave_y_axis))
                ss_wave_y_axis_selected = ss_wave_y_axis(:, SS_ind_selected);
                ss_wave_x_axis_selected = repmat((1:1:size(ss_wave_y_axis_selected, 1))', 1, size(ss_wave_y_axis_selected, 2)) ./ (app.app_databade.sample_freq/1000);
                ss_wave_y_axis_selected(end+1, :) = nan(1, size(ss_wave_y_axis_selected, 2));
                ss_wave_x_axis_selected(end+1, :) = nan(1, size(ss_wave_x_axis_selected, 2));
            else
                ss_wave_y_axis_selected = nan(size(ss_wave_y_axis));
                ss_wave_x_axis_selected = nan(size(ss_wave_y_axis));
            end
            app.app_databade.plot_handles.axes_ss_wave.selected_waves = plot(app.axes_ss_wave , ss_wave_x_axis_selected(:), ss_wave_y_axis_selected(:), '-k', 'LineWidth', 1.5); % SS
            
            app.axes_ss_wave.XLimMode = 'auto';
            app.axes_ss_wave.YLimMode = 'auto';
            
            pca_window_y_min = app.axes_ss_wave.YLim(1);
            pca_window_y_max = app.axes_ss_wave.YLim(2);
            app.app_databade.plot_handles.axes_ss_wave.h2 = plot(app.axes_ss_wave, ...
            [pca_window_x_min; pca_window_x_min; nan; pca_window_x_max; pca_window_x_max], ...
            [pca_window_y_min; pca_window_y_max; nan; pca_window_y_min; pca_window_y_max], 'k', 'LineWidth', 1.0, 'Color', [0.1, 0.1, 0.5]);
            
            app.axes_ss_wave.Interactions = [app.app_databade.plot_handles.panInterac_X app.app_databade.plot_handles.zoomInterac_X];
            set(app.app_databade.plot_handles.axes_ss_wave.h1, 'HitTest', 'off');
            set(app.app_databade.plot_handles.axes_ss_wave.selected_waves, 'HitTest', 'off');
        end
        
        function ESN_plot_cs_wave(app)
            cla( app.axes_cs_wave)
            grid(app.axes_cs_wave, 'on')
            hold(app.axes_cs_wave, 'on')
            cs_wave_y_axis  = app.app_databade.current_slot_data.CS_waveform';
            if (~isempty(cs_wave_y_axis))
                cs_wave_x_axis = repmat((1:1:size(cs_wave_y_axis, 1))', 1, size(cs_wave_y_axis, 2)) ./ (app.app_databade.sample_freq/1000);
                cs_wave_y_axis(end+1, :) = nan(1, size(cs_wave_y_axis, 2));
                cs_wave_x_axis(end+1, :) = nan(1, size(cs_wave_x_axis, 2));
                app.axes_cs_wave.XLim   = [nanmin(cs_wave_x_axis(:)) nanmax(cs_wave_x_axis(:))];
                pca_window_x_min = cs_wave_x_axis(app.app_databade.pca_window_cs_ind_min, 1);
                pca_window_x_max = cs_wave_x_axis(app.app_databade.pca_window_cs_ind_max, 1);
            else
                cs_wave_x_axis = nan(size(cs_wave_y_axis));
                pca_window_x_min = nan;
                pca_window_x_max = nan;
            end
            app.app_databade.plot_handles.axes_cs_wave.h1 = plot(app.axes_cs_wave, cs_wave_x_axis(:), cs_wave_y_axis(:), '-r');
            
            CS_ind_selected = app.app_databade.current_slot_data.CS_ind_selected;
            if (sum(CS_ind_selected)>0) && (~isempty(cs_wave_y_axis))
                cs_wave_y_axis_selected = cs_wave_y_axis(:, CS_ind_selected);
                cs_wave_x_axis_selected = repmat((1:1:size(cs_wave_y_axis_selected, 1))', 1, size(cs_wave_y_axis_selected, 2)) ./ (app.app_databade.sample_freq/1000);
                cs_wave_y_axis_selected(end+1, :) = nan(1, size(cs_wave_y_axis_selected, 2));
                cs_wave_x_axis_selected(end+1, :) = nan(1, size(cs_wave_x_axis_selected, 2));
            else
                cs_wave_y_axis_selected = nan(size(cs_wave_y_axis));
                cs_wave_x_axis_selected = nan(size(cs_wave_y_axis));
            end
            app.app_databade.plot_handles.axes_cs_wave.selected_waves = plot(app.axes_cs_wave , cs_wave_x_axis_selected(:), cs_wave_y_axis_selected(:), '-k', 'LineWidth', 1.5); % CS
            
            app.axes_cs_wave.XLimMode = 'auto';
            app.axes_cs_wave.YLimMode = 'auto';
            
            pca_window_y_min = app.axes_cs_wave.YLim(1);
            pca_window_y_max = app.axes_cs_wave.YLim(2);
            app.app_databade.plot_handles.axes_cs_wave.h2 = plot(app.axes_cs_wave, ...
            [pca_window_x_min; pca_window_x_min; nan; pca_window_x_max; pca_window_x_max], ...
            [pca_window_y_min; pca_window_y_max; nan; pca_window_y_min; pca_window_y_max], '-', 'LineWidth', 1.0, 'Color', [0.5, 0.1, 0.1]);
            
            app.axes_cs_wave.Interactions = [app.app_databade.plot_handles.panInterac_X app.app_databade.plot_handles.zoomInterac_X];
            set(app.app_databade.plot_handles.axes_cs_wave.h1, 'HitTest', 'off');
            set(app.app_databade.plot_handles.axes_cs_wave.selected_waves, 'HitTest', 'off');
        end
        
        function ESN_plot_ss_isi(app)
            cla( app.axes_ss_isi)
            grid(app.axes_ss_isi, 'on')
            hold(app.axes_ss_isi, 'on')
            SS_time = app.app_databade.current_slot_data.SS_time;
            edges_SS = (0 : 0.002 : 0.050) *1000;
            ISI_SS = diff(SS_time) * 1000;
            histogram(app.axes_ss_isi, ISI_SS, edges_SS, 'DisplayStyle', 'stairs', 'Normalization', 'count', 'LineWidth', 2, 'EdgeColor', [0.1 0.1 0.9]);
            set(app.axes_ss_isi, 'XTick', (0:0.010:0.050)*1000);
            app.axes_ss_isi.XLimMode = 'auto';
            app.axes_ss_isi.YLimMode = 'auto';
            app.axes_ss_isi.Interactions = [app.app_databade.plot_handles.panInterac_X app.app_databade.plot_handles.zoomInterac_X];
        end
        
        function ESN_plot_cs_isi(app)
            cla( app.axes_cs_isi)
            grid(app.axes_cs_isi, 'on')
            hold(app.axes_cs_isi, 'on')
            CS_time = app.app_databade.current_slot_data.CS_time;
            edges_CS = 0 : 0.200 : 5.000;
            ISI_CS = diff(CS_time);
            histogram(app.axes_cs_isi, ISI_CS, edges_CS, 'DisplayStyle', 'stairs', 'Normalization', 'count', 'LineWidth', 2, 'EdgeColor', [0.9 0.1 0.1]);
            set(app.axes_cs_isi, 'XTick', (0:1.0:5.0));
            app.axes_cs_isi.XLimMode = 'auto';
            app.axes_cs_isi.YLimMode = 'auto';
            app.axes_cs_isi.Interactions = [app.app_databade.plot_handles.panInterac_X app.app_databade.plot_handles.zoomInterac_X];
        end
        
        function ESN_plot_ss_pca(app)
            cla( app.axes_ss_pca)
            grid(app.axes_ss_pca, 'on')
            hold(app.axes_ss_pca, 'on')
            ss_pca_1 = app.app_databade.current_slot_data.SS_pca_1;
            ss_pca_2 = app.app_databade.current_slot_data.SS_pca_2;
            app.app_databade.plot_handles.axes_ss_pca.h1 = plot(app.axes_ss_pca,  ss_pca_1,     ss_pca_2, '+b', 'LineWidth', 0.5);
            
            SS_ind_selected = app.app_databade.current_slot_data.SS_ind_selected;
            if (sum(SS_ind_selected)>0) && (~isempty(ss_pca_1))
                ss_pca_1_selected = ss_pca_1(SS_ind_selected);
                ss_pca_2_selected = ss_pca_2(SS_ind_selected);
            else
                ss_pca_1_selected = nan(size(ss_pca_1));
                ss_pca_2_selected = nan(size(ss_pca_2));
            end
            app.app_databade.plot_handles.axes_ss_pca.selected_points = plot(app.axes_ss_pca , ss_pca_1_selected,     ss_pca_2_selected, 'ok', 'LineWidth', 1.5); % SS
            app.axes_ss_pca.XLimMode = 'auto';
            app.axes_ss_pca.YLimMode = 'auto';
            set(app.app_databade.plot_handles.axes_ss_pca.h1, 'HitTest', 'off');
            set(app.app_databade.plot_handles.axes_ss_pca.selected_points, 'HitTest', 'off');
        end
        
        function ESN_plot_cs_pca(app)
            cla( app.axes_cs_pca)
            grid(app.axes_cs_pca, 'on')
            hold(app.axes_cs_pca, 'on')
            cs_pca_1 = app.app_databade.current_slot_data.CS_pca_1;
            cs_pca_2 = app.app_databade.current_slot_data.CS_pca_2;
            app.app_databade.plot_handles.axes_cs_pca.h1 = plot(app.axes_cs_pca,  cs_pca_1,     cs_pca_2, '*r', 'LineWidth', 0.5);
            
            CS_ind_selected = app.app_databade.current_slot_data.CS_ind_selected;
            if (sum(CS_ind_selected)>0) && (~isempty(cs_pca_1))
                cs_pca_1_selected = cs_pca_1(CS_ind_selected);
                cs_pca_2_selected = cs_pca_2(CS_ind_selected);
            else
                cs_pca_1_selected = nan(size(cs_pca_1));
                cs_pca_2_selected = nan(size(cs_pca_2));
            end
            app.app_databade.plot_handles.axes_cs_pca.selected_points = plot(app.axes_cs_pca , cs_pca_1_selected,     cs_pca_2_selected, 'ok', 'LineWidth', 1.5); % CS
            app.axes_cs_pca.XLimMode = 'auto';
            app.axes_cs_pca.YLimMode = 'auto';
            set(app.app_databade.plot_handles.axes_cs_pca.h1, 'HitTest', 'off');
            set(app.app_databade.plot_handles.axes_cs_pca.selected_points, 'HitTest', 'off');
        end
        
        function ESN_plot_ss_corr(app)
            cla( app.axes_ss_corr)
            grid(app.axes_ss_corr, 'on')
            hold(app.axes_ss_corr, 'on')
            SSxSS_AUTO = app.app_databade.current_slot_data.Corr_SS_SSxSS_AUTO;
            if size(SSxSS_AUTO, 1) > 1
                inds_span = mean(app.app_databade.current_slot_data.Corr_SS_inds_span);
            else
                inds_span =      app.app_databade.current_slot_data.Corr_SS_inds_span;
            end
            bin_size_time = mean(app.app_databade.current_slot_data.Corr_SS_bin_size_time);
            if (~isempty(SSxSS_AUTO))
                x_axis_ = (inds_span * bin_size_time * 1000)';
                if size(SSxSS_AUTO, 1) > 1
                    prob_value_ = mean(SSxSS_AUTO);
                else
                    prob_value_ =      SSxSS_AUTO;
                end
                prob_value_(round(length(inds_span)/2)) = 0;
                y_axis_mean_ = prob_value_;
                y_axis_stdv_ = sqrt(size(SSxSS_AUTO, 1) .* ( prob_value_ .* (1 - prob_value_) ) ) ./ size(SSxSS_AUTO, 1);
                app.axes_ss_corr.XLim   = [x_axis_(1) x_axis_(end)];
            else
                x_axis_      = nan(size(SSxSS_AUTO));
                y_axis_mean_ = nan(size(SSxSS_AUTO));
                y_axis_stdv_ = nan(size(SSxSS_AUTO));
            end
            
            app.app_databade.plot_handles.axes_ss_corr.h1 = plot(app.axes_ss_corr, x_axis_, ESN_smooth(app, y_axis_mean_)+ESN_smooth(app, y_axis_stdv_), 'LineWidth', 1, 'Color', [0.5 0.5 0.9]);
            app.app_databade.plot_handles.axes_ss_corr.h2 = plot(app.axes_ss_corr, x_axis_, ESN_smooth(app, y_axis_mean_)-ESN_smooth(app, y_axis_stdv_), 'LineWidth', 1, 'Color', [0.5 0.5 0.9]);
            app.app_databade.plot_handles.axes_ss_corr.h3 = plot(app.axes_ss_corr, x_axis_, ESN_smooth(app, y_axis_mean_),                               'LineWidth', 2, 'Color', [0.1 0.1 0.9]);
            
            app.axes_ss_corr.YLim   = [0 inf];
            app.axes_ss_corr.Interactions = [app.app_databade.plot_handles.panInterac_X app.app_databade.plot_handles.zoomInterac_X];
            set(app.app_databade.plot_handles.axes_ss_corr.h1, 'HitTest', 'off');
            set(app.app_databade.plot_handles.axes_ss_corr.h2, 'HitTest', 'off');
            set(app.app_databade.plot_handles.axes_ss_corr.h3, 'HitTest', 'off');
        end
        
        function ESN_plot_cs_corr(app)
            cla( app.axes_cs_corr)
            grid(app.axes_cs_corr, 'on')
            hold(app.axes_cs_corr, 'on')
            CSxSS_AUTO    = app.app_databade.current_slot_data.Corr_CS_CSxSS_AUTO;
            
            if size(CSxSS_AUTO, 1) > 1
                inds_span = mean(app.app_databade.current_slot_data.Corr_CS_inds_span);
            else
                inds_span      = app.app_databade.current_slot_data.Corr_CS_inds_span;
            end
            bin_size_time = mean(app.app_databade.current_slot_data.Corr_CS_bin_size_time);
            if (~isempty(CSxSS_AUTO))
                x_axis_ = (inds_span * bin_size_time * 1000)';
                if size(CSxSS_AUTO, 1) > 1
                    prob_value_ = mean(CSxSS_AUTO);
                else
                    prob_value_ =      CSxSS_AUTO;
                end
                y_axis_mean_ = prob_value_;
                y_axis_stdv_ = sqrt(size(CSxSS_AUTO, 1) .* ( prob_value_ .* (1 - prob_value_) ) ) ./ size(CSxSS_AUTO, 1);
                app.axes_cs_corr.XLim   = [x_axis_(1) x_axis_(end)];
            else
                x_axis_      = nan(size(CSxSS_AUTO));
                y_axis_mean_ = nan(size(CSxSS_AUTO));
                y_axis_stdv_ = nan(size(CSxSS_AUTO));
            end
            
            app.app_databade.plot_handles.axes_cs_corr.h1 = plot(app.axes_cs_corr, x_axis_, ESN_smooth(app, y_axis_mean_)+ESN_smooth(app, y_axis_stdv_), 'LineWidth', 1, 'Color', [0.9 0.5 0.5]);
            app.app_databade.plot_handles.axes_cs_corr.h2 = plot(app.axes_cs_corr, x_axis_, ESN_smooth(app, y_axis_mean_)-ESN_smooth(app, y_axis_stdv_), 'LineWidth', 1, 'Color', [0.9 0.5 0.5]);
            app.app_databade.plot_handles.axes_cs_corr.h3 = plot(app.axes_cs_corr, x_axis_, ESN_smooth(app, y_axis_mean_),                               'LineWidth', 2, 'Color', [0.9 0.1 0.1]);
            
            app.axes_cs_corr.YLim   = [0 inf];
            app.axes_cs_corr.Interactions = [app.app_databade.plot_handles.panInterac_X app.app_databade.plot_handles.zoomInterac_X];
            set(app.app_databade.plot_handles.axes_cs_corr.h1, 'HitTest', 'off');
            set(app.app_databade.plot_handles.axes_cs_corr.h2, 'HitTest', 'off');
            set(app.app_databade.plot_handles.axes_cs_corr.h3, 'HitTest', 'off');
        end
        
        function smooth_data_ = ESN_smooth(~, data_)
            frame_len = 9;
            order = 2;
            %smooth_data_ = smooth(data_, frame_len, 'sgolay', order);
            if length(data_) > frame_len
                smooth_data_ = sgolayfilt(double(data_), order, frame_len);
            else
                smooth_data_ = data_;
            end
        end
        
        function out_ = ESN_round(~, in_, accuracy_)
            in_ = double(in_);
            out_ = round(in_ ./ accuracy_) .* accuracy_;
        end
        function ESN_pop_up_figure(app)
            app.app_databade.plot_handles.pop_up_fig_handle = figure(1);
            set(app.app_databade.plot_handles.pop_up_fig_handle, ...
                'units','normalized','outerposition',[0 0 1 1], ...
                'MenuBar', 'none', 'ToolBar', 'none',...
                'DockControls', 'off',...
                'WindowStyle', 'modal', ...
                'RendererMode', 'manual', 'Renderer', 'opengl', ...
                'GraphicsSmoothing', 'off', ...
                'hittest','off');
            clf(app.app_databade.plot_handles.pop_up_fig_handle);
        end
        function ESN_plot_save_file(app, SS_data, CS_data, Corr_data)
            % figure
            pop_up_fig_handle = figure(1);
            clf(pop_up_fig_handle)
            
            % subplot(2,2,1) Waveform
            plot_handle_(1) = subplot(2,2,1);
            hold on
            ss_wave_y_axis = SS_data.SS_waveform;
            ss_wave_x_axis = ( (1:1:size(ss_wave_y_axis, 2))./ (app.app_databade.sample_freq/1000) ) - 2;
            plot(ss_wave_x_axis, nanmean(ss_wave_y_axis)+nanstd(ss_wave_y_axis), ...
                'LineWidth', 1, 'Color', [0.5 0.5 0.9])
            plot(ss_wave_x_axis, nanmean(ss_wave_y_axis)-nanstd(ss_wave_y_axis), ...
                'LineWidth', 1, 'Color', [0.5 0.5 0.9])
            plot(ss_wave_x_axis, nanmean(ss_wave_y_axis), ...
                'LineWidth', 2, 'Color', [0.1 0.1 0.9])
            cs_wave_y_axis = CS_data.CS_waveform;
            cs_wave_x_axis = ( (1:1:size(cs_wave_y_axis, 2))./ (app.app_databade.sample_freq/1000) ) - 2;
            plot(cs_wave_x_axis, nanmean(cs_wave_y_axis)+nanstd(cs_wave_y_axis), ...
                'LineWidth', 1, 'Color', [0.9 0.5 0.5])
            plot(cs_wave_x_axis, nanmean(cs_wave_y_axis)-nanstd(cs_wave_y_axis), ...
                'LineWidth', 1, 'Color', [0.9 0.5 0.5])
            plot(cs_wave_x_axis, nanmean(cs_wave_y_axis), ...
                'LineWidth', 2, 'Color', [0.9 0.1 0.1])
            xlabel('Time (ms)')
            ylabel('Voltage (uv)')
            
            % subplot(2,2,2) Probablities
            plot_handle_(2) = subplot(2,2,2);
            hold on
            SSxSS_AUTO    =      Corr_data.Corr_SS_SSxSS_AUTO;
            inds_span     = mean(Corr_data.Corr_SS_inds_span);
            bin_size_time = mean(Corr_data.Corr_SS_bin_size_time);
            x_axis_       = (inds_span * bin_size_time * 1000)';
            prob_value_ = mean(SSxSS_AUTO);
            prob_value_(round(length(inds_span)/2)) = 0;
            y_axis_mean_ = prob_value_;
            y_axis_stdv_ = sqrt(size(SSxSS_AUTO, 1) .* ( prob_value_ .* (1 - prob_value_) ) ) ./ size(SSxSS_AUTO, 1);
            plot(x_axis_, y_axis_mean_+y_axis_stdv_, 'LineWidth', 1, 'Color', [0.5 0.5 0.9])
            plot(x_axis_, y_axis_mean_-y_axis_stdv_, 'LineWidth', 1, 'Color', [0.5 0.5 0.9])
            plot(x_axis_, y_axis_mean_,              'LineWidth', 2, 'Color', [0.1 0.1 0.9])
            
            CSxSS_AUTO    =      Corr_data.Corr_CS_CSxSS_AUTO;
            inds_span     = mean(Corr_data.Corr_CS_inds_span);
            bin_size_time = mean(Corr_data.Corr_CS_bin_size_time);
            x_axis_       = (inds_span * bin_size_time * 1000)';
            prob_value_ = mean(CSxSS_AUTO);
            y_axis_mean_ = prob_value_;
            y_axis_stdv_ = sqrt(size(CSxSS_AUTO, 1) .* ( prob_value_ .* (1 - prob_value_) ) ) ./ size(CSxSS_AUTO, 1);
            plot(x_axis_, y_axis_mean_+y_axis_stdv_, 'LineWidth', 1, 'Color', [0.9 0.5 0.5])
            plot(x_axis_, y_axis_mean_-y_axis_stdv_, 'LineWidth', 1, 'Color', [0.9 0.5 0.5])
            plot(x_axis_, y_axis_mean_,              'LineWidth', 2, 'Color', [0.9 0.1 0.1])
            
            ylim([0 inf])
            if(~isempty(x_axis_)) && (sum(~isnan(x_axis_))>0)
                xlim([x_axis_(1) x_axis_(end)])
            end
            xlabel('Time (ms)')
            ylabel('Probability')
            
            % subplot(2,2,3) ISI SS
            plot_handle_(3) = subplot(2,2,3);
            hold on
            
            edges_SS = (0 : 0.002 : 0.050) *1000;
            ISI_SS = diff(SS_data.SS_time) * 1000;
            histogram(ISI_SS,edges_SS, 'DisplayStyle', 'stairs', 'Normalization', 'probability', 'LineWidth', 2, 'EdgeColor', [0.1 0.1 0.9]);
            set(plot_handle_(3), 'XTick', (0:0.010:0.050)*1000)
            xlabel('Time (ms)')
            ylabel('Probability')
            
            % subplot(2,2,4) ISI CS
            plot_handle_(4) = subplot(2,2,4);
            hold on
            edges_CS = 0 : 0.200 : 5.000;
            ISI_CS = diff(CS_data.CS_time);
            histogram(ISI_CS,edges_CS, 'DisplayStyle', 'stairs', 'Normalization', 'probability', 'LineWidth', 2, 'EdgeColor', [0.9 0.1 0.1]);
            set(plot_handle_(4), 'XTick', (0:1.0:5.0))
            xlabel('Time (s)')
            ylabel('Probability')
            
            sgtitle(app.app_databade.save_file_name_fig, 'Interpreter', 'none');
            
            ESN_Beautify_Plot(pop_up_fig_handle);
            figure_size  = [8.0 8.0];
            paper_margin = [0.1 0.1];
            paper_size = figure_size + 2 * paper_margin;
            set(pop_up_fig_handle, 'PaperSize', paper_size);
            set(pop_up_fig_handle, 'PaperPositionMode', 'manual');
            set(pop_up_fig_handle, 'PaperPosition', [paper_margin figure_size]);
            set(pop_up_fig_handle, 'Position', [[1 1] figure_size]);
            set(pop_up_fig_handle, 'PaperOrientation', 'portrait');
            app.app_databade.plot_handles.pop_up_fig_handle = pop_up_fig_handle;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning('off','signal:findpeaks:largeMinPeakHeight');
            app.app_databade.current_slot_num = 1;
            app.app_databade.total_num_slots  = 2;
            app.app_databade.pca_window_ss_ind_min = 10;
            app.app_databade.pca_window_ss_ind_max = 80;
            app.app_databade.pca_window_cs_ind_min = 40;
            app.app_databade.pca_window_cs_ind_max = 160;
            
            ESN_init_slot_data(app, 0)
            app.app_databade.plot_handles.panInterac_X   = panInteraction( 'Dimensions','x');
            app.app_databade.plot_handles.zoomInterac_X  = zoomInteraction('Dimensions','x');
            app.app_databade.plot_handles.panInterac_Y   = panInteraction( 'Dimensions','y');
            app.app_databade.plot_handles.zoomInterac_Y  = zoomInteraction('Dimensions','y');
            app.app_databade.plot_handles.panInterac_XY  = panInteraction( 'Dimensions','xy');
            app.app_databade.plot_handles.zoomInterac_XY = zoomInteraction('Dimensions','xy');
            %disableDefaultInteractivity(app.axes_rawSignal)
            %disableDefaultInteractivity(app.axes_ss_wave)
            %disableDefaultInteractivity(app.axes_cs_wave)
            %disableDefaultInteractivity(app.axes_ss_isi)
            %disableDefaultInteractivity(app.axes_cs_isi)
            %disableDefaultInteractivity(app.axes_ss_corr)
            %disableDefaultInteractivity(app.axes_cs_corr)
            %disableDefaultInteractivity(app.axes_rawSignal_ss_dist)
            %disableDefaultInteractivity(app.axes_rawSignal_cs_dist)
            %disableDefaultInteractivity(app.axes_cs_pca)
            %disableDefaultInteractivity(app.axes_ss_pca)
            app.axes_rawSignal.Interactions         = [app.app_databade.plot_handles.panInterac_X  app.app_databade.plot_handles.zoomInterac_X ];
            app.axes_ss_wave.Interactions           = [app.app_databade.plot_handles.panInterac_X  app.app_databade.plot_handles.zoomInterac_X ];
            app.axes_cs_wave.Interactions           = [app.app_databade.plot_handles.panInterac_X  app.app_databade.plot_handles.zoomInterac_X ];
            app.axes_ss_isi.Interactions            = [app.app_databade.plot_handles.panInterac_X  app.app_databade.plot_handles.zoomInterac_X ];
            app.axes_cs_isi.Interactions            = [app.app_databade.plot_handles.panInterac_X  app.app_databade.plot_handles.zoomInterac_X ];
            app.axes_ss_corr.Interactions           = [app.app_databade.plot_handles.panInterac_X  app.app_databade.plot_handles.zoomInterac_X ];
            app.axes_cs_corr.Interactions           = [app.app_databade.plot_handles.panInterac_X  app.app_databade.plot_handles.zoomInterac_X ];
            app.axes_rawSignal_ss_dist.Interactions = [app.app_databade.plot_handles.panInterac_Y  app.app_databade.plot_handles.zoomInterac_Y ];
            app.axes_rawSignal_cs_dist.Interactions = [app.app_databade.plot_handles.panInterac_Y  app.app_databade.plot_handles.zoomInterac_Y ];
            app.axes_ss_pca.Interactions            = [app.app_databade.plot_handles.panInterac_XY app.app_databade.plot_handles.zoomInterac_XY];
            app.axes_cs_pca.Interactions            = [app.app_databade.plot_handles.panInterac_XY app.app_databade.plot_handles.zoomInterac_XY];
            set(app.axes_rawSignal,         'HitTest', 'off');
            set(app.axes_ss_wave,           'HitTest', 'off');
            set(app.axes_cs_wave,           'HitTest', 'off');
            set(app.axes_ss_isi,            'HitTest', 'off');
            set(app.axes_cs_isi,            'HitTest', 'off');
            set(app.axes_ss_corr,           'HitTest', 'off');
            set(app.axes_cs_corr,           'HitTest', 'off');
            set(app.axes_rawSignal_ss_dist, 'HitTest', 'off');
            set(app.axes_rawSignal_cs_dist, 'HitTest', 'off');
            set(app.axes_cs_pca,            'HitTest', 'off');
            set(app.axes_ss_pca,            'HitTest', 'off');
            axtoolbar(app.axes_rawSignal,         {'zoomin','restoreview','pan'});
            axtoolbar(app.axes_ss_wave,           {'zoomin','restoreview'});
            axtoolbar(app.axes_cs_wave,           {'zoomin','restoreview'});
            axtoolbar(app.axes_ss_isi,            {'zoomin','restoreview'});
            axtoolbar(app.axes_cs_isi,            {'zoomin','restoreview'});
            axtoolbar(app.axes_ss_corr,           {'zoomin','restoreview'});
            axtoolbar(app.axes_cs_corr,           {'zoomin','restoreview'});
            axtoolbar(app.axes_rawSignal_ss_dist, {'zoomin','restoreview'});
            axtoolbar(app.axes_rawSignal_cs_dist, {'zoomin','restoreview'});
            axtoolbar(app.axes_cs_pca,            {'zoomin','restoreview'});
            axtoolbar(app.axes_ss_pca,            {'zoomin','restoreview'});
            app.app_databade.sample_freq = 30e3; %Hz
            app.txtstatic_status_message.Text = 'Please load ''10#_CH#.mat'' file and then ''10#_CMN1.mat'' to begin spike sorting.';
            file_path = [pwd filesep];
            app.txtstatic_filepath.Text = file_path;
        end

        % Button pushed function: pushbtn_load_file
        function pushbtn_load_fileButtonPushed(app, event)
            file_path = app.txtstatic_filepath.Text;
            if ~strcmp(file_path(end), filesep)
                file_path = [file_path filesep];
            end
            [file_name,file_path] = uigetfile([file_path '*_CH*.mat' ';' '*_CH*.continuous'], 'Select _CH file');
            if ~strcmp(file_path(end), filesep)
                file_path = [file_path filesep];
            end
            if ~isequal(file_name,0)
                ESN_init_slot_data(app, 0);
            else
                app.txtstatic_status_message.Text = 'Please load ''10#_CH#.mat'' file and then ''10#_CMN1.mat'' to begin spike sorting.';
                figure(app.PurkinjeSortUIFigure);
                return;
            end
            app.txtstatic_filename.Text = file_name;
            app.txtstatic_filepath.Text = file_path;
            
            app.txtstatic_status_message.Text = ['Loading ' file_name ' ... '];
            app.lamp_status.Color = [1 0 0];
            figure(app.PurkinjeSortUIFigure);
            pause(0.050);
            [~,~,file_ext] = fileparts(file_name);
            if contains(file_ext, 'mat')
                app.app_databade.EPHYS.CH_1   = load([file_path file_name]);
            elseif contains(file_ext, 'continuous')
                [ch_data, ch_time, ch_info] = load_open_ephys_data([file_path file_name]);
                clearvars('CH_DATA');
                app.app_databade.EPHYS.CH_1.ch_data = ch_data;
                app.app_databade.EPHYS.CH_1.ch_time = ch_time;
                app.app_databade.EPHYS.CH_1.ch_info = ch_info;
            else
                app.txtstatic_status_message.Text = ['Error loading ' file_name ' ! '];
                return;
            end
            app.app_databade.EPHYS.time = app.app_databade.EPHYS.CH_1.ch_time(:);
            
            app.txtstatic_status_message.Text = ['Loading ' file_name ' ... ' '-> Completed.'];
            app.lamp_status.Color = [0 1 0];
            
            [file_name_cmn,file_path] = uigetfile([file_path '*_CMN1.mat' ';' '*_CH*.continuous'], 'Select _CMN1 mat file');
            if ~strcmp(file_path(end), filesep)
                file_path = [file_path filesep];
            end
            if ~isequal(file_name_cmn,0)
                app.txtstatic_status_message.Text = ['Loading ' file_name_cmn ' ... '];
                app.lamp_status.Color = [1 0 0];
                figure(app.PurkinjeSortUIFigure);
                pause(0.050);
                
                [~,~,file_ext_cmn] = fileparts(file_name_cmn);
                if contains(file_ext_cmn, 'mat')
                    app.app_databade.EPHYS.CH_CMN = load([file_path file_name_cmn]);
                elseif contains(file_ext_cmn, 'continuous')
                    [ch_data, ch_time, ch_info] = load_open_ephys_data([file_path file_name_cmn]);
                    clearvars('CH_DATA');
                    app.app_databade.EPHYS.CH_CMN.ch_data = ch_data;
                    app.app_databade.EPHYS.CH_CMN.ch_time = ch_time;
                    app.app_databade.EPHYS.CH_CMN.ch_info = ch_info;
                else
                    app.txtstatic_status_message.Text = ['Error loading ' file_name_cmn ' ! '];
                    return;
                end
                
                app.txtstatic_status_message.Text = ['Loading ' file_name_cmn ' ... ' '-> Completed.'];
                app.lamp_status.Color = [0 1 0];
            else
                app.app_databade.EPHYS.CH_CMN.ch_data = zeros(size(app.app_databade.EPHYS.CH_1.ch_data));
            end
            
            if sum(diff(app.app_databade.EPHYS.time) > 1.0) > 0
                % handling an error when 2 files have been concatenated to
                % each other. The code will find the point of separation
                % and just analyze the first piece of recording
                ind_time_end = find(diff(app.app_databade.EPHYS.time) > 1.0, 1, 'first');
                app.app_databade.EPHYS.time = app.app_databade.EPHYS.time(1:ind_time_end);
            end
            
            time_edge_interval = 60; %s
            app.app_databade.total_num_slots = ceil((app.app_databade.EPHYS.time(end)-app.app_databade.EPHYS.time(1)) / time_edge_interval);
            app.txtstatic_slot_numTotal.Text = num2str(app.app_databade.total_num_slots);
            % set the time slots to be time_edge_interval
            % app.app_databade.time_edges = [(app.app_databade.EPHYS.time(1) : time_edge_interval : app.app_databade.EPHYS.time(end))'; app.app_databade.EPHYS.time(end)];
            % set the time slots adaptively, the duration will be a number
            % between time_edge_interval/2 and time_edge_interval. This way
            % will gaurantee a smooth last time slot instead of a potential very
            % short last time slot
            app.app_databade.time_edges = linspace(app.app_databade.EPHYS.time(1), app.app_databade.EPHYS.time(end), (app.app_databade.total_num_slots+1) )';
            % to include the first data point of the first slot the first
            % time point should be less than the actual time point
            app.app_databade.time_edges(1) = app.app_databade.time_edges(1) - 1;
            
            app.app_databade.current_slot_num = 1;
            app.txtedit_slot_numCurrent.Value = app.app_databade.current_slot_num;
            app.txtedit_slot_numCurrent.Limits = [1 app.app_databade.total_num_slots];
            
            figure(app.PurkinjeSortUIFigure);
            pause(0.050);
            ESN_init_slot_data(app, app.app_databade.total_num_slots);
            ESN_evaluate_slot_data(app);
            ESN_save_slot_data(app);
        end

        % Button pushed function: pushbtn_save_file
        function pushbtn_save_fileButtonPushed(app, event)
            ESN_save_slot_data(app);
            save_file_name = app.txtstatic_filename.Text;
            save_file_path = app.txtstatic_filepath.Text;
            [~,save_file_name,~] = fileparts(save_file_name);
            save_file_name = [save_file_name '_sorted' '.mat'];
            [save_file_name,save_file_path] = uiputfile([save_file_path filesep save_file_name], 'Select where to save the _sorted mat-file.');
            if isequal(save_file_name,0)
                app.txtstatic_status_message.Text = 'Saving got cancelled.';
                figure(app.PurkinjeSortUIFigure);
                return;
            end
            app.txtstatic_status_message.Text = ['Saving ' save_file_name ' ... '];
            app.lamp_status.Color = [1 0 0];
            figure(app.PurkinjeSortUIFigure);
            pause(0.050);
            [~, save_file_name_fig, ~]  = fileparts(save_file_name);
            app.app_databade.save_file_name_fig = save_file_name_fig;
            [CH_data, CS_data, SS_data, Corr_data] = ESN_save_file_data(app);
            ESN_plot_save_file(app, SS_data, CS_data, Corr_data);
            
            saveas(app.app_databade.plot_handles.pop_up_fig_handle,[save_file_path filesep save_file_name_fig '_properties'], 'pdf');
            saveas(app.app_databade.plot_handles.pop_up_fig_handle,[save_file_path filesep save_file_name_fig '_properties'], 'png');
            close(app.app_databade.plot_handles.pop_up_fig_handle);
            
            figure(app.PurkinjeSortUIFigure);
            pause(0.050);
            save([save_file_path filesep save_file_name], 'CH_data','CS_data','SS_data','Corr_data','-v7.3');
            
            app.txtstatic_status_message.Text = ['Saving ' save_file_name ' ... ' '-> Completed.'];
            app.lamp_status.Color = [0 1 0];
        end

        % Button pushed function: pushbtn_slot_next
        function pushbtn_slot_nextButtonPushed(app, event)
            ESN_save_slot_data(app);
            app.app_databade.current_slot_num = app.app_databade.current_slot_num + 1;
            if app.app_databade.current_slot_num >= app.app_databade.total_num_slots
                app.app_databade.current_slot_num = app.app_databade.total_num_slots;
            end
            app.txtedit_slot_numCurrent.Value = app.app_databade.current_slot_num;
            if app.app_databade.slot_data.is_analyzed(   app.app_databade.current_slot_num, 1)
                ESN_extract_slot_data(app);
            else
                ESN_evaluate_slot_data(app);
            end
            ESN_save_slot_data(app);
        end

        % Button pushed function: pushbtn_slot_back
        function pushbtn_slot_backButtonPushed(app, event)
            ESN_save_slot_data(app);
            app.app_databade.current_slot_num = app.app_databade.current_slot_num - 1;
            if app.app_databade.current_slot_num <= 0
                app.app_databade.current_slot_num = 1;
            end
            app.txtedit_slot_numCurrent.Value = app.app_databade.current_slot_num;
            if app.app_databade.slot_data.is_analyzed(   app.app_databade.current_slot_num, 1)
                ESN_extract_slot_data(app);
            else
                ESN_evaluate_slot_data(app);
            end
            ESN_save_slot_data(app);
        end

        % Button pushed function: pushbtn_rawSignal_refresh
        function pushbtn_rawSignal_refreshButtonPushed(app, event)
            ESN_evaluate_slot_data(app);
        end

        % Button pushed function: pushbtn_cs_pca_select
        function pushbtn_cs_pca_selectButtonPushed(app, event)
            hold(app.axes_cs_wave, 'on');
            if isfield(app.app_databade.plot_handles, 'axes_cs_wave')
                if isfield(app.app_databade.plot_handles.axes_cs_wave, 'polygon')
                    delete(app.app_databade.plot_handles.axes_cs_wave.polygon);
                end
                if isfield(app.app_databade.plot_handles.axes_cs_wave, 'selected_waves')
                    delete(app.app_databade.plot_handles.axes_cs_wave.selected_waves);
                end
            end
            hold(app.axes_cs_pca, 'on');
            if isfield(app.app_databade.plot_handles, 'axes_cs_pca')
                if isfield(app.app_databade.plot_handles.axes_cs_pca, 'polygon')
                    delete(app.app_databade.plot_handles.axes_cs_pca.polygon);
                end
                if isfield(app.app_databade.plot_handles.axes_cs_pca, 'selected_points')
                    delete(app.app_databade.plot_handles.axes_cs_pca.selected_points);
                end
            end
            
            ESN_pop_up_figure(app)
            pop_up_axes_handle = axes;
            hold(pop_up_axes_handle, 'on');
            grid(pop_up_axes_handle,'on');
            cs_pca_1 = app.app_databade.current_slot_data.CS_pca_1;
            cs_pca_2 = app.app_databade.current_slot_data.CS_pca_2;
            plot_handle_ = plot(pop_up_axes_handle,  cs_pca_1,     cs_pca_2, '*r', 'LineWidth', 1);
            set(plot_handle_,'hittest','off');
            title('CS PCA');
            xlabel('PCA1');
            ylabel('PCA2');
            
            figure(app.app_databade.plot_handles.pop_up_fig_handle)
            num_points = 6;
            coordinates = nan(num_points+1, 2);
            pop_up_plot_handle = plot(pop_up_axes_handle , coordinates(:,1),    coordinates(:,2), 'o-', 'Color', 'g'); % CS
            for counter_points = 1 : 1 : num_points
                try
                    [x_, y_] = ginput(1);
                catch
                    figure(app.PurkinjeSortUIFigure);
                    return;
                end
                coordinates(counter_points, 1) = x_;
                coordinates(counter_points, 2) = y_;
                delete(pop_up_plot_handle);
                pop_up_plot_handle = plot(pop_up_axes_handle , coordinates(:,1),    coordinates(:,2), 'o-', 'Color', 'g'); % CS
            end
            coordinates(num_points+1, :) = coordinates(1, :);
            delete(pop_up_plot_handle);
            pop_up_plot_handle = plot(pop_up_axes_handle , coordinates(:,1),    coordinates(:,2), 'o-', 'Color', 'g'); % CS
            pause(0.100);
            delete(pop_up_plot_handle)
            close(app.app_databade.plot_handles.pop_up_fig_handle);
            
            is_inside_ = inpolygon(cs_pca_1, cs_pca_2, coordinates(:,1), coordinates(:,2));
            app.app_databade.current_slot_data.CS_ind_selected = is_inside_(:);
            
            ESN_plot_cs_wave(app);
            ESN_plot_cs_pca(app);
            ESN_plot_rawSignal(app);
            app.app_databade.plot_handles.axes_cs_pca.polygon = plot(app.axes_cs_pca , coordinates(:,1),    coordinates(:,2), '.-', 'Color', 'g'); % CS
            figure(app.PurkinjeSortUIFigure);
            app.lamp_status.Color = [0 1 0];
        end

        % Button pushed function: pushbtn_cs_wave_select
        function pushbtn_cs_wave_selectButtonPushed(app, event)
            hold(app.axes_cs_wave, 'on');
            if isfield(app.app_databade.plot_handles, 'axes_cs_wave')
                if isfield(app.app_databade.plot_handles.axes_cs_wave, 'polygon')
                    delete(app.app_databade.plot_handles.axes_cs_wave.polygon);
                end
                if isfield(app.app_databade.plot_handles.axes_cs_wave, 'selected_waves')
                    delete(app.app_databade.plot_handles.axes_cs_wave.selected_waves);
                end
            end
            hold(app.axes_cs_pca, 'on');
            if isfield(app.app_databade.plot_handles, 'axes_cs_pca')
                if isfield(app.app_databade.plot_handles.axes_cs_pca, 'polygon')
                    delete(app.app_databade.plot_handles.axes_cs_pca.polygon);
                end
                if isfield(app.app_databade.plot_handles.axes_cs_pca, 'selected_points')
                    delete(app.app_databade.plot_handles.axes_cs_pca.selected_points);
                end
            end
            
            ESN_pop_up_figure(app)
            pop_up_axes_handle = axes;
            hold(pop_up_axes_handle, 'on');
            grid(pop_up_axes_handle,'on');
            cs_wave_y_axis = app.app_databade.current_slot_data.CS_waveform';
            if (~isempty(cs_wave_y_axis))
                cs_wave_y_axis_ = cs_wave_y_axis;
                cs_wave_x_axis_ = repmat((1:1:size(cs_wave_y_axis_, 1))', 1, size(cs_wave_y_axis_, 2)) ./ (app.app_databade.sample_freq/1000);
                cs_wave_y_axis_(end+1, :) = nan(1, size(cs_wave_y_axis_, 2));
                cs_wave_x_axis_(end+1, :) = nan(1, size(cs_wave_x_axis_, 2));
                cs_wave_x_axis = (1 : 1 : size(cs_wave_y_axis, 1))' ./ (app.app_databade.sample_freq/1000);
            else
                cs_wave_y_axis_ = nan(size(cs_wave_y_axis));
                cs_wave_x_axis_ = nan(size(cs_wave_y_axis));
                cs_wave_x_axis  = nan(size(cs_wave_y_axis));
            end
            plot_handle_ = plot(pop_up_axes_handle, cs_wave_x_axis_(:), cs_wave_y_axis_(:), '-r');
            set(plot_handle_,'hittest','off');
            title('CS Waveform');
            xlabel('Time (ms)');
            ylabel('Amplitude (uV)');
            
            
            figure(app.app_databade.plot_handles.pop_up_fig_handle);
            num_points = 6;
            coordinates = nan(num_points+1, 2);
            pop_up_plot_handle = plot(pop_up_axes_handle , coordinates(:,1),    coordinates(:,2), 'o-', 'Color', 'g'); % CS
            for counter_points = 1 : 1 : num_points
                try
                    [x_, y_] = ginput(1);
                catch
                    figure(app.PurkinjeSortUIFigure);
                    return;
                end
                coordinates(counter_points, 1) = x_;
                coordinates(counter_points, 2) = y_;
                delete(pop_up_plot_handle);
                pop_up_plot_handle = plot(pop_up_axes_handle , coordinates(:,1),    coordinates(:,2), 'o-', 'Color', 'g'); % CS
            end
            coordinates(num_points+1, :) = coordinates(1, :);
            delete(pop_up_plot_handle);
            pop_up_plot_handle = plot(pop_up_axes_handle , coordinates(:,1),    coordinates(:,2), 'o-', 'Color', 'g'); % CS
            pause(0.100);
            delete(pop_up_plot_handle);
            close(app.app_databade.plot_handles.pop_up_fig_handle);
            
            num_cs = size(cs_wave_y_axis, 2);
            is_inside_ = true(num_cs, 1);
            for counter_cs_wave = 1 : 1 : num_cs
                is_inside_wave_ = inpolygon(cs_wave_x_axis,cs_wave_y_axis(:,counter_cs_wave),...
                    coordinates(:,1), coordinates(:,2));
                is_inside_(counter_cs_wave) = sum(is_inside_wave_) > 0;
            end
            app.app_databade.current_slot_data.CS_ind_selected = is_inside_(:);
            
            ESN_plot_cs_wave(app);
            ESN_plot_cs_pca(app);
            ESN_plot_rawSignal(app);
            app.app_databade.plot_handles.axes_cs_wave.polygon = plot(app.axes_cs_wave , coordinates(:,1),    coordinates(:,2), '.-', 'Color', 'g'); % CS
            figure(app.PurkinjeSortUIFigure);
            app.lamp_status.Color = [0 1 0];
        end

        % Button pushed function: pushbtn_ss_wave_select
        function pushbtn_ss_wave_selectButtonPushed(app, event)
            hold(app.axes_ss_wave, 'on');
            if isfield(app.app_databade.plot_handles, 'axes_ss_wave')
                if isfield(app.app_databade.plot_handles.axes_ss_wave, 'polygon')
                    delete(app.app_databade.plot_handles.axes_ss_wave.polygon);
                end
                if isfield(app.app_databade.plot_handles.axes_ss_wave, 'selected_waves')
                    delete(app.app_databade.plot_handles.axes_ss_wave.selected_waves);
                end
            end
            hold(app.axes_ss_pca, 'on');
            if isfield(app.app_databade.plot_handles, 'axes_ss_pca')
                if isfield(app.app_databade.plot_handles.axes_ss_pca, 'polygon')
                    delete(app.app_databade.plot_handles.axes_ss_pca.polygon);
                end
                if isfield(app.app_databade.plot_handles.axes_ss_pca, 'selected_points')
                    delete(app.app_databade.plot_handles.axes_ss_pca.selected_points);
                end
            end
            
            ESN_pop_up_figure(app)
            pop_up_axes_handle = axes;
            hold(pop_up_axes_handle, 'on');
            grid(pop_up_axes_handle,'on');
            ss_wave_y_axis = app.app_databade.current_slot_data.SS_waveform';
            if (~isempty(ss_wave_y_axis))
                ss_wave_y_axis_ = ss_wave_y_axis;
                ss_wave_x_axis_ = repmat((1:1:size(ss_wave_y_axis_, 1))', 1, size(ss_wave_y_axis_, 2)) ./ (app.app_databade.sample_freq/1000);
                ss_wave_y_axis_(end+1, :) = nan(1, size(ss_wave_y_axis_, 2));
                ss_wave_x_axis_(end+1, :) = nan(1, size(ss_wave_x_axis_, 2));
                ss_wave_x_axis = (1 : 1 : size(ss_wave_y_axis, 1))' ./ (app.app_databade.sample_freq/1000);
            else
                ss_wave_y_axis_ = nan(size(ss_wave_y_axis));
                ss_wave_x_axis_ = nan(size(ss_wave_y_axis));
                ss_wave_x_axis  = nan(size(ss_wave_y_axis));
            end
            plot_handle_ = plot(pop_up_axes_handle, ss_wave_x_axis_(:), ss_wave_y_axis_(:), '-b');
            set(plot_handle_,'hittest','off');
            title('SS Waveform');
            xlabel('Time (ms)');
            ylabel('Amplitude (uV)');
            
            
            figure(app.app_databade.plot_handles.pop_up_fig_handle);
            num_points = 6;
            coordinates = nan(num_points+1, 2);
            pop_up_plot_handle = plot(pop_up_axes_handle , coordinates(:,1),    coordinates(:,2), 'o-', 'Color', 'g'); % SS
            for counter_points = 1 : 1 : num_points
                try
                    [x_, y_] = ginput(1);
                catch
                    figure(app.PurkinjeSortUIFigure);
                    return;
                end
                coordinates(counter_points, 1) = x_;
                coordinates(counter_points, 2) = y_;
                delete(pop_up_plot_handle);
                pop_up_plot_handle = plot(pop_up_axes_handle , coordinates(:,1),    coordinates(:,2), 'o-', 'Color', 'g'); % SS
            end
            coordinates(num_points+1, :) = coordinates(1, :);
            delete(pop_up_plot_handle);
            pop_up_plot_handle = plot(pop_up_axes_handle , coordinates(:,1),    coordinates(:,2), 'o-', 'Color', 'g'); % SS
            pause(0.100);
            delete(pop_up_plot_handle)
            close(app.app_databade.plot_handles.pop_up_fig_handle);
            
            num_ss = size(ss_wave_y_axis, 2);
            is_inside_ = true(num_ss, 1);
            for counter_ss_wave = 1 : 1 : num_ss
                is_inside_wave_ = inpolygon(ss_wave_x_axis,ss_wave_y_axis(:,counter_ss_wave),...
                    coordinates(:,1), coordinates(:,2));
                is_inside_(counter_ss_wave) = sum(is_inside_wave_) > 0;
            end
            app.app_databade.current_slot_data.SS_ind_selected = is_inside_(:);
            
            ESN_plot_ss_wave(app);
            ESN_plot_ss_pca(app);
            ESN_plot_rawSignal(app);
            app.app_databade.plot_handles.axes_ss_wave.polygon = plot(app.axes_ss_wave , coordinates(:,1),    coordinates(:,2), '.-', 'Color', 'g'); % SS
            figure(app.PurkinjeSortUIFigure);
            app.lamp_status.Color = [0 1 0];
        end

        % Button pushed function: pushbtn_ss_pca_select
        function pushbtn_ss_pca_selectButtonPushed(app, event)
            hold(app.axes_ss_wave, 'on');
            if isfield(app.app_databade.plot_handles, 'axes_ss_wave')
                if isfield(app.app_databade.plot_handles.axes_ss_wave, 'polygon')
                    delete(app.app_databade.plot_handles.axes_ss_wave.polygon);
                end
                if isfield(app.app_databade.plot_handles.axes_ss_wave, 'selected_waves')
                    delete(app.app_databade.plot_handles.axes_ss_wave.selected_waves);
                end
            end
            hold(app.axes_ss_pca, 'on');
            if isfield(app.app_databade.plot_handles, 'axes_ss_pca')
                if isfield(app.app_databade.plot_handles.axes_ss_pca, 'polygon')
                    delete(app.app_databade.plot_handles.axes_ss_pca.polygon);
                end
                if isfield(app.app_databade.plot_handles.axes_ss_pca, 'selected_points')
                    delete(app.app_databade.plot_handles.axes_ss_pca.selected_points);
                end
            end
            
            ESN_pop_up_figure(app)
            pop_up_axes_handle = axes;
            hold(pop_up_axes_handle, 'on');
            grid(pop_up_axes_handle,'on');
            ss_pca_1 = app.app_databade.current_slot_data.SS_pca_1;
            ss_pca_2 = app.app_databade.current_slot_data.SS_pca_2;
            plot_handle_ = plot(pop_up_axes_handle,  ss_pca_1,     ss_pca_2, '+b', 'LineWidth', 0.5);
            set(plot_handle_,'hittest','off');
            title('SS PCA');
            xlabel('PCA1');
            ylabel('PCA2');
            
            figure(app.app_databade.plot_handles.pop_up_fig_handle)
            num_points = 6;
            coordinates = nan(num_points+1, 2);
            pop_up_plot_handle = plot(pop_up_axes_handle , coordinates(:,1),    coordinates(:,2), 'o-', 'Color', 'g'); % SS
            for counter_points = 1 : 1 : num_points
                try
                    [x_, y_] = ginput(1);
                catch
                    figure(app.PurkinjeSortUIFigure);
                    return;
                end
                coordinates(counter_points, 1) = x_;
                coordinates(counter_points, 2) = y_;
                delete(pop_up_plot_handle);
                pop_up_plot_handle = plot(pop_up_axes_handle , coordinates(:,1),    coordinates(:,2), 'o-', 'Color', 'g'); % SS
            end
            coordinates(num_points+1, :) = coordinates(1, :);
            delete(pop_up_plot_handle);
            pop_up_plot_handle = plot(pop_up_axes_handle , coordinates(:,1),    coordinates(:,2), 'o-', 'Color', 'g'); % SS
            pause(0.100);
            delete(pop_up_plot_handle)
            close(app.app_databade.plot_handles.pop_up_fig_handle);
            
            is_inside_ = inpolygon(ss_pca_1, ss_pca_2, coordinates(:,1), coordinates(:,2));
            app.app_databade.current_slot_data.SS_ind_selected = is_inside_(:);
            
            ESN_plot_ss_wave(app);
            ESN_plot_ss_pca(app);
            ESN_plot_rawSignal(app);
            app.app_databade.plot_handles.axes_ss_pca.polygon = plot(app.axes_ss_pca , coordinates(:,1),    coordinates(:,2), '.-', 'Color', 'g'); % SS
            figure(app.PurkinjeSortUIFigure);
            app.lamp_status.Color = [0 1 0];
        end

        % Button pushed function: pushbtn_cs_delete
        function pushbtn_cs_deleteButtonPushed(app, event)
            CS_ind_selected = app.app_databade.current_slot_data.CS_ind_selected;
            if (sum(CS_ind_selected)>0)
                ESN_keep_cs(app, ~CS_ind_selected);
                ESN_evaluate_corr_and_pca(app);
                ESN_plot_slot_data(app);
            end
        end

        % Button pushed function: pushbtn_cs_keep
        function pushbtn_cs_keepButtonPushed(app, event)
            CS_ind_selected = app.app_databade.current_slot_data.CS_ind_selected;
            if (sum(CS_ind_selected)>0)
                ESN_keep_cs(app, CS_ind_selected);
                ESN_evaluate_corr_and_pca(app);
                ESN_plot_slot_data(app);
            end
        end

        % Button pushed function: pushbtn_ss_delete
        function pushbtn_ss_deleteButtonPushed(app, event)
            SS_ind_selected = app.app_databade.current_slot_data.SS_ind_selected;
            if (sum(SS_ind_selected)>0)
                ESN_keep_ss(app, ~SS_ind_selected);
                ESN_evaluate_corr_and_pca(app);
                ESN_plot_slot_data(app);
            end
        end

        % Button pushed function: pushbtn_ss_keep
        function pushbtn_ss_keepButtonPushed(app, event)
            SS_ind_selected = app.app_databade.current_slot_data.SS_ind_selected;
            if (sum(SS_ind_selected)>0)
                ESN_keep_ss(app, SS_ind_selected);
                ESN_evaluate_corr_and_pca(app);
                ESN_plot_slot_data(app);
            end
        end

        % Button pushed function: pushbtn_cs_move
        function pushbtn_cs_moveButtonPushed(app, event)
            CS_ind_selected = app.app_databade.current_slot_data.CS_ind_selected;
            if (sum(CS_ind_selected)>0)
                ESN_move_from_CS_to_SS(app, CS_ind_selected);
                ESN_evaluate_corr_and_pca(app);
                ESN_plot_slot_data(app);
            end
        end

        % Button pushed function: pushbtn_ss_move
        function pushbtn_ss_moveButtonPushed(app, event)
            SS_ind_selected = app.app_databade.current_slot_data.SS_ind_selected;
            if (sum(SS_ind_selected)>0)
                ESN_move_from_SS_to_CS(app, SS_ind_selected);
                ESN_evaluate_corr_and_pca(app);
                ESN_plot_slot_data(app);
            end
        end

        % Value changed function: txtedit_slot_numCurrent
        function txtedit_slot_numCurrentValueChanged(app, event)
            ESN_save_slot_data(app);
            app.app_databade.current_slot_num = round(app.txtedit_slot_numCurrent.Value);
            if app.app_databade.current_slot_num >= app.app_databade.total_num_slots
                app.app_databade.current_slot_num = app.app_databade.total_num_slots;
            end
            if app.app_databade.current_slot_num < 1
                app.app_databade.current_slot_num = 1;
            end
            app.txtedit_slot_numCurrent.Value = app.app_databade.current_slot_num;
            if app.app_databade.slot_data.is_analyzed(   app.app_databade.current_slot_num, 1)
                ESN_extract_slot_data(app);
            else
                ESN_evaluate_slot_data(app);
            end
            ESN_save_slot_data(app);
        end

        % Button pushed function: pushbtn_cs_pca_window
        function pushbtn_cs_pca_windowButtonPushed(app, event)
            ESN_pop_up_figure(app)
            pop_up_axes_handle = axes;
            hold(pop_up_axes_handle, 'on');
            grid(pop_up_axes_handle,'on');
            cs_wave_y_axis = app.app_databade.current_slot_data.CS_waveform';
            if (~isempty(cs_wave_y_axis))
                cs_wave_y_axis_ = cs_wave_y_axis;
                cs_wave_x_axis_ = repmat((1:1:size(cs_wave_y_axis_, 1))', 1, size(cs_wave_y_axis_, 2)) ./ (app.app_databade.sample_freq/1000);
                cs_wave_y_axis_(end+1, :) = nan(1, size(cs_wave_y_axis_, 2));
                cs_wave_x_axis_(end+1, :) = nan(1, size(cs_wave_x_axis_, 2));
            else
                cs_wave_y_axis_ = nan(size(cs_wave_y_axis));
                cs_wave_x_axis_ = nan(size(cs_wave_y_axis));
            end
            plot_handle_ = plot(pop_up_axes_handle, cs_wave_x_axis_(:), cs_wave_y_axis_(:), '-r');
            set(plot_handle_,'hittest','off');
            title('CS Waveform');
            xlabel('Time (ms)');
            ylabel('Amplitude (uV)');
            
            
            figure(app.app_databade.plot_handles.pop_up_fig_handle);
            num_points = 2;
            coordinates = nan(num_points, 2);
            for counter_points = 1 : 1 : num_points
                try
                    [x_, y_] = ginput(1);
                catch
                    figure(app.PurkinjeSortUIFigure);
                    return;
                end
                coordinates(counter_points, 1) = x_;
                coordinates(counter_points, 2) = y_;
                plot(pop_up_axes_handle , x_,    y_, 'o', 'Color', 'g'); % CS
                plot(pop_up_axes_handle , [x_ x_],    pop_up_axes_handle.YLim, '-', 'Color', [0.5 0.1 0.1], 'LineWidth', 1.5); % CS
            end
            pause(0.100);
            close(app.app_databade.plot_handles.pop_up_fig_handle);
            
            app.app_databade.pca_window_cs_ind_min = round(min(coordinates(:,1)) .* (app.app_databade.sample_freq/1000));
            app.app_databade.pca_window_cs_ind_max = round(max(coordinates(:,1)) .* (app.app_databade.sample_freq/1000));
            if     app.app_databade.pca_window_cs_ind_min < 1
                   app.app_databade.pca_window_cs_ind_min = 1;
            elseif app.app_databade.pca_window_cs_ind_min > size(cs_wave_y_axis, 1)
                   app.app_databade.pca_window_cs_ind_min = size(cs_wave_y_axis, 1);
            end
            if     app.app_databade.pca_window_cs_ind_max < 1
                   app.app_databade.pca_window_cs_ind_max = 1;
            elseif app.app_databade.pca_window_cs_ind_max > size(cs_wave_y_axis, 1)
                   app.app_databade.pca_window_cs_ind_max = size(cs_wave_y_axis, 1);
            end
            ESN_evaluate_corr_and_pca(app);
            ESN_plot_slot_data(app);
        end

        % Button pushed function: pushbtn_ss_pca_window
        function pushbtn_ss_pca_windowButtonPushed(app, event)
            ESN_pop_up_figure(app)
            pop_up_axes_handle = axes;
            hold(pop_up_axes_handle, 'on');
            grid(pop_up_axes_handle,'on');
            ss_wave_y_axis = app.app_databade.current_slot_data.SS_waveform';
            if (~isempty(ss_wave_y_axis))
                ss_wave_y_axis_ = ss_wave_y_axis;
                ss_wave_x_axis_ = repmat((1:1:size(ss_wave_y_axis_, 1))', 1, size(ss_wave_y_axis_, 2)) ./ (app.app_databade.sample_freq/1000);
                ss_wave_y_axis_(end+1, :) = nan(1, size(ss_wave_y_axis_, 2));
                ss_wave_x_axis_(end+1, :) = nan(1, size(ss_wave_x_axis_, 2));
            else
                ss_wave_y_axis_ = nan(size(ss_wave_y_axis));
                ss_wave_x_axis_ = nan(size(ss_wave_y_axis));
            end
            plot_handle_ = plot(pop_up_axes_handle, ss_wave_x_axis_(:), ss_wave_y_axis_(:), '-b');
            set(plot_handle_,'hittest','off');
            title('SS Waveform');
            xlabel('Time (ms)');
            ylabel('Amplitude (uV)');
            
            
            figure(app.app_databade.plot_handles.pop_up_fig_handle);
            num_points = 2;
            coordinates = nan(num_points, 2);
            for counter_points = 1 : 1 : num_points
                try
                    [x_, y_] = ginput(1);
                catch
                    figure(app.PurkinjeSortUIFigure);
                    return;
                end
                coordinates(counter_points, 1) = x_;
                coordinates(counter_points, 2) = y_;
                plot(pop_up_axes_handle , x_,    y_, 'o', 'Color', 'g'); % SS
                plot(pop_up_axes_handle , [x_ x_],    pop_up_axes_handle.YLim, '-', 'Color', [0.1 0.1 0.5], 'LineWidth', 1.5); % SS
            end
            pause(0.100);
            close(app.app_databade.plot_handles.pop_up_fig_handle);
            
            app.app_databade.pca_window_ss_ind_min = round(min(coordinates(:,1)) .* (app.app_databade.sample_freq/1000));
            app.app_databade.pca_window_ss_ind_max = round(max(coordinates(:,1)) .* (app.app_databade.sample_freq/1000));
            if     app.app_databade.pca_window_ss_ind_min < 1
                   app.app_databade.pca_window_ss_ind_min = 1;
            elseif app.app_databade.pca_window_ss_ind_min > size(ss_wave_y_axis, 1)
                   app.app_databade.pca_window_ss_ind_min = size(ss_wave_y_axis, 1);
            end
            if     app.app_databade.pca_window_ss_ind_max < 1
                   app.app_databade.pca_window_ss_ind_max = 1;
            elseif app.app_databade.pca_window_ss_ind_max > size(ss_wave_y_axis, 1)
                   app.app_databade.pca_window_ss_ind_max = size(ss_wave_y_axis, 1);
            end
            ESN_evaluate_corr_and_pca(app);
            ESN_plot_slot_data(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PurkinjeSortUIFigure = uifigure('Visible', 'off');
            app.PurkinjeSortUIFigure.Name = 'PurkinjeSort';
            app.grid_main = uigridlayout(app.PurkinjeSortUIFigure);
            app.panel_status = uipanel(app.grid_main);
            app.grid_status = uigridlayout(app.panel_status);
            app.txtstatic_status_label = uilabel(app.grid_status);
            app.txtstatic_status_label.Text = 'Status:';
            app.lamp_status = uilamp(app.grid_status);
            app.txtstatic_status_message = uilabel(app.grid_status);
            app.txtstatic_status_message.Text = 'Message';
            app.txtstatic_status_ssFiring = uilabel(app.grid_status);
            app.txtstatic_status_ssFiring.Text = 'SS Firing: 0.0 Hz.';
            app.txtstatic_status_csFiring = uilabel(app.grid_status);
            app.txtstatic_status_csFiring.Text = 'CS Firing: 0.0 Hz.';
            app.grid_file_slot = uigridlayout(app.grid_main);
            app.panel_load_save = uipanel(app.grid_file_slot);
            app.grid_load_save = uigridlayout(app.panel_load_save);
            app.pushbtn_load_file = uibutton(app.grid_load_save, 'push');
            app.pushbtn_load_file.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_load_fileButtonPushed, true);
            app.pushbtn_load_file.Text = 'Load File';
            app.pushbtn_save_file = uibutton(app.grid_load_save, 'push');
            app.pushbtn_save_file.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_save_fileButtonPushed, true);
            app.pushbtn_save_file.Text = 'Save File';
            app.panel_file_name_path = uipanel(app.grid_file_slot);
            app.grid_file_name_path = uigridlayout(app.panel_file_name_path);
            app.txtstatic_filepath = uilabel(app.grid_file_name_path);
            app.txtstatic_filepath.Text = 'File Path';
            app.txtstatic_filename = uilabel(app.grid_file_name_path);
            app.txtstatic_filename.Text = 'File Name';
            app.panel_slot = uipanel(app.grid_file_slot);
            app.grid_slot = uigridlayout(app.panel_slot);
            app.grid_slot_back_next = uigridlayout(app.grid_slot);
            app.pushbtn_slot_back = uibutton(app.grid_slot_back_next, 'push');
            app.pushbtn_slot_back.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_slot_backButtonPushed, true);
            app.pushbtn_slot_back.Text = '<< Previous';
            app.pushbtn_slot_next = uibutton(app.grid_slot_back_next, 'push');
            app.pushbtn_slot_next.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_slot_nextButtonPushed, true);
            app.pushbtn_slot_next.Text = 'Next >>';
            app.grid_slot_num2 = uigridlayout(app.grid_slot);
            app.txtstatic_slot_label1 = uilabel(app.grid_slot_num2);
            app.txtstatic_slot_label1.Text = 'Slot#';
            app.txtedit_slot_numCurrent = uieditfield(app.grid_slot_num2, 'numeric');
            app.txtedit_slot_numCurrent.Limits = [1 200];
            app.txtedit_slot_numCurrent.RoundFractionalValues = 'on';
            app.txtedit_slot_numCurrent.ValueDisplayFormat = '%.0f';
            app.txtedit_slot_numCurrent.ValueChangedFcn = createCallbackFcn(app, @txtedit_slot_numCurrentValueChanged, true);
            app.txtedit_slot_numCurrent.Value = 1;
            app.txtstatic_slot_numTotal = uilabel(app.grid_slot_num2);
            app.txtstatic_slot_numTotal.Text = '50 (0)';
            app.txtstatic_slot_label2 = uilabel(app.grid_slot_num2);
            app.txtstatic_slot_label2.Text = '/';
            app.grid_signal = uigridlayout(app.grid_main);
            app.panel_flip = uipanel(app.grid_signal);
            app.grid_flip = uigridlayout(app.panel_flip);
            app.chkbox_neg_ss_hipass = uicheckbox(app.grid_flip);
            app.chkbox_neg_ss_hipass.Text = 'Neg(-) SS HiPass';
            app.chkbox_neg_ss_hipass.Value = true;
            app.chkbox_neg_cs_hipass = uicheckbox(app.grid_flip);
            app.chkbox_neg_cs_hipass.Text = 'Neg(-) CS HiPass';
            app.chkbox_neg_cs_hipass.Value = true;
            app.chkbox_pos_cs_lopass = uicheckbox(app.grid_flip);
            app.chkbox_pos_cs_lopass.Text = 'Pos(+) CS LoPass';
            app.chkbox_pos_cs_lopass.Value = true;
            app.txtstatic_lopass_cutoff = uilabel(app.grid_flip);
            app.txtstatic_lopass_cutoff.Text = 'LoPass Filter (Hz):';
            app.txtedit_lopass_cutoff_max = uieditfield(app.grid_flip, 'numeric');
            app.txtedit_lopass_cutoff_max.Limits = [1 15000];
            app.txtedit_lopass_cutoff_max.RoundFractionalValues = 'on';
            app.txtedit_lopass_cutoff_max.ValueDisplayFormat = '%.0f';
            app.txtedit_lopass_cutoff_max.Value = 150;
            app.txtstatic_hipass_cutoff = uilabel(app.grid_flip);
            app.txtstatic_hipass_cutoff.Text = 'HiPass Filter (Hz):';
            app.txtedit_hipass_cutoff_min = uieditfield(app.grid_flip, 'numeric');
            app.txtedit_hipass_cutoff_min.Limits = [1 15000];
            app.txtedit_hipass_cutoff_min.RoundFractionalValues = 'on';
            app.txtedit_hipass_cutoff_min.ValueDisplayFormat = '%.0f';
            app.txtedit_hipass_cutoff_min.Value = 150;
            app.txtstatic_lopass_cutoff_dash = uilabel(app.grid_flip);
            app.txtstatic_lopass_cutoff_dash.Text = '-';
            app.txtstatic_hipass_cutoff_dash = uilabel(app.grid_flip);
            app.txtstatic_hipass_cutoff_dash.Text = '-';
            app.txtedit_lopass_cutoff_min = uieditfield(app.grid_flip, 'numeric');
            app.txtedit_lopass_cutoff_min.Limits = [1 15000];
            app.txtedit_lopass_cutoff_min.RoundFractionalValues = 'on';
            app.txtedit_lopass_cutoff_min.ValueDisplayFormat = '%.0f';
            app.txtedit_lopass_cutoff_min.Value = 10;
            app.txtedit_hipass_cutoff_max = uieditfield(app.grid_flip, 'numeric');
            app.txtedit_hipass_cutoff_max.Limits = [1 15000];
            app.txtedit_hipass_cutoff_max.RoundFractionalValues = 'on';
            app.txtedit_hipass_cutoff_max.ValueDisplayFormat = '%.0f';
            app.txtedit_hipass_cutoff_max.Value = 3000;
            app.grid_raw_thresh = uigridlayout(app.grid_signal);
            app.panel_raw = uipanel(app.grid_raw_thresh);
            app.grid_raw = uigridlayout(app.panel_raw);
            app.axes_rawSignal = uiaxes(app.grid_raw);
            title(app.axes_rawSignal, 'Raw Signal')
            xlabel(app.axes_rawSignal, 'Time (s)')
            ylabel(app.axes_rawSignal, 'Amp (uV)')
            app.axes_rawSignal_ss_dist = uiaxes(app.grid_raw);
            title(app.axes_rawSignal_ss_dist, 'SS Peak Dist.')
            xlabel(app.axes_rawSignal_ss_dist, 'Count')
            ylabel(app.axes_rawSignal_ss_dist, 'Amp (uV)')
            app.axes_rawSignal_cs_dist = uiaxes(app.grid_raw);
            title(app.axes_rawSignal_cs_dist, 'CS Peak Dist.')
            xlabel(app.axes_rawSignal_cs_dist, 'Count')
            ylabel(app.axes_rawSignal_cs_dist, 'Amp (uV)')
            app.panel_thresh = uipanel(app.grid_raw_thresh);
            app.grid_thresh = uigridlayout(app.panel_thresh);
            app.pushbtn_rawSignal_refresh = uibutton(app.grid_thresh, 'push');
            app.pushbtn_rawSignal_refresh.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_rawSignal_refreshButtonPushed, true);
            app.pushbtn_rawSignal_refresh.Text = 'Re-Evaluate';
            app.txtstatic_cs_threshold = uilabel(app.grid_thresh);
            app.txtstatic_cs_threshold.Text = 'CS LoPass Thresh';
            app.txtedit_cs_threshold = uieditfield(app.grid_thresh, 'numeric');
            app.txtedit_cs_threshold.Limits = [10 1000];
            app.txtedit_cs_threshold.RoundFractionalValues = 'on';
            app.txtedit_cs_threshold.ValueDisplayFormat = '%.0f';
            app.txtedit_cs_threshold.Value = 100;
            app.txtstatic_ss_threshold = uilabel(app.grid_thresh);
            app.txtstatic_ss_threshold.Text = 'SS HiPass Thresh';
            app.txtedit_ss_threshold = uieditfield(app.grid_thresh, 'numeric');
            app.txtedit_ss_threshold.Limits = [10 1000];
            app.txtedit_ss_threshold.RoundFractionalValues = 'on';
            app.txtedit_ss_threshold.ValueDisplayFormat = '%.0f';
            app.txtedit_ss_threshold.Value = 150;
            app.grid_ss_cs = uigridlayout(app.grid_signal);
            app.panel_ss = uipanel(app.grid_ss_cs);
            app.panel_ss.Title = 'Simple Spike';
            app.grid_ss = uigridlayout(app.panel_ss);
            app.grid_ss_axes = uigridlayout(app.grid_ss);
            app.grid_ss_wave = uigridlayout(app.grid_ss_axes);
            app.axes_ss_wave = uiaxes(app.grid_ss_wave);
            title(app.axes_ss_wave, 'SS Waveform')
            xlabel(app.axes_ss_wave, 'Time (ms)')
            ylabel(app.axes_ss_wave, 'Amp (uV)')
            app.pushbtn_ss_wave_select = uibutton(app.grid_ss_wave, 'push');
            app.pushbtn_ss_wave_select.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_ss_wave_selectButtonPushed, true);
            app.pushbtn_ss_wave_select.Text = 'Select Waveform';
            app.grid_ss_pca = uigridlayout(app.grid_ss_axes);
            app.axes_ss_pca = uiaxes(app.grid_ss_pca);
            title(app.axes_ss_pca, 'SS PCA')
            xlabel(app.axes_ss_pca, 'PCA1')
            ylabel(app.axes_ss_pca, 'PCA2')
            app.grid_ss_pca_pushbtn = uigridlayout(app.grid_ss_pca);
            app.pushbtn_ss_pca_select = uibutton(app.grid_ss_pca_pushbtn, 'push');
            app.pushbtn_ss_pca_select.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_ss_pca_selectButtonPushed, true);
            app.pushbtn_ss_pca_select.Text = 'Select PCA';
            app.pushbtn_ss_pca_window = uibutton(app.grid_ss_pca_pushbtn, 'push');
            app.pushbtn_ss_pca_window.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_ss_pca_windowButtonPushed, true);
            app.pushbtn_ss_pca_window.Text = 'PCA Window';
            app.axes_ss_corr = uiaxes(app.grid_ss_axes);
            title(app.axes_ss_corr, 'SSxSS Corr')
            xlabel(app.axes_ss_corr, 'Time (ms)')
            ylabel(app.axes_ss_corr, 'Prob')
            app.axes_ss_isi = uiaxes(app.grid_ss_axes);
            title(app.axes_ss_isi, 'SS ISI')
            xlabel(app.axes_ss_isi, 'Time (ms)')
            ylabel(app.axes_ss_isi, 'Count')
            app.grid_ss_pushbtn = uigridlayout(app.grid_ss);
            app.pushbtn_ss_move = uibutton(app.grid_ss_pushbtn, 'push');
            app.pushbtn_ss_move.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_ss_moveButtonPushed, true);
            app.pushbtn_ss_move.Text = 'Move to CS';
            app.pushbtn_ss_keep = uibutton(app.grid_ss_pushbtn, 'push');
            app.pushbtn_ss_keep.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_ss_keepButtonPushed, true);
            app.pushbtn_ss_keep.Text = 'Keep';
            app.pushbtn_ss_delete = uibutton(app.grid_ss_pushbtn, 'push');
            app.pushbtn_ss_delete.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_ss_deleteButtonPushed, true);
            app.pushbtn_ss_delete.Text = 'Delete';
            app.panel_cs = uipanel(app.grid_ss_cs);
            app.panel_cs.Title = 'Complex Spike';
            app.grid_cs = uigridlayout(app.panel_cs);
            app.grid_cs_pushbtn = uigridlayout(app.grid_cs);
            app.pushbtn_cs_move = uibutton(app.grid_cs_pushbtn, 'push');
            app.pushbtn_cs_move.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_cs_moveButtonPushed, true);
            app.pushbtn_cs_move.Text = 'Move to SS';
            app.pushbtn_cs_keep = uibutton(app.grid_cs_pushbtn, 'push');
            app.pushbtn_cs_keep.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_cs_keepButtonPushed, true);
            app.pushbtn_cs_keep.Text = 'Keep';
            app.pushbtn_cs_delete = uibutton(app.grid_cs_pushbtn, 'push');
            app.pushbtn_cs_delete.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_cs_deleteButtonPushed, true);
            app.pushbtn_cs_delete.Text = 'Delete';
            app.grid_cs_axes = uigridlayout(app.grid_cs);
            app.grid_cs_wave = uigridlayout(app.grid_cs_axes);
            app.axes_cs_wave = uiaxes(app.grid_cs_wave);
            title(app.axes_cs_wave, 'CS Waveform')
            xlabel(app.axes_cs_wave, 'Time (ms)')
            ylabel(app.axes_cs_wave, 'Amp (uV)')
            app.pushbtn_cs_wave_select = uibutton(app.grid_cs_wave, 'push');
            app.pushbtn_cs_wave_select.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_cs_wave_selectButtonPushed, true);
            app.pushbtn_cs_wave_select.Text = 'Select Waveform';
            app.grid_cs_pca = uigridlayout(app.grid_cs_axes);
            app.axes_cs_pca = uiaxes(app.grid_cs_pca);
            title(app.axes_cs_pca, 'CS PCA')
            xlabel(app.axes_cs_pca, 'PCA1')
            ylabel(app.axes_cs_pca, 'PCA2')
            app.grid_cs_pca_pushbtn = uigridlayout(app.grid_cs_pca);
            app.pushbtn_cs_pca_select = uibutton(app.grid_cs_pca_pushbtn, 'push');
            app.pushbtn_cs_pca_select.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_cs_pca_selectButtonPushed, true);
            app.pushbtn_cs_pca_select.Text = 'Select PCA';
            app.pushbtn_cs_pca_window = uibutton(app.grid_cs_pca_pushbtn, 'push');
            app.pushbtn_cs_pca_window.ButtonPushedFcn = createCallbackFcn(app, @pushbtn_cs_pca_windowButtonPushed, true);
            app.pushbtn_cs_pca_window.Text = 'PCA Window';
            app.axes_cs_corr = uiaxes(app.grid_cs_axes);
            title(app.axes_cs_corr, 'CSxSS Corr')
            xlabel(app.axes_cs_corr, 'Time (ms)')
            ylabel(app.axes_cs_corr, 'Prob')
            app.axes_cs_isi = uiaxes(app.grid_cs_axes);
            title(app.axes_cs_isi, 'CS ISI')
            xlabel(app.axes_cs_isi, 'Time (s)')
            ylabel(app.axes_cs_isi, 'Count')
            % Show the figure after all components are created
            app.PurkinjeSortUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ESN_CS_SS_Sorter_App_v2_11

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PurkinjeSortUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PurkinjeSortUIFigure)
        end
    end
end